classdef DataDescUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ApplyButton                matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        EEGDataDescriptionPanel    matlab.ui.container.Panel
        LabelReqState              matlab.ui.control.Label
        LabelReqReference          matlab.ui.control.Label
        LabelReqEEGDevice          matlab.ui.control.Label
        LabelReqCountry            matlab.ui.control.Label
        KeepsignalSwitch           matlab.ui.control.Switch
        KeepsignalSwitchLabel      matlab.ui.control.Label
        WindowslengthSpinner       matlab.ui.control.Spinner
        WindowslengthSpinnerLabel  matlab.ui.control.Label
        StateEditField             matlab.ui.control.EditField
        StateEditFieldLabel        matlab.ui.control.Label
        ReferenceEditField         matlab.ui.control.EditField
        ReferenceEditFieldLabel    matlab.ui.control.Label
        EEGdeviceEditField         matlab.ui.control.EditField
        EEGdeviceEditFieldLabel    matlab.ui.control.Label
        CountryEditField           matlab.ui.control.EditField
        CountryEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_properties
    end
    
    properties (Access = public)
        data_params
        Finished % Description
        Applied
    end
    
    methods (Access = private)
        
        function load_predef_params(app)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            %% Filling components from predefined params
            % General params
            app.data_params                             = app.app_properties.general_params.data;
            app.CountryEditField.Value          = app.data_params.country;
            app.EEGdeviceEditField.Value    = app.data_params.eeg_device;
            app.ReferenceEditField.Value    = app.data_params.reference;
            app.StateEditField.Value    = app.data_params.state;
            app.WindowslengthSpinner.Value    = app.data_params.lwin;
            if(app.data_params.lwin)
                app.KeepsignalSwitch.Value = 'Yes';
            else
                app.KeepsignalSwitch.Value = 'No';
            end    

        end
        
        function checked = check_visual_params(app)
            checked = true;
            %% Data params
            if(isempty(app.CountryEditField.Value))
                 msgbox({'The Country can not be empty',...
                    ' Please type a Country name.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.EEGdeviceEditField.Value))
                msgbox({'The EEG device can not be empty.',...
                    ' Please select a correct EEG device.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.ReferenceEditField.Value))
                msgbox({'The Reference can not be empty.',...
                    ' Please select a correct refenrece.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.StateEditField.Value))
                msgbox({'The State can not be empty.',...
                    ' Please select a correct State.'},'Info');
                app.TmpPathEditField.Value = 'local';
                checked = false;
                return;
            end  
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*            
            app.Finished = false; 
            movegui(app.UIFigure,"center");
            app.app_properties = get_properties();
            % load prodefinition parameters
            load_predef_params(app);
            app.Applied = false;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checked = app.check_visual_params();
            if(checked)
                app.Applied = true;
                app.Finished = true;
                app.UIFigure.Visible = false;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EEGDataDescriptionPanel = uipanel(app.UIFigure);
            app.EEGDataDescriptionPanel.Title = 'EEG Data Description';
            app.CountryEditFieldLabel = uilabel(app.EEGDataDescriptionPanel);
            app.CountryEditFieldLabel.Text = 'Country:';
            app.CountryEditField = uieditfield(app.EEGDataDescriptionPanel, 'text');
            app.EEGdeviceEditFieldLabel = uilabel(app.EEGDataDescriptionPanel);
            app.EEGdeviceEditFieldLabel.Text = 'EEG device:';
            app.EEGdeviceEditField = uieditfield(app.EEGDataDescriptionPanel, 'text');
            app.ReferenceEditFieldLabel = uilabel(app.EEGDataDescriptionPanel);
            app.ReferenceEditFieldLabel.Text = 'Reference:';
            app.ReferenceEditField = uieditfield(app.EEGDataDescriptionPanel, 'text');
            app.StateEditFieldLabel = uilabel(app.EEGDataDescriptionPanel);
            app.StateEditFieldLabel.Text = 'State:';
            app.StateEditField = uieditfield(app.EEGDataDescriptionPanel, 'text');
            app.WindowslengthSpinnerLabel = uilabel(app.EEGDataDescriptionPanel);
            app.WindowslengthSpinnerLabel.Text = 'Windows length:';
            app.WindowslengthSpinner = uispinner(app.EEGDataDescriptionPanel);
            app.WindowslengthSpinner.Step = 0.01;
            app.WindowslengthSpinner.Limits = [1 5];
            app.WindowslengthSpinner.Value = 2.56;
            app.KeepsignalSwitchLabel = uilabel(app.EEGDataDescriptionPanel);
            app.KeepsignalSwitchLabel.Text = 'Keep signal:';
            app.KeepsignalSwitch = uiswitch(app.EEGDataDescriptionPanel, 'slider');
            app.KeepsignalSwitch.Items = {'No', 'Yes'};
            app.KeepsignalSwitch.Value = 'Yes';
            app.LabelReqCountry = uilabel(app.EEGDataDescriptionPanel);
            app.LabelReqCountry.Text = '*';
            app.LabelReqEEGDevice = uilabel(app.EEGDataDescriptionPanel);
            app.LabelReqEEGDevice.Text = '*';
            app.LabelReqReference = uilabel(app.EEGDataDescriptionPanel);
            app.LabelReqReference.Text = '*';
            app.LabelReqState = uilabel(app.EEGDataDescriptionPanel);
            app.LabelReqState.Text = '*';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'images', 'apply.png');
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataDescUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end