classdef XiAlphaNetUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        ProcessMenu               matlab.ui.container.Menu
        NewprocessMenu            matlab.ui.container.Menu
        DefaultparamsMenu         matlab.ui.container.Menu
        RunMenu                   matlab.ui.container.Menu
        DatasetsMenu              matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        ListMenu                  matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        ToolsMenu                 matlab.ui.container.Menu
        TestingMenu               matlab.ui.container.Menu
        DownloaddataMenu          matlab.ui.container.Menu
        DownloadTensorFieldsMenu  matlab.ui.container.Menu
        ExampleOutputsMenu        matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        CheckforupdateMenu        matlab.ui.container.Menu
        WikiMenu                  matlab.ui.container.Menu
        Toolbar                   matlab.ui.container.Toolbar
        RunProcessPushTool        matlab.ui.container.toolbar.PushTool
        NewProcessPushTool        matlab.ui.container.toolbar.PushTool
        DefaultParamsPushTool     matlab.ui.container.toolbar.PushTool
        UploadDatasetPushTool     matlab.ui.container.toolbar.PushTool
        ListDatasetsPushTool      matlab.ui.container.toolbar.PushTool
        HelpPushTool              matlab.ui.container.toolbar.PushTool
        GridLayout                matlab.ui.container.GridLayout
        PanelActions              matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        TreeActions               matlab.ui.container.Tree
        NodeAction1               matlab.ui.container.TreeNode
        Node2                     matlab.ui.container.TreeNode
        Node3                     matlab.ui.container.TreeNode
        Node4                     matlab.ui.container.TreeNode
        NodeAction2               matlab.ui.container.TreeNode
        Node2_2                   matlab.ui.container.TreeNode
        Node3_2                   matlab.ui.container.TreeNode
        Node4_2                   matlab.ui.container.TreeNode
        PanelControls             matlab.ui.container.Panel
        LabelBreadcrumbs          matlab.ui.control.Label
        PanelViewer               matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        TabExplorer               matlab.ui.container.Tab
        OpenFolderButton          matlab.ui.control.Button
        DeleteFolderButton        matlab.ui.control.Button
        RefreshButton             matlab.ui.control.Button
        NewFolderButton           matlab.ui.control.Button
        UpperButton               matlab.ui.control.Button
        TreeInput                 matlab.ui.container.Tree
        TabDatasets               matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        InformationPanel          matlab.ui.container.Panel
        TreeDatasets              matlab.ui.container.Tree
    end


    properties (Access = private)
        app_properties % Description
        rejected_subs % Description
        ref_sub % Description
        dt_file
        currentSub % Description
        currentAxes; % Description
        XANdir % Description
        Datasets_file % Description        
        Datasets % Dataset information
        currentDS % Description
        UIAxes % Description
        last_dir % Description
    end

    methods (Access = private)

        %%
        %% Explore the Tree Folder
        %%
        function ExploreFolder(app,nodeData)            
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            app.TreeInput.Children.delete;
            rootDir = fullfile(nodeData.folder,nodeData.name);
            dirName = nodeData.name;    
            if(isempty(dirName))
                dirName = rootDir;
            end
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.NodeData = nodeData;
            parent.Icon = '+guide/images/folder.png';

            sub_cm = uicontextmenu(app.UIFigure);
            folder_cm = uimenu(sub_cm,"Text","Folder");
            fo = uimenu(folder_cm,"Text","New","MenuSelectedFcn",{@app.FolderContenMenu,'New',nodeData});
            f1 = uimenu(folder_cm,"Text","Rename","MenuSelectedFcn",{@app.FolderContenMenu,'Rename',nodeData});
            f3 = uimenu(folder_cm,"Text","Delete","MenuSelectedFcn",{@app.FolderContenMenu,'Delete',nodeData});

            mo = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',nodeData},'Separator','on');
            m1 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',nodeData},'Separator','on');
            m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',nodeData},'Separator','on');
            parent.ContextMenu = sub_cm;

            file_list = dir(rootDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = '+guide/images/folder_parent.png';
                    else
                        icon_file = '+guide/images/folder.png';
                        % Contex menu
                        sub_cm = uicontextmenu(app.UIFigure);
                        folder_cm = uimenu(sub_cm,"Text","Folder");
                        fo = uimenu(folder_cm,"Text","New","MenuSelectedFcn",{@app.FolderContenMenu,'New',node.NodeData});
                        f1 = uimenu(folder_cm,"Text","Rename","MenuSelectedFcn",{@app.FolderContenMenu,'Rename',node.NodeData});
                        f3 = uimenu(folder_cm,"Text","Delete","MenuSelectedFcn",{@app.FolderContenMenu,'Delete',node.NodeData});

                        m1 = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',node.NodeData});
                        m2 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',node.NodeData});
                        m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',node.NodeData},'Separator','on');

                        node.ContextMenu = sub_cm;
                        node.Icon = icon_file;
                    end                    
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.Icon = '+guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        
        function LoadDatasetsDB(app)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            TempDatasets = xan_get('datasets');
            if(~isempty(TempDatasets))
                d = uiprogressdlg(app.UIFigure,'Title','Loading saved Datasets','Indeterminate','on');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Load';
                % Use the TeX interpreter to format the question
                quest = {strcat("XiAlphaNet db have saved Datasets."), ...
                    'Would you like import these the datasets?'};
                answer = questdlg(quest,'Load Datasets',...
                    'Load','Reset',opts);
                % Handle response
                switch answer
                    case 'Load'
                        app.Datasets = TempDatasets;
                        [icondata,iconcmap] = imread("+guide/images/success.png");
                        msg = msgbox({'The Datasets loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                        waitfor(msg);
                        disp("-->> Datasets loaded");
                    case 'Reset'
                        app.Datasets = struct([]);
                        xan_set('datasets',app.Datasets);
                    otherwise

                end
                close(d)
                figure(app.UIFigure);
            end
            reject = [];
            for i=1:length(app.Datasets)
                dt = app.Datasets(i);
                if(~isfile(fullfile(dt.Location,'XIALPHANET.json')))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Update';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("We can not find the Dataset: ", dt.Name, "in the defined location:"), ...
                        strcat(dt.Location),...
                        'Would you like to define a correct path or delete the Dataset information?'};
                    answer = questdlg(quest,'Dataset path',...
                        'Update','Delete','Later',opts);
                    % Handle response
                    switch answer
                        case 'Update'
                            while true
                                folder = uigetdir(strcat("Select the ",dt.Name," dataset path"));
                                if(~isfile(fullfile(folder,'XIALPHANET.json')))
                                    d = uiprogressdlg(app.UIFigure,'Title','Checking selected folder.','Indeterminate','on');                                   
                                    opts.Interpreter = 'tex';
                                    % Include the desired Default answer
                                    opts.Default = 'Try again';
                                    % Use the TeX interpreter to format the question
                                    quest = {strcat("The selected folder do not contain a XiAlphaNet structure"), ...
                                        strcat(dt.Location),...
                                        'Please select a new Dataset path'};
                                    answer1 = questdlg(quest,'Dataset path',...
                                        'Try again','Cancel',opts);
                                    if(isequal(answer1,'Cancel'))
                                        break;
                                    end                                    
                                    delete(d);
                                    figure(app.UIFigure);
                                else
                                    tempDT = jsondecode(fileread(fullfile(folder,'XIALPHANET.json')));
                                    if(isequal(dt.UUID,tempDT.UUID))
                                        dt.Location = strrep(folder,'\','/');
                                        tempDT.Location = strrep(folder,'\','/');
                                        saveJSON(tempDT,fullfile(folder,'XIALPHANET.json'));
                                        h = matlab.desktop.editor.openDocument(fullfile(folder,'XIALPHANET.json'));
                                        h.smartIndentContents
                                        h.save
                                        h.close
                                        app.Datasets(i) = dt;
                                        msg = msgbox({'The new dataset location has been updated', ...
                                            strcat("Location: ",folder)},'Info');

                                        break;
                                    else
                                        d = uiprogressdlg(app.UIFigure,'Title','Checking selected dataset.','Indeterminate','on');
                                        msg = msgbox({'The new configuration do not corresponde with the stored dataset', ...
                                            'Please select a new Dataset path'},'Info');
                                        waitfor(msg);
                                        delete(d);
                                        figure(app.UIFigure);                                    
                                    end
                                end
                            end
                        case 'Delete'
                            reject = [reject, i];
                        case 'Later'
                            continue;
                    end
                end
            end
            if(~isempty(reject))
                app.Datasets(reject) = [];
            end
            xan_set('datasets',app.Datasets);
        end    

        function AnalysisReport(app,event,action,clasif)
            d = uiprogressdlg(app.UIFigure,'Title','Generating Pariticipant report');
             nodeLevel = action.InteractionInformation.Level;
             switch nodeLevel
                 case 1
                     dataset = action.ContextObject.NodeData;
                     participants = action.ContextObject.Children;
                     for i=1:length(participants)
                         nodeData = participants(i).NodeData;
                         userData = participants(i).UserData;
                         result = report_process_interface(dataset,clasif,nodeData,userData);
                     end
                 case 2
                     nodeData = action.ContextObject.NodeData;
                     userData = action.ContextObject.UserData;
                     dataset = action.ContextObject.Parent.NodeData;
                     result = report_process_interface(dataset,clasif,nodeData,userData);
             end
            
            close(d);
        end

        function ExportSubject(app,event,action)
            d = uiprogressdlg(app.UIFigure,'Title','Generating Pariticipant report');
             nodeLevel = action.InteractionInformation.Level;
             switch nodeLevel
                 case 1
                     dataset = action.ContextObject.NodeData;
                     participants = action.ContextObject.Children;
                     for i=1:length(participants)
                         nodeData = participants(i).NodeData;
                         userData = participants(i).UserData;
                         result = report_process_interface(dataset,clasif,nodeData,userData);
                     end
                 case 2
                     nodeData = action.ContextObject.NodeData;
                     userData = action.ContextObject.UserData;
                     dataset = action.ContextObject.Parent.NodeData;
                     result = report_process_interface(dataset,clasif,nodeData,userData);
             end
            
            close(d);
        end

        function FillDatasetsTree(app)     
            import guide.functions.*
            d = uiprogressdlg(app.UIFigure,'Title','Updating Dataset Tree','Indeterminate','on');
            delete(app.TreeDatasets.Children);            
            for i=1:length(app.Datasets)
                app.currentDS = app.Datasets(i);
                child = uitreenode(app.TreeDatasets,'Text',app.currentDS.Name);
                child.NodeData = app.currentDS;
                child.UserData.NodeType = 'dataset';
                child.Icon = '+guide/images/box-solid.png';
                contexMenu = uicontextmenu(app.UIFigure);
                if(isfile(fullfile(app.currentDS.Location,'XIALPHANET.json')))
                    menu = uimenu(contexMenu,'Text','Dataset report', "MenuSelectedFcn",{@app.AnalysisReport,'dataset'},'Separator','on');
                    menu = uimenu(contexMenu,'Text','Export', "MenuSelectedFcn",{@app.ExportDataset},'Separator','on');
                    child.ContextMenu = contexMenu;
                else
                    style = uistyle("FontColor",[0.8500 0.3250 0.0980]);
                    addStyle(app.TreeDatasets, style, 'node', child);                    
                    menu = uimenu(contexMenu,'Text',strcat("Update location"), "MenuSelectedFcn",{@app.DatasetAction,'update_path'});
                    menu = uimenu(contexMenu,'Text',strcat("Delete"), "MenuSelectedFcn",{@app.DatasetAction,'delete'});                    
                    child.set("Text",strcat(app.currentDS.Name, " (Wrong location)"));
                    child.ContextMenu = contexMenu;
                    continue;
                end 
                
                indexDS = find(contains({app.Datasets.UUID},app.currentDS.UUID),1);
                participants = app.Datasets(indexDS).Participants;
                
                for j=1:length(participants)
                    participant = participants(j);
                    subChild = uitreenode(child,'Text',participant.SubID);
                    subChild.Icon = '+guide/images/participant.png';                    
                    try
                        subChild.NodeData = jsondecode(fileread(fullfile(app.currentDS.Location,participant.SubID,participant.FileInfo)));                       
                    catch
                        subChild.NodeData = struct;
                    end
                    subChild.UserData.NodeType = 'subject';
                    subChild.UserData.Condition = app.currentDS.Task;
                    contexMenu = uicontextmenu(app.UIFigure);
                    if(isequal(participant.Status,"Completed"))
                        mStyle = uistyle("FontColor","blue");
                        menu = uimenu(contexMenu,'Text','Subject report', "MenuSelectedFcn",{@app.AnalysisReport,'subject'},'Separator','on'); 
                        menu = uimenu(contexMenu,'Text','Export', "MenuSelectedFcn",{@app.ExportSubject},'Separator','on');
                    else
                        mStyle = uistyle("FontColor","red");
                        errors = participant.Errors;
                        menu_error = uimenu(contexMenu,'Text','This participant crashed because the following errors');
                        separator = 'on';
                        for e=1:length(errors)    
                            menu_error = uimenu(contexMenu,'Text',errors{e},'Separator',separator);
                            separator = 'off';
                        end
                    end
                    subChild.ContextMenu = contexMenu;
                    addStyle(app.TreeDatasets,mStyle,"node",subChild);
                    
                end
                clear Participants_Info;
            end
            delete(app.TreeActions.Children);
            app.TabGroup.SelectedTab = app.TabDatasets;
            close(d);
        end

        % Folder Content Menu
        function FolderContenMenu(app, event, action, clasif, nodeData)
            switch clasif
                case 'New'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end
                    app.TreeInput.SelectedNodes = [selectedNode];
                    app.NewFolderButtonPushed(event);
                case 'Rename'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end
                    inputDLG = inputdlg({'New name:'}, 'Rename folder', 1);
                    if(~isempty(inputDLG))
                        newFolderName= char(inputDLG(1));
                        oldFolder = fullfile(selectedNode.NodeData.folder,selectedNode.NodeData.name);
                        NewFolder = fullfile(selectedNode.NodeData.folder,newFolderName);
                        folders = dir(selectedNode.NodeData.folder);
                        if(~isempty(find(ismember({folders.name},{newFolderName}),1)))
                            msg = msgbox({'The folder name alresdy exist'},'Info');
                            waitfor(msg);
                            return;
                        end
                        try
                            movefile(oldFolder,NewFolder);
                        catch Ex
                            msg = msgbox({Ex.message},'Info');
                            waitfor(msg);
                        end
                        if(isempty(index))
                            app.TreeInput.Children(1).NodeData.name = newFolderName;
                        end
                        app.RefreshButtonPushed();
                    end                    
                case 'Delete'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end    
                    app.TreeInput.SelectedNodes = [selectedNode];
                    app.DeleteFolderButtonPushed(event);  
            end
        end

        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            text = strcat(filesep, " ");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");
                else
                    text = strcat(text, pathParts{i});
                end
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>");
            app.LabelBreadcrumbs.Interpreter = 'html';
        end
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = '+guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.UIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',node.NodeData});                            
                            m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',node.NodeData},'Separator','on');
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = '+guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end

        function DefineProperties(app,event,action,clasif,nodeData)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for user action','Indeterminate','on');
            switch clasif
                case 'Output'
                    base_path = strrep(fullfile(nodeData.folder,nodeData.name),'\','/');                    
                    app.app_properties.general_params.output_path = base_path;
                    disp("-->> Defining output path to:");
                    disp(app.app_properties.general_params.output_path);

                    msg = msgbox({strcat('The Output folder is:',app.app_properties.general_params.output_path)},'Info');
                    waitfor(msg);
                    app.dt_file = fullfile(app.app_properties.general_params.output_path,'Dataset.json');
                    if(isfile(app.dt_file))
                        app.currentDS = jsondecode(fileread(app.dt_file));
                        app.FillDatasetTab();
                    end
                    
                    saveJSON(app.app_properties.general_params,app.app_properties.module_param_files(1).file_path);
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(1).file_path));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Input'
                    base_path = strrep(fullfile(nodeData.folder,nodeData.name),'\','/');
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        files = dir(fullfile(structure.folder,structure.name,"**","*.mat"));
                        if(isempty(files))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msg = msgbox({'The selected folder do not contain any file to process',...
                            'Please select a correct anatomy folder.'},'Info');
                        waitfor(msg);
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders do not contain the EEG file.',...
                            'Please check the Subject folders or you can continue in this way.'},'Info');
                    end
                    app.app_properties.general_params.input_path = base_path;
                    disp("-->> Defining output path to:");
                    disp(app.app_properties.general_params.input_path);
                    msg = msgbox({strcat('The Input folder is:',app.app_properties.general_params.input_path)},'Info');
                    waitfor(msg); 
                    
                    saveJSON(app.app_properties.general_params,app.app_properties.module_param_files(1).file_path);
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.module_param_files(1).file_path));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Setting'                    
                    d1 = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration','Indeterminate','on');
                    obj_Config = ParamsUI();
                    waitfor(obj_Config,'Finished',true);
                    app.app_properties = get_properties();
                    close(d1);                    
            end
            figure(app.UIFigure);
            close(d);
        end

        function DefineAction(app,event,action,clasif,nodeData)
            if(isfield(nodeData,'Name'))
                d = uiprogressdlg(app.UIFigure,'Title',strcat("Creating reports for Dataset: ",nodeData.Name),'Indeterminate','on');
            else
                d = uiprogressdlg(app.UIFigure,'Title',strcat("Creating report for subject: ",nodeData.SubID),'Indeterminate','on');
            end
            switch clasif
                case 'Basic'
                    subID = nodeData.SubID;
                    dataset_path = app.currentDS.Location;
                    ReportGenerator(dataset_path, subID);
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Basic');
                case 'Medium'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Medium');
                case 'Expert'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Expert');
                case 'BasicAll'
                    dataset_path = app.currentDS.Location;
                    ReportGenerator(dataset_path);
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Basic');
                case 'MediumAll'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Medium');
                case 'ExpertAll'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Expert');
                case 'Reload'                    
                    app.currentDS = jsondecode(fileread(app.dt_file));
                    app.FillDatasetTab();
                    
            end            
            close(d);
            figure(app.UIFigure);
            if(~isequal(clasif,'Reload'))
                if (ispc)
                    winopen(dataset_path);
                elseif ismac
                    cmdToExecute = ['open ' dataset_path];
                    [status, path] = system(cmdToExecute); %#ok<ASGLU>
                else
                    % using xdg-open to open a file in Linux. Some Linux systems might not have
                    % xdg-open .In that case displaying as error with the file path
                    cmdToExecute = ['xdg-open ' dataset_path];
                    [status, path] = system(cmdToExecute); %#ok<ASGLU>
                end
            end
        end

        % Getting default properties and seting on user interface
        function set_default_properties(app)         
            app.app_properties.general_params          = app.app_properties.defaults.general_params;
            app.app_properties.anatomy_params          = app.app_properties.defaults.anatomy_params;
            app.app_properties.model_params             = app.app_properties.defaults.model_params;
            % Saving property files
            saveJSON(app.app_properties.general_params,app.app_properties.default_param_files(1).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.default_param_files(1).file_path));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.anatomy_params,app.app_properties.default_param_files(2).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.default_param_files(2).file_path));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.model_params,app.app_properties.default_param_files(3).file_path);
            h = matlab.desktop.editor.openDocument(fullfile(pwd,app.app_properties.default_param_files(3).file_path));
            h.smartIndentContents
            h.save
            h.close 
        end % set_default_properties
                        
        function ShowMessage(app,text)
            delete(app.PanelControls.Children);
            label = uilabel(Text=text,Position=[5,5,500,30],Parent=app.PanelControls);
            disp(strcat("-->> ", text));
        end     
               
        function FillDatasetTab(app)
            delete(app.TreeDatasets.Children);
            dt_path = fileparts(app.dt_file);
            app.currentDS.Location = dt_path;
            app.PanelViewer.Title = app.currentDS.Name;
            node = uitreenode(app.TreeDatasets, 'Text', app.currentDS.Name, 'Icon','+guide/images/dataset.png');
            node.NodeData = app.currentDS;
            node.UserData = 'dataset';
            % Dataset Content menu
            dt_cm = uicontextmenu(app.UIFigure);
            exp_cm = uimenu(dt_cm,"Text","Export All");
            uimenu(exp_cm,"Text","Basic report","MenuSelectedFcn",{@app.DefineAction,'BasicAll',node.NodeData});
            uimenu(exp_cm,"Text","Medium report","MenuSelectedFcn",{@app.DefineAction,'MediumAll',node.NodeData});
            uimenu(exp_cm,"Text","Expert report","MenuSelectedFcn",{@app.DefineAction,'ExpertAll',node.NodeData});
            uimenu(dt_cm,"Text","Reload Dataset","MenuSelectedFcn",{@app.DefineAction,'Reload',node.NodeData},'Separator','on');
            node.ContextMenu = dt_cm;
            for i=1:length(app.currentDS.Participants)
                participant = app.currentDS.Participants(i);
                nodeSub = uitreenode(node, 'Text', participant.SubID);
                % subject = jsondecode(fileread(fullfile(dt_path,participant.SubID,participant.FileInfo)));
                nodeSub.NodeData = participant;
                nodeSub.UserData = 'subject';
                icon_file = '+guide/images/subject.png';
                nodeSub.Icon = icon_file;
                % Subject content menu
                sub_cm = uicontextmenu(app.UIFigure);
                exp_cm = uimenu(sub_cm,"Text","Export subject");
                uimenu(exp_cm,"Text","Basic report","MenuSelectedFcn",{@app.DefineAction,'Basic',nodeSub.NodeData});
                uimenu(exp_cm,"Text","Medium report","MenuSelectedFcn",{@app.DefineAction,'Medium',nodeSub.NodeData});
                uimenu(exp_cm,"Text","Expert report","MenuSelectedFcn",{@app.DefineAction,'Expert',nodeSub.NodeData});
                uimenu(sub_cm,"Text","Mark subject","MenuSelectedFcn",{@app.DefineAction,'Mark',nodeSub.NodeData});
                uimenu(sub_cm,"Text","Reject subject","MenuSelectedFcn",{@app.DefineAction,'Reject',nodeSub.NodeData},'Separator','on');
                nodeSub.ContextMenu = sub_cm;

                % Node Common
                nodeCom = uitreenode(nodeSub, 'Text', 'Common','Icon','+guide/images/common.png');
                uitreenode(nodeCom, 'Text', "EEG signal","Icon",'+guide/images/eeg_data.png');

                % Node Sensor
                nodeSensor = uitreenode(nodeSub, 'Text', 'Sensor','Icon','+guide/images/sensor.png');
                option1 = uitreenode(nodeSensor, 'Text', "PSD","UserData","Sensor-PSD","Icon",'+guide/images/psd.png');
                option2 = uitreenode(nodeSensor, 'Text', "Topography","UserData","Sensor-Topo","Icon",'+guide/images/topo.png');
                option3 = uitreenode(nodeSensor, 'Text', "Connectivity","UserData","Sensor-Conn","Icon",'+guide/images/connectivity.png');

                % Node Sensor
                nodeSource = uitreenode(nodeSub, 'Text', 'Source','Icon','+guide/images/source.png');
                option1 = uitreenode(nodeSource, 'Text', "PSD","UserData","Source-PSD","Icon",'+guide/images/psd.png');
                option2 = uitreenode(nodeSource, 'Text', "Connectivity","UserData","Source-Conn","Icon",'+guide/images/connectivity.png');
            end
            expand(app.TreeDatasets);
        end
    
        function PlotSensorCrossSpectrum(app)   
            delete(app.PanelViewer.Children);
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
            freqs = data.freq;
            Svv = data.Cross; 
            PSD = zeros(size(Svv,1),length(freqs));
            for freq = 1:length(freqs)
                PSD(:,freq) = diag(squeeze(abs(Svv(:,:,freq))));
            end
            PSD_log = 10*log10(abs(PSD));
            plot(app.UIAxes,freqs,PSD_log);
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end

        function PlotSourceCrossSpectrum(app)    
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_PSD));
            freqs = data.freq; 
            plot(app.UIAxes,freqs,data.Source_PSD');
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end
        
        function PlotMeasure(app,J,varargin)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import guide.functions.*
            import tools.*
            if(~isempty(varargin))
                operand = varargin{1};
                operand = operand/max(operand(:));
            else
                operand = 1;
            end
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));             
            colorMap                = load("+templates/mycolormap_brain_basic_conn.mat");
            template                = load("+guide/axes.mat");
            app.currentAxes         = template.axes;
            J_smoothed              = J.*operand;
            sources_iv = J_smoothed;%_star;
            sources_iv              = sources_iv/max(sources_iv(:));
            %%
            smoothValue             = 0.4;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',Cortex.SulciMap*0.00+1+sources_iv, ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',1);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            colormap(app.currentAxes,colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            axis(app.currentAxes,'equal');
            rotate3d(app.currentAxes,'on');
        end
        
        function PlotFullActivation(app,full_activation)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import guide.functions.*
            import tools.*
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));             
            colorMap                = load("+templates/mycolormap_brain_basic_conn.mat");
            template                = load("+guide/axes.mat");
            app.currentAxes         = template.axes;
            delete(app.PanelViewer.Children);
            J = abs(full_activation(:,app.PanelControls.Children(1).ValueIndex));
            J_smoothed              = J;
            sources_iv = J_smoothed;%_star;
            sources_iv              = sources_iv/max(sources_iv(:));
            %%
            smoothValue             = 0.4;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            FaceVertexCData         = Cortex.SulciMap*0.00+1+sources_iv;
            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',FaceVertexCData, ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',1);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            colormap(app.currentAxes,colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            axis(app.currentAxes,'equal');
            rotate3d(app.currentAxes,'on');
        end
        
        function ChangeActivationFreq(app,full_activation)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import guide.functions.*
            import tools.*
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));
            patch_comp = app.PanelViewer.Children.Children(1);
            freq = app.PanelControls.Children(1).ValueIndex;
            J = abs(full_activation(:,app.PanelControls.Children(1).ValueIndex));
            J_smoothed              = J;
            sources_iv = J_smoothed;
            sources_iv              = sources_iv/max(sources_iv(:));
            smoothValue             = 0.4;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            FaceVertexCData         = Cortex.SulciMap*0.00+1+sources_iv;
            patch_comp.FaceVertexCData = FaceVertexCData;
        end
                
        function PlotSourceConnnectivity(app)  
            import guide.functions.*
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);  
            template                = load('+guide/axes.mat');
            app.currentAxes         = template.axes;      
            
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));              
            Atlas                   = Cortex.Atlas(Cortex.iAtlas);
            Scouts                  = Atlas.Scouts;
            Faces                   = Cortex.Faces;
            smoothValue             = 0.66;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);    
            Conn_Matrix             = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Conn_Matrix));
            Conn_Matrix             = Conn_Matrix.Conn_Matrix;
            
            %%
            %% Work to obtain th
            %%
            maxvalue                = max(Conn_Matrix(:));
            th                      = maxvalue - 5*mean(Conn_Matrix(:));

            % Creating Tools panel
            toolsPanel = uipanel(app.PanelViewer, "Title","Tools", "BackgroundColor","white","Position",[25 5 300 120],"BorderType","none", "Tag","Tools"); 
            uilabel(toolsPanel,"Text","Face Color","Position",[25 5 120 30]);
            uicolorpicker(toolsPanel, "Value",[255/255 204/255 204/255],"Position", [90 5 40 30],Tooltip='Select the Face color', ...
                ValueChangedFcn=@(src,event) app.ChangeEdgeFaceColor(event,'Face'));

            uilabel(toolsPanel,"Text","Edge Color","Position",[25 40 120 30]);
            uicolorpicker(toolsPanel, "Value",[255/255 204/255 204/255],"Position", [90 40 40 30],Tooltip='Select the Edge color', ...
                ValueChangedFcn=@(src,event) app.ChangeEdgeFaceColor(event,'Edge'));

            uicheckbox(toolsPanel,'Text','Show labels','Value', 1,'Position',[180 5 120 30],...
                'ValueChangedFcn',@(src,event) app.ShowLabels());
            uicheckbox(toolsPanel,'Text','Show scouts','Value', 0,'Position',[180 40 120 30],...
                'ValueChangedFcn',@(src,event) app.ShowScouts());
            
            patch(app.currentAxes, ...
                'Faces',            Faces, ...
                'Vertices',         Vertices,...
                'FaceVertexCData',  [], ...
                'FaceColor',        [255/255 204/255 204/255], ...
                'FaceAlpha',        0.1, ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        0.1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'Tag',              'AnatSurface');
           
            % Proting Seeds
            for i=1:length(Scouts)
                line(app.currentAxes,Vertices(Scouts(i).Seed,1),Vertices(Scouts(i).Seed,2),Vertices(Scouts(i).Seed,3),...
                    'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(i).Color, 'MarkerSize', 10); 
            end

            % plotting lines
            scoutsCMap = myCmap('r',length(Scouts),100/length(Scouts));
            for i=1:length(Conn_Matrix)
                for j=i+1:length(Conn_Matrix)
                    w = Conn_Matrix(i,j);
                    if(w>th)
                    LineWidth = Conn_Matrix(i,j);
                    LineWidth = abs(LineWidth) + 0.5;
                    uSeed = Vertices(Scouts(i).Seed,:);
                    vSeed = Vertices(Scouts(j).Seed,:);
                    % Ploting Seeds
                    line(app.currentAxes,uSeed(1),uSeed(2),uSeed(3), 'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(i).Color, 'MarkerSize', 10);
                    line(app.currentAxes,vSeed(1),vSeed(2),vSeed(3), 'LineStyle', '-', 'Marker', 'o',  'MarkerFaceColor', Scouts(j).Color, 'MarkerSize', 10);
                    
                    % Getting arrow distance
                    Center = [(uSeed(1) + vSeed(1))/2 (uSeed(2) + vSeed(2))/2 (uSeed(3) + vSeed(3))/2];
                    Line = [uSeed;Center;vSeed];
                    color = interp1(linspace(min(Conn_Matrix(:)),max(Conn_Matrix(:)),length(scoutsCMap)),scoutsCMap,Conn_Matrix(i,j));
                    line(app.currentAxes,Line(:,1),Line(:,2),Line(:,3),'LineWidth', LineWidth, 'Color', color);                    
                    end
                end
            end
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            set(app.currentAxes,"Parent",app.PanelViewer);
            axis(app.currentAxes,'tickaligned');
            axis(app.currentAxes,'off');
            rotate3d(app.currentAxes,'on');
        end        

        function PlotSourceConnnectivityCircular(app)          
           import guide.functions.*
            delete(app.PanelViewer.Children);
            delete(app.currentAxes);  
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));              
            Atlas                   = Cortex.Atlas(Cortex.iAtlas);
            Scouts                  = Atlas.Scouts;
              
            Conn_Matrix             = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Conn_Matrix));
            Conn_Matrix             = Conn_Matrix.Conn_Matrix;

            regions                 = {'LT', 'LU', 'LP', 'LO', 'RO', 'RP', 'RU', 'RT', 'RL', 'RC', 'RPF', 'RF', 'LF', 'LPF', 'LL', 'LC'};
            orderScouts             = [];
            pos                     = 1;
            for i=1:length(regions)
                region              = regions{i};
                ScoutsRegion_ind    = ismember({Scouts.Region},{region});
                if(any(ScoutsRegion_ind))
                    ScoutsRegion    = Scouts(ScoutsRegion_ind);
                    orderScouts     = [orderScouts ScoutsRegion];
                    groupScoutsInd{pos} = ScoutsRegion_ind;
                    outRegions{pos} = region;
                    pos             = pos+1;
                end
            end

            %% Ordering ThetaSS by Regions
            disp("Ordering Theta columns by regions");
            % Ordering columns
            inP = 1;
            endP = 0;
            preOrderThetaSS = zeros(length(Scouts),length(Scouts));
            for i=1:length(groupScoutsInd)
                gScoutsInd = groupScoutsInd{i};
                endP = endP + sum(gScoutsInd);
                preOrderThetaSS(:,inP:endP) = Conn_Matrix(:,gScoutsInd);
                inP = inP + sum(groupScoutsInd{i});
            end
            disp("Ordering Theta rows by regions");
            % Ordering rows
            orderThetaSS = zeros(length(Scouts),length(Scouts));
            inP = 1;
            endP = 0;
            for i=1:length(groupScoutsInd)
                gScoutsInd = groupScoutsInd{i};
                endP = endP + sum(gScoutsInd);
                orderThetaSS(inP:endP,:) = preOrderThetaSS(gScoutsInd,:);
                inP = inP + sum(groupScoutsInd{i});
            end

            % if(isregion)
            %     disp("Mapping Theta from Scouts to Regions");
            %     [orderThetaRR] = roi_to_region_map(orderConn_Matrix,groupScoutsInd);
            %     minvalue = min(orderThetaRR(:));
            %     maxvalue = max(orderThetaRR(:));
            %     thresh = 0;
            %     orderThetaRR(orderThetaRR <= thresh) = 0;
            %     disp("Ploting Theta by Regions");
            %     fig = figure;
            %     regionsCMap = scoutsCMap(1:floor(length(orderScouts)/length(outRegions)):length(orderScouts),:);
            %     regionsCMap(length(outRegions)+1:end,:) = [];
            %     circularGraph(orderThetaRR,'Colormap',regionsCMap,'Label',outRegions);
            %     axis tickaligned;
            % end

            %'Colormap',myColorMap,'Label',myLabel
            % scoutsCMap = myCmap('r',length(Scouts),100/length(Scouts));
            scoutsCMap = reshape([Scouts.Color],length(Scouts),length(Scouts(1).Color));

            disp("Ploting Theta by Scouts");
            % Ploting ThetaSS ordered
            minvalue = min(orderThetaSS(:));
            maxvalue = max(orderThetaSS(:));
            thresh = maxvalue/2;
            orderThetaSS(orderThetaSS <= thresh) = 0;

            % Plotting CircularGraph
            fig = figure("Visible","off");
            circGraph = circularGraph(orderThetaSS,'Colormap',scoutsCMap,'Label',{Scouts.Label});
            toolsPanel = uipanel(app.PanelViewer, "Title","Tools", "BackgroundColor","white", ...
                "Position",[25 5 300 100],"BorderType","none", "Tag","Tools"); 
            copyobj(circGraph.ShowButton,toolsPanel);
            copyobj(circGraph.HideButton,toolsPanel);  
            regionSwitch = uiswitch(toolsPanel,"toggle","Orientation","horizontal", ...
                "Position",[160 30 80 30], ValueChangedFcn=@(src,event) app.ChangeLabels(event));
            regionSwitch.Items = ["Parcels","Regions"];

            set(gca,"Parent",app.PanelViewer);             
            toolsPanel.Children(2).Callback = @circularGraph.hideNodes;            
            toolsPanel.Children(2).Position =[10 5 80 30];
            toolsPanel.Children(3).Callback = @circularGraph.showNodes;
            toolsPanel.Children(3).Position =[10 40 80 30];
            delete(fig);
            colorbar(app.PanelViewer.Children(2),'westoutside');            
            colormap(app.PanelViewer.Children(3),scoutsCMap);  
            clim(app.PanelViewer.Children(3),[minvalue maxvalue]);
            axis(app.PanelViewer.Children(3),'tickaligned');
            app.PanelViewer.Children(2).Position(1) =  0.1;
            app.PanelViewer.Children(3).Position(2) = 0.06; 
        end

        function ChangeEdgeFaceColor(app,event,comp)
            axesObj = findobj(app.PanelViewer.Children, 'type', 'Axes');
            patchObj = findobj(axesObj.Children, 'type', 'Patch');
            if(isequal(comp,'Face'))
                patchObj.FaceColor = event.Value;
            else
                patchObj.EdgeColor = event.Value;
            end                            
        end

        function ShowScouts(app)
            controls = app.PanelViewer.Children;            
            for i=1:length(controls)
                control = controls(i);
                if(isequal(control.Tag,"Tools"))
                    for j=1:length(control.Children)
                        tool = control.Children(j);
                        if(isequal('matlab.ui.control.CheckBox',class(tool)) && isequal(tool.Text,'Show scouts'))
                            controlScouts = tool;
                            break;
                        end
                    end
                end
            end
            if(controlScouts.Value)
                Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));
                Atlas                   = Cortex.Atlas(Cortex.iAtlas);
                Vertices                = Cortex.Vertices;
                Scouts                  = Atlas.Scouts;
                Faces                   = Cortex.Faces;
                for i = 1:length(Scouts)
                    scoutColor = Scouts(i).Color;
                    % Get visible scouts vertices
                    iScoutVert = Scouts(i).Vertices;
                    patchVert = Vertices(iScoutVert,:);
                    vertMask = false(length(Vertices),1);
                    vertMask(iScoutVert) = true;
                    iFacesTmp = find(vertMask(Faces(:,1)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,2)));
                    iFacesTmp = iFacesTmp(vertMask(Faces(iFacesTmp,3)));
                    patchFaces = Faces(iFacesTmp,:);
                    [~, patchFaces] = ismember(patchFaces, iScoutVert);
                    EdgeColor = 'none';
                    patchAlpha = 0.5;
                    patch(app.currentAxes, ...
                        'Faces',            patchFaces, ...
                        'Vertices',         patchVert, ...
                        'FaceVertexCData',  scoutColor, ...
                        'FaceColor',        scoutColor, ...
                        'EdgeColor',        EdgeColor,...
                        'FaceAlpha',        1 - patchAlpha, ...
                        'BackFaceLighting', 'lit', ...
                        'Tag',              'ScoutPatch', ...
                        'AmbientStrength',  0.5, ...
                        'DiffuseStrength',  0.5, ...
                        'SpecularStrength', 0.2, ...
                        'SpecularExponent', 1, ...
                        'SpecularColorReflectance', 0.5, ...
                        'FaceLighting',     'gouraud', ...
                        'EdgeLighting',     'gouraud');
                    hold on;
                end
            else
                delete(findobj(app.currentAxes, 'type', 'Patch', 'tag', 'ScoutPatch'));
            end
        end

        function ShowLabels(app)
            controls = app.PanelViewer.Children;          
            for i=1:length(controls)
                control = controls(i);
                if(isequal(control.Tag,"Tools"))
                    for j=1:length(control.Children)
                        tool = control.Children(j);
                        if(isequal('matlab.ui.control.CheckBox',class(tool)) && isequal(tool.Text,'Show labels'))
                            controlLabels = tool;
                            break;
                        end
                    end
                end
            end

            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));
            Atlas                   = Cortex.Atlas(Cortex.iAtlas);
            Vertices                = Cortex.Vertices;
            Scouts                  = Atlas.Scouts;
            for i=1:length(Scouts)                
                if(controlLabels.Value)
                    text(app.currentAxes,Vertices(Scouts(i).Seed,1),Vertices(Scouts(i).Seed,2),Vertices(Scouts(i).Seed,3),Scouts(i).Label,'Color', Scouts(i).Color);
                end
                hold on;
            end
        end

        function ChangeLabels(app,event)
            import guide.functions.*
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));              
            Atlas                   = Cortex.Atlas(Cortex.iAtlas);
            Scouts                  = Atlas.Scouts;
            regions                 = {'LT', 'LU', 'LP', 'LO', 'RO', 'RP', 'RU', 'RT', 'RL', 'RC', 'RPF', 'RF', 'LF', 'LPF', 'LL', 'LC'};
            orderScouts             = [];
            pos                     = 1;
            for i=1:length(regions)
                region              = regions{i};
                ScoutsRegion_ind    = ismember({Scouts.Region},{region});
                if(any(ScoutsRegion_ind))
                    ScoutsRegion    = Scouts(ScoutsRegion_ind);
                    orderScouts     = [orderScouts ScoutsRegion];
                    groupScoutsInd{pos} = ScoutsRegion_ind;
                    outRegions{pos} = region;
                    pos             = pos+1;
                end
            end
            comp = app.PanelViewer.Children(3).Children;
           
            for i=1:length(comp)
                switch event.Value
                    case 'Parcels'

                    case 'Regions'
                end
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import app.*
            import app.functions.*
            import functions.*
            %movegui(app.UIFigure,"center");
            app.app_properties = get_properties();
            % Load Explorer tree
            userDir = getUserDir();
            app.last_dir = userDir;
            app.Breadcrumbs(userDir);
            [nodeData.folder,nodeData.name] = fileparts(userDir);
            nodeData.isdir = 1;
            app.ExploreFolder(nodeData);   

            app.XANdir  = xan_get('xan_dir'); 
            app.Datasets_file = xan_get('datasets_file');
            
            app.LoadDatasetsDB();
            app.FillDatasetsTree();
            figure(app.UIFigure);
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end

        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            nodeData = event.InteractionInformation.Node.NodeData;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);   
                dirName = split(rootDir,filesep);
                dirName =dirName{end};
                rootDir = fileparts(rootDir);
                nodeData.folder = rootDir; 
                nodeData.name = dirName;            
            end
            if(nodeData.isdir)
                app.ExploreFolder(nodeData);
            end

        end

        % Callback function: RunProcessPushTool
        function RunProcessPushToolClicked(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            dlg = uiprogressdlg(app.UIFigure, ...
                'Icon','+guide/images/processing1.gif', ...
                'Title','Processing dataset', ...
                'Message','Please wait...', ...
                'Cancelable','on');
            
            if(isempty(app.app_properties.general_params.input_path))
                msg =  msgbox({'You should define a correct Input folder.'},'Info');
                waitfor(msg);
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.app_properties.general_params.output_path))
                msgbox({'You should define a correct Output folder.'},'Info');
                figure(app.UIFigure);
                return;
            end 
            app.app_properties = get_properties();
            app.app_properties.dlg = dlg;
            app.app_properties.UIFigure = app.UIFigure;
            process_interface(app.app_properties);
        
            app.XANdir  = xan_get('xan_dir'); 
            app.Datasets_file = xan_get('datasets_file'); 
            app.Datasets = xan_get('datasets');

            app.FillDatasetsTree();
            app.TabGroup.SelectedTab = app.TabDatasets;
            close(dlg);
            figure(app.UIFigure);
                        
            if (ispc)
                winopen(app.currentDS.Location);
            elseif ismac
                cmdToExecute = ['open ' app.currentDS.Location];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            else
                % using xdg-open to open a file in Linux. Some Linux systems might not have
                % xdg-open .In that case displaying as error with the file path
                cmdToExecute = ['xdg-open ' app.currentDS.Location];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            end
            disp("=============================================================");
            disp("-->> Procesing finished");
            disp("=============================================================");
        end

        % Selection changed function: TreeDatasets
        function TreeDatasetsSelectionChanged(app, event)
            selectedNode = app.TreeDatasets.SelectedNodes;           
            delete(app.PanelControls.Children);
            nodeData = selectedNode.NodeData;

            %%
            %%  Filling actions
            %%
            delete(app.TreeActions.Children);
            delete(app.InformationPanel.Children);
            if(isequal(selectedNode.UserData.NodeType,'dataset'))
                app.currentDS = jsondecode(fileread(fullfile(selectedNode.NodeData.Location,'XIALPHANET.json'))); 
                labelName = uilabel(app.InformationPanel,"Text",app.currentDS.Name,"WordWrap","on","Position",[10 80 30 100]);
                labelDesc = uilabel(app.InformationPanel,"Text",strcat(app.currentDS.Description{1},'...'),"WordWrap","on","Position",[10 50 50 100]);
            end
            if(isequal(selectedNode.UserData.NodeType,'subject'))
                app.currentDS = selectedNode.Parent.NodeData;
                app.currentSub = nodeData;
                if(~isempty(fieldnames(nodeData)))
                    labelSubID = uilabel(app.InformationPanel,"Text",strcat("ID: ", app.currentSub.SubID),"WordWrap","on","Position",[10 80 150 30]);
                    labelAge = uilabel(app.InformationPanel,"Text",strcat("Age: ", num2str(app.currentSub.Age)),"WordWrap","on","Position",[10 50 100 30]);
                end
            end
            
            if(~isempty(fields(nodeData)))
                child = uitreenode(app.TreeActions,'Text','PSD');
                child.Tag = 'psd';
                child.Icon = '+guide/images/frequency.png';
                child.NodeData = nodeData;

                %% Alpha
                child = uitreenode(app.TreeActions,'Text','Alpha');
                child.Icon = '+guide/images/frequency.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Power');
                subChild.Tag = 'power_alpha';
                subChild.Icon = '+guide/images/power.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Width');
                subChild.Tag = 'width_alpha';
                subChild.Icon = '+guide/images/width.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Exponent');
                subChild.Tag = 'exponent_alpha';
                subChild.Icon = '+guide/images/exponent.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','PAF');
                subChild.Tag = 'paf_alpha';
                subChild.Icon = '+guide/images/paf.png';
                subChild.NodeData = nodeData;

                %% Xi (AC)
                child = uitreenode(app.TreeActions,'Text','Xi (AC)');
                child.Icon = '+guide/images/frequency.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Power');
                subChild.Tag = 'power_xi';
                subChild.Icon = '+guide/images/power.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Width');
                subChild.Tag = 'width_xi';
                subChild.Icon = '+guide/images/width.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Exponent');
                subChild.Tag = 'exponent_xi';
                subChild.Icon = '+guide/images/exponent.png';
                subChild.NodeData = nodeData;

                %% Activation
                child = uitreenode(app.TreeActions,'Text','Activation');
                child.Tag = 'activation';
                child.Icon = '+guide/images/activation.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Full Activation');
                subChild.Tag = 'full_activation';
                subChild.Icon = '+guide/images/activation.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Alpha Activation');
                subChild.Tag = 'activation_alpha';
                subChild.Icon = '+guide/images/activation.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Xi Activation');
                subChild.Tag = 'activation_xi';
                subChild.Icon = '+guide/images/activation.png';
                subChild.NodeData = nodeData;               


                %% Anat Connectivity
                child = uitreenode(app.TreeActions,'Text','Anat Connectivity');
                child.Tag = 'anat_conn';
                child.Icon = '+guide/images/anat_conn.png';
                child.NodeData = nodeData;

                %% Condition Delay
                child = uitreenode(app.TreeActions,'Text','Conduction Delay');
                child.Tag = 'cond_delay';
                child.Icon = '+guide/images/delays.png';
                child.NodeData = nodeData;

                %% Source PSD
                child = uitreenode(app.TreeActions,'Text','Source PSD');
                child.Tag = 'source_psd';
                child.Icon = '+guide/images/frequency.png';
                child.NodeData = nodeData;

                %% Shows cross spectrum
                app.PlotSensorCrossSpectrum();
            else
                delete(app.PanelViewer.Children);
                delete(app.PanelControls.Children);
            end
            expand(app.TreeActions,'all');
        end

        % Button pushed function: UpperButton
        function UpperButtonPushed(app, event)
            nodeData = app.TreeInput.Children(1).NodeData;
            rootDir = fileparts(nodeData.folder);
            dirName = split(nodeData.folder,filesep);
            dirName =dirName{end};
            nodeData.folder = rootDir;
            nodeData.name = dirName;
            app.ExploreFolder(nodeData);
        end

        % Button pushed function: NewFolderButton
        function NewFolderButtonPushed(app, event)
            selectedNode = app.TreeInput.SelectedNodes;
            if(isempty(selectedNode))
                selectedNode = app.TreeInput.Children(1);
            end
            inputDLG = inputdlg({'Name:'}, 'New folder', 1);
            if(~isempty(inputDLG))
                folderName= char(inputDLG(1));                
                rootDir = fullfile(selectedNode.NodeData.folder,selectedNode.NodeData.name);
                folders = dir(rootDir);
                if(~isempty(find(ismember({folders.name},{folderName}),1)))
                     msg = msgbox({'The folder name alresdy exist'},'Info');
                     waitfor(msg);
                     return;
                end
                try
                    mkdir(fullfile(rootDir,folderName));
                catch Ex
                    msg = msgbox({Ex.message},'Info');
                    waitfor(msg);
                end
                app.ExploreFolder(selectedNode.NodeData);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            nodeData = app.TreeInput.Children(1).NodeData;       
            app.ExploreFolder(nodeData);
        end

        % Button pushed function: DeleteFolderButton
        function DeleteFolderButtonPushed(app, event)
            
            selectedNode = app.TreeInput.SelectedNodes;
            if(isempty(selectedNode))
                msg = msgbox({'Please select a folder to delete'},'Info');
                waitfor(msg);
                return;
            end
           
            dlg = uiprogressdlg(app.UIFigure, ...
                'Icon','+guide/images/waiting.gif', ...
                'Title','Please wait...', ...
                'Message','Waiting for message box');
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Cancel';
            % Use the TeX interpreter to format the question
            quest = {strcat("Would you like to delete the folder: ",selectedNode.Text)};
            answer = questdlg(quest,'Delete folder',...
                'Yes','Cancel',opts);
            % Handle response
            switch answer
                case 'Yes'
                    nodeData = selectedNode.NodeData;
                    [status, message, ~] = rmdir(fullfile(nodeData.folder,nodeData.name), 's');
                    if(status)
                        msg = msgbox(strcat("The folder ",selectedNode.Text, " was deleted."),'Info');
                        waitfor(msg);
                        app.RefreshButtonPushed(event);
                    else
                        msg = msgbox(message,'info');
                        waitfor(msg);
                    end
                case 'Cancel'
                    close(d);
                    figure(app.UIFigure);
                    return;
            end
            close(dlg);
            figure(app.UIFigure);
        end

        % Callback function: NewProcessPushTool, NewprocessMenu
        function NewProcessPushToolClicked(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/processing1.gif', ...
                    'Title','Please wait...', ...
                    'Message','Parameters configuration');           
            obj_Config = ParamsUI();
            waitfor(obj_Config,'Finished',true);
            app.last_dir = obj_Config.last_dir;
            delete(obj_Config);
            app.app_properties = get_properties();            
            close(dlg);
            figure(app.UIFigure);
        end

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            try
                folder = uigetdir("Select the root path");
                folder = strrep(folder,'\','/');
                [base_path, name] = fileparts(folder);
                nodeData.isdir = 1;
                nodeData.name = name;
                nodeData.folder = base_path;
                app.last_dir = folder;
                app.ExploreFolder(nodeData);
                figure(app.UIFigure);
            catch
                
            end
        end

        % Selection changed function: TreeActions
        function TreeActionsSelectionChanged(app, event)
            % d = uiprogressdlg(app.UIFigure,'Title','Waiting for visualization');   
            selectedNode = app.TreeActions.SelectedNodes;
            nodeData = selectedNode.NodeData;
            delete(app.PanelViewer.Children);
            freqInd = 1;
            if(~isempty(app.PanelControls.Children))
                control = app.PanelControls.Children(1);
                if(isequal(control.Tag,'freqs'))
                    freqInd = control.ValueIndex;
                end
            end
            delete(app.PanelControls.Children);
            % Checking node level
            if(isempty(nodeData))               
               return;
            end
            
            % PSD
            if(isequal(selectedNode.Tag,'psd'))
                app.PlotSensorCrossSpectrum();
            end

            % Showing Alpha measures
            if(isequal(selectedNode.Tag,'power_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Power);
            end
            if(isequal(selectedNode.Tag,'width_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Width,alpha.Power);
            end
            if(isequal(selectedNode.Tag,'exponent_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Exponent,alpha.Power);
            end
            if(isequal(selectedNode.Tag,'paf_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.PAF,alpha.Power);
            end

            % Showing Xi measures
            if(isequal(selectedNode.Tag,'power_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Power);
            end
            if(isequal(selectedNode.Tag,'width_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Width);
            end
            if(isequal(selectedNode.Tag,'exponent_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Exponent);
            end
            
            % Showing Source cross spectrum
            if(isequal(selectedNode.Tag,'source_psd'))
                %% Showing Source cross spectrum
                app.PlotSourceCrossSpectrum(); 
            end

             % Showing Activation
            if(isequal(selectedNode.Tag,'full_activation'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_full = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Full));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)), 'Tag', 'freqs',...
                    'ValueIndex', freqInd, 'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.ChangeActivationFreq(activation_full.Source_Activations_Full));
                app.PlotFullActivation(activation_full.Source_Activations_Full);
            end 
            if(isequal(selectedNode.Tag,'activation_alpha'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Alpha));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)), 'Tag', 'freqs',...
                    'ValueIndex', freqInd, 'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.ChangeActivationFreq(activation_alpha.Source_Activations_Alpha));
                app.PlotFullActivation(activation_alpha.Source_Activations_Alpha);
            end
            if(isequal(selectedNode.Tag,'activation_xi'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Xi));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)), 'Tag', 'freqs',...
                    'ValueIndex', freqInd, 'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.ChangeActivationFreq(activation_xi.Source_Activations_Xi));
                app.PlotFullActivation(activation_xi.Source_Activations_Xi);
            end

            % Showing Anat connectivity
            if(isequal(selectedNode.Tag,'anat_conn'))  
                pos = app.PanelControls.Position;
                                
                uibutton(app.PanelControls,'Text','Connectivity','Position',[(pos(3)/2-100) 5 90 30],...
                    'ButtonPushedFcn',@(src,event) app.PlotSourceConnnectivity());   
                uibutton(app.PanelControls,'Text','Circular graph','Position',[(pos(3)/2) 5 100 30],...
                    'ButtonPushedFcn',@(src,event) app.PlotSourceConnnectivityCircular());               
                app.PlotSourceConnnectivity();
            end 

             % Showing Conduction delays
            if(isequal(selectedNode.Tag,'cond_delay')) 
               cond_delay = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Delay_Matrix));
            end 
            % close(d);
            figure(app.UIFigure);
       
        end

        % Callback function: LoadMenu, UploadDatasetPushTool
        function LoadMenuSelected(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/loading.gif', ...
                    'Title','Please wait...', ...
                    'Message','Loading Dataset');     
            
            folder = uigetdir(app.last_dir,"Select the Dataset folder");
            if(isequal(folder,0))
                figure(app.UIFigure);
                return;
            end
            app.last_dir = folder; 
            dataset_file = fullfile(folder,'XIALPHANET.json');
            if(isfile(dataset_file))
                XiAlphaNet_info = jsondecode(fileread(dataset_file));
                XiAlphaNet_info.Location = folder;
                if(~isempty(app.Datasets) && ~isempty(find(ismember({app.Datasets.UUID},XiAlphaNet_info.UUID),1)))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Add';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The dataset: ", XiAlphaNet_info.Name, " is already loaded"), ...
                        'Would you like import this the dataset anyway?'};
                    answer = questdlg(quest,'Apply changes',...
                        'Add','Replace','Cancel',opts);
                    % Handle response
                    switch answer
                        case 'Add'
                            app.Datasets(end + 1) = XiAlphaNet_info;
                            xan_set('datasets',app.Datasets);
                            saveJSON(XiAlphaNet_info,dataset_file);
                            [icondata,iconcmap] = imread("+guide/images/success.png");
                            msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                            disp("-->> Dataset loaded");
                        case 'Replace'
                            index = find(ismember({app.Datasets.UUID},XiAlphaNet_info.UUID),1);
                            app.Datasets(index) = XiAlphaNet_info;                            
                            xan_set('datasets',app.Datasets);
                            saveJSON(XiAlphaNet_info,dataset_file);
                            disp("-->> Dataset replaced");
                            [icondata,iconcmap] = imread("+guide/images/success.png");
                            msg = msgbox({'The Dataset was replaced successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                        otherwise
                            return;
                    end
                else
                    if(isempty(app.Datasets))
                        app.Datasets = XiAlphaNet_info;
                    else
                        app.Datasets(end + 1) = XiAlphaNet_info;
                    end
                    [icondata,iconcmap] = imread("+guide/images/success.png");
                    msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                    waitfor(msg);
                    disp("-->> Dataset loaded");
                    xan_set('datasets',app.Datasets);
                    saveJSON(XiAlphaNet_info,dataset_file);
                    app.currentDS = XiAlphaNet_info;
                end
            else
                msg = msgbox({'The selected folder do not contains the dataset file.',...
                    ' Please select a correct folder.'},'Error',"error","modal");
                waitfor(msg);
            end
            close(dlg);
            % Updating Dataset Tree
            app.FillDatasetsTree();
            figure(app.UIFigure);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Callback function: ListDatasetsPushTool, ListMenu
        function ListMenuSelected(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/waiting.gif', ...
                    'Title','Please wait...', ...
                    'Message','Waiting for List Datasets', ...
                    'Indeterminate','on'); 
            if(isempty(app.Datasets))
                msg = msgbox({'There is no Dataset to show.',...
                    'Please import or create a Dataset.'},'Info',"help","modal");
                movegui(msg,'north');
                waitfor(msg);
            else
                datasetUI                   = DatasetsUI;    
                datasetUI.XANdir            = app.XANdir;
                datasetUI.Datasets_file     = app.Datasets_file;
                datasetUI.Datasets          = app.Datasets;  
                datasetUI.last_dir          = app.last_dir;
                tableData.Name              = {app.Datasets.Name}';                
                for i=1:length(app.Datasets)                   
                    descrip_array{i}        = string(app.Datasets(i).Description);
                end
                tableData.Description       = descrip_array';
                tableData.Location          = {app.Datasets.Location}';
                datasetUI.UITable.Data      = struct2table(tableData);                
                waitfor(datasetUI,'Finished',true);
                delete(datasetUI);
            end
            app.Datasets = xan_get('datasets');
            app.FillDatasetsTree();
            app.TabGroup.SelectedTab = app.TabDatasets;
            close(dlg);
            figure(app.UIFigure);            
        end

        % Menu selected function: DownloaddataMenu
        function DownloadTestDataMenuSelected(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            % try
            folder = uigetdir(app.last_dir,"Select the folder to download the test data");
            [~,values] = fileattrib(folder);
            if(values.UserWrite)
                % Checking the folder free space
                FileObj      = java.io.File(folder);
                free_bytes   = FileObj.getFreeSpace;
                total_bytes  = FileObj.getTotalSpace;
                usable_bytes = FileObj.getUsableSpace;
                size = sizeconvert(free_bytes,1);
                if(size{1} < 6)
                    msg = msgbox({'The selected folder do not contain enoght free space to download and unzip the test data.', ...
                        "Please select a diferent folder."},'Info');
                    waitfor(msg);
                    return;
                end
                figure(app.UIFigure);
                app.last_dir = folder;
                % Downloading test data
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/downloading.gif', ...
                    'Title','Please wait...', ...
                    'Message','Downloading the test data');

                filename = strcat('MultinationalNorms.zip');
                disp(strcat("-->> Downloading test data......."));
                url = app.app_properties.test_data.dataset;
                % d = DownloadFile(url,folder,filename);
                setGlobalDownloaded(false);
                fullSize = 3250712054;
                iter = false;
                while true
                    if(~iter)
                        iter = true;
                        out = parfeval(@DownloadFile,1,url,folder,filename);
                    end
                    pause(1);
                    s = dir(fullfile(folder,filename));
                    if(~isempty(s))
                        dlg.Value = s.bytes/fullSize;
                        drawnow;
                        if(dlg.Value > 0.95)
                            dlg.Value = 1;
                            drawnow;
                            break;
                        end
                    end
                end
                pause(1);
                downloaded = fetchOutputs(out);
                if(~downloaded)
                    close(dlg);
                    delete(gcp('nocreate'));
                    return;
                end
                close(dlg);

                % Unzipping Test data
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/unzipping.png', ...
                    'Title','Please wait...', ...
                    'Message','Unziping the test data');
                disp(strcat("-->> Unziping files......."));
                fullSize = 3528212480;
                iter = false;
                while true
                    if(~iter)
                        iter = true;
                        out = parfeval(@UnzipFile,1,folder,filename);
                    end
                    pause(1);
                    if(isfolder(fullfile(folder,strrep(filename,'.zip',''))))
                        bytes = DirSize(fullfile(folder,strrep(filename,'.zip','')));
                        if(~isequal(bytes,0))
                            dlg.Value = bytes/fullSize;
                            drawnow;
                            if(dlg.Value > 0.95)
                                dlg.Value = 1;
                                drawnow;
                                break;
                            end
                        end
                    end
                end
                pause(1);
                unzipped = fetchOutputs(out);
                if(~unzipped)
                    close(dlg);
                    delete(gcp('nocreate'));
                    return;
                end
                close(dlg);
                delete(gcp('nocreate'));
                app.app_properties.general_params.input_path = strrep(fullfile(folder,strrep(filename,'.zip','')),'\','/');
                saveJSON(app.app_properties.general_params,app.app_properties.module_param_files(1).file_path);
                msg = msgbox({'The test data was dowloaded successfully.'},'Info');
                waitfor(msg);
                figure(app.UIFigure);
            else
                msg = msgbox({'The current user do not have write permissions on the selected folder.',...
                    ' Please check the folder permission or select another folder to save the test data.'},'Info');
                waitfor(msg);
            end
            % catch
            %     msg = msgbox({'Download error.',...
            %         ' Please try it again.'},'Error');
            %     waitfor(msg);
            % end
            figure(app.UIFigure);
        end

        % Callback function: DefaultParamsPushTool, DefaultparamsMenu
        function DefaultParamsPushToolClicked(app, event)
             opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Yes';
            % Use the TeX interpreter to format the question
            quest = {strcat("You will restore the default params"), ...
                'Would you like continue?'};
            answer = questdlg(quest,'Apply changes',...
                'Yes','No',opts);
            % Handle response
            switch answer
                case 'Yes'
                    set_default_properties(app);
                    [icondata,iconcmap] = imread("+guide/images/success.png");
                    msg = msgbox({'The Default params has been setted successfully!!!'},'Success',"custom",icondata,iconcmap);
                    waitfor(msg);
                    disp("-->> Datasets loaded");                    
                case 'No'
            end
            figure(app.UIFigure);
        end

        % Menu selected function: ExampleOutputsMenu
        function ExampleOutputsMenuSelected(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            folder = uigetdir(app.last_dir,"Select the folder to download the example output data");
            [~,values] = fileattrib(folder);
            if(values.UserWrite)
                % Checking the folder free space
                FileObj      = java.io.File(folder);
                free_bytes   = FileObj.getFreeSpace;
                total_bytes  = FileObj.getTotalSpace;
                usable_bytes = FileObj.getUsableSpace;
                size = sizeconvert(free_bytes,1);
                if(size{1} < 6)
                    msg = msgbox({'The selected folder do not contain enoght free space to download and unzip the test data.', ...
                        "Please select a diferent folder."},'Info');
                    waitfor(msg);
                    return;
                end
                figure(app.UIFigure);
                app.last_dir = folder;
                % Downloading test data
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/downloading.gif', ...
                    'Title','Please wait...', ...
                    'Message','Downloading the test data');

                filename = strcat('MultinationalNorms.zip');
                disp(strcat("-->> Downloading test data......."));
                url = app.app_properties.test_data.dataset;
                % d = DownloadFile(url,folder,filename);
                setGlobalDownloaded(false);
                fullSize = 3250712054;
                iter = false;
                while true
                    if(~iter)
                        iter = true;
                        out = parfeval(@DownloadFile,1,url,folder,filename);
                    end
                    pause(1);
                    s = dir(fullfile(folder,filename));
                    if(~isempty(s))
                        dlg.Value = s.bytes/fullSize;
                        drawnow;
                        if(dlg.Value > 0.95)
                            dlg.Value = 1;
                            drawnow;
                            break;
                        end
                    end
                end
                pause(1);
                downloaded = fetchOutputs(out);
                if(~downloaded)
                    close(dlg);
                    delete(gcp('nocreate'));
                    return;
                end
                close(dlg);

                % Unzipping Test data
                dlg = uiprogressdlg(app.UIFigure, ...
                    'Icon','+guide/images/unzipping.png', ...
                    'Title','Please wait...', ...
                    'Message','Unziping the test data');
                disp(strcat("-->> Unziping files......."));
                fullSize = 3528212480;
                iter = false;
                while true
                    if(~iter)
                        iter = true;
                        out = parfeval(@UnzipFile,1,folder,filename);
                    end
                    pause(1);
                    if(isfolder(fullfile(folder,strrep(filename,'.zip',''))))
                        bytes = DirSize(fullfile(folder,strrep(filename,'.zip','')));
                        if(~isequal(bytes,0))
                            dlg.Value = bytes/fullSize;
                            drawnow;
                            if(dlg.Value > 0.95)
                                dlg.Value = 1;
                                drawnow;
                                break;
                            end
                        end
                    end
                end
                pause(1);
                unzipped = fetchOutputs(out);
                if(~unzipped)
                    close(dlg);
                    delete(gcp('nocreate'));
                    return;
                end
                close(dlg);
                delete(gcp('nocreate'));
                app.app_properties.general_params.input_path = strrep(fullfile(folder,strrep(filename,'.zip','')),'\','/');
                saveJSON(app.app_properties.general_params,app.app_properties.module_param_files(1).file_path);
                msg = msgbox({'The test data was dowloaded successfully.'},'Info');
                waitfor(msg);
                figure(app.UIFigure);
            else
                msg = msgbox({'The current user do not have write permissions on the selected folder.',...
                    ' Please check the folder permission or select another folder to save the example outputs.'},'Info');
                waitfor(msg);
            end
            % catch
            %     msg = msgbox({'Download error.',...
            %         ' Please try it again.'},'Error');
            %     waitfor(msg);
            % end
            figure(app.UIFigure);
        end

        % Menu selected function: DownloadTensorFieldsMenu
        function DownloadTensorFieldsMenuSelected(app, event)
            import app.*
            import app.functions.*
            import functions.*
            import guide.*
            import tools.*
            % try
            folder = app.app_properties.general_params.tmp.path;
            if(isequal(lower(folder),'local'))
                folder = fullfile(pwd,'tmp');
                if(~isfolder(folder))
                    mkdir(folder);
                end
            end
            TF_path = fullfile(folder,'TensorField');
            if(~isfolder(TF_path))
                mkdir(TF_path);
            end
            
            [~,values] = fileattrib(TF_path);
            if(values.UserWrite)
                % Checking the folder free space
                FileObj      = java.io.File(TF_path);
                free_bytes   = FileObj.getFreeSpace;
                size = sizeconvert(free_bytes,1);
                if(size{1} < 50)
                    msg = msgbox({'The temporal folder do not contain enoght free space to download and unzip the tendor field.', ...
                        "Please select a diferent temporal folder."},'Info');
                    waitfor(msg);
                    return;
                end
                figure(app.UIFigure);
                app.last_dir = TF_path;
                %%
                %% Tensor Field 9.5ms and 11ms
                %%
                TF_names = {'Tensor_Field_9.5ms','Tensor_Field_11ms'};
                mSecons = {'9.5','11'};
                zip_fullSizes = {12687521346,12691135947};
                %zip_fullSizes = {1149465121 ,1149465121};
                folders_fullSizes = {12683000000, 12687000000};
                %folders_fullSizes = {1150296297, 1150296297};                
                urls = {app.app_properties.test_data.tensor_field9, app.app_properties.test_data.tensor_field11};
                for i=1:2
                    TF_name = TF_names{i};
                    filename = strcat(TF_name,'.zip');
                    ms = mSecons{i};
                    zipFullSize = zip_fullSizes{i};
                    folderFullSize = folders_fullSizes{i};
                    url = urls{i};
                    % Downloading Tensor Field
                    dlg = uiprogressdlg(app.UIFigure, ...
                        'Icon','+guide/images/downloading.gif', ...
                        'Title','Please wait...', ...
                        'Message',strcat("Downloading the Tensor Field ",ms,"ms"));

                    disp(strcat("-->> Downloading Tensor Field ",ms,"ms......."));
                    iter = false;
                    while true
                        if(~iter)
                            iter = true;
                            out = parfeval(@DownloadFile,1,url,TF_path,filename);
                        end
                        pause(1);
                        s = dir(fullfile(TF_path,filename));
                        if(~isempty(s))
                            dlg.Value = s.bytes/zipFullSize;
                            drawnow;
                            if(dlg.Value > 0.98)
                                dlg.Value = 1;
                                drawnow;
                                break;
                            end
                        end
                    end
                    pause(1);
                    downloaded = fetchOutputs(out);
                    close(dlg);
                    delete(gcp('nocreate'));
                    if(~downloaded)
                        continue;
                    end

                    % Unzipping Tensor Field 
                    dlg = uiprogressdlg(app.UIFigure, ...
                        'Icon','+guide/images/unzipping.png', ...
                        'Title','Please wait...', ...
                        'Message',strcat("Unziping the Tensor Field ",ms,"ms"));
                    disp(strcat("-->> Unziping files......."));

                    iter = false;
                    while true
                        if(~iter)
                            iter = true;
                            out = parfeval(@UnzipFile,1,TF_path,filename);
                        end
                        pause(1);
                        if(isfolder(fullfile(TF_path,TF_name)))
                            bytes = DirSize(fullfile(TF_path,TF_name));
                            if(~isequal(bytes,0))
                                dlg.Value = bytes/folderFullSize;
                                drawnow;
                                if(dlg.Value > 0.98)
                                    dlg.Value = 1;
                                    drawnow;
                                    break;
                                end
                            end
                        end
                    end
                    pause(1);
                    unzipped = fetchOutputs(out);
                    close(dlg);
                    delete(gcp('nocreate'));
                    if(~unzipped)
                        continue;
                    end 
                end                
                msg = msgbox({'The Tensor Field were dowloaded successfully.'},'Info');
                waitfor(msg);
                figure(app.UIFigure);
            else
                msg = msgbox({'The current user do not have write permissions on the temporal folder.',...
                    ' Please check the folder permission or select another temporal folder.'},'Info');
                waitfor(msg);
            end
            % catch
            %     msg = msgbox({'Download error.',...
            %         ' Please try it again.'},'Error');
            %     waitfor(msg);
            % end
            figure(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Xi-AlphaNet';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'favicon.png');
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ProcessMenu = uimenu(app.FileMenu);
            app.ProcessMenu.Text = 'Process';
            app.NewprocessMenu = uimenu(app.ProcessMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewProcessPushToolClicked, true);
            app.NewprocessMenu.Text = 'New process';
            app.DefaultparamsMenu = uimenu(app.ProcessMenu);
            app.DefaultparamsMenu.MenuSelectedFcn = createCallbackFcn(app, @DefaultParamsPushToolClicked, true);
            app.DefaultparamsMenu.Text = 'Default params';
            app.RunMenu = uimenu(app.ProcessMenu);
            app.RunMenu.Text = 'Run';
            app.DatasetsMenu = uimenu(app.FileMenu);
            app.DatasetsMenu.Text = 'Datasets';
            app.LoadMenu = uimenu(app.DatasetsMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ListMenu = uimenu(app.DatasetsMenu);
            app.ListMenu.MenuSelectedFcn = createCallbackFcn(app, @ListMenuSelected, true);
            app.ListMenu.Text = 'List';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.TestingMenu = uimenu(app.ToolsMenu);
            app.TestingMenu.Text = 'Testing';
            app.DownloaddataMenu = uimenu(app.TestingMenu);
            app.DownloaddataMenu.MenuSelectedFcn = createCallbackFcn(app, @DownloadTestDataMenuSelected, true);
            app.DownloaddataMenu.Text = 'Download data';
            app.DownloadTensorFieldsMenu = uimenu(app.TestingMenu);
            app.DownloadTensorFieldsMenu.MenuSelectedFcn = createCallbackFcn(app, @DownloadTensorFieldsMenuSelected, true);
            app.DownloadTensorFieldsMenu.Text = 'Download Tensor Fields';
            app.ExampleOutputsMenu = uimenu(app.TestingMenu);
            app.ExampleOutputsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExampleOutputsMenuSelected, true);
            app.ExampleOutputsMenu.Text = 'Example Outputs';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.CheckforupdateMenu = uimenu(app.HelpMenu);
            app.CheckforupdateMenu.Text = 'Check for update';
            app.WikiMenu = uimenu(app.HelpMenu);
            app.WikiMenu.Text = 'Wiki';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.RunProcessPushTool = uipushtool(app.Toolbar);
            app.RunProcessPushTool.Tooltip = {'Run processing'};
            app.RunProcessPushTool.ClickedCallback = createCallbackFcn(app, @RunProcessPushToolClicked, true);
            app.RunProcessPushTool.Icon = fullfile(pathToMLAPP, 'images', 'run.jpg');
            app.NewProcessPushTool = uipushtool(app.Toolbar);
            app.NewProcessPushTool.Tooltip = {'Params configuration'};
            app.NewProcessPushTool.ClickedCallback = createCallbackFcn(app, @NewProcessPushToolClicked, true);
            app.NewProcessPushTool.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.DefaultParamsPushTool = uipushtool(app.Toolbar);
            app.DefaultParamsPushTool.Tooltip = {'Set default parameters'};
            app.DefaultParamsPushTool.ClickedCallback = createCallbackFcn(app, @DefaultParamsPushToolClicked, true);
            app.DefaultParamsPushTool.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.UploadDatasetPushTool = uipushtool(app.Toolbar);
            app.UploadDatasetPushTool.Tooltip = {'Upload dataset'};
            app.UploadDatasetPushTool.ClickedCallback = createCallbackFcn(app, @LoadMenuSelected, true);
            app.UploadDatasetPushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.UploadDatasetPushTool.Separator = 'on';
            app.ListDatasetsPushTool = uipushtool(app.Toolbar);
            app.ListDatasetsPushTool.Tooltip = {'List datasets'};
            app.ListDatasetsPushTool.ClickedCallback = createCallbackFcn(app, @ListMenuSelected, true);
            app.ListDatasetsPushTool.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.Tooltip = {'Help'};
            app.HelpPushTool.Icon = fullfile(pathToMLAPP, 'images', 'help.png');
            app.HelpPushTool.Separator = 'on';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.UpperButton = uibutton(app.TabExplorer, 'push');
            app.UpperButton.ButtonPushedFcn = createCallbackFcn(app, @UpperButtonPushed, true);
            app.UpperButton.Icon = fullfile(pathToMLAPP, 'images', 'folder_parent.png');
            app.UpperButton.Tooltip = {'Go to parent'};
            app.UpperButton.Text = '';
            app.NewFolderButton = uibutton(app.TabExplorer, 'push');
            app.NewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @NewFolderButtonPushed, true);
            app.NewFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'new_folder.png');
            app.NewFolderButton.Tooltip = {'New Folder'};
            app.NewFolderButton.Text = '';
            app.RefreshButton = uibutton(app.TabExplorer, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'images', 'refresh.png');
            app.RefreshButton.Tooltip = {'Refresh explorer'};
            app.RefreshButton.Text = '';
            app.DeleteFolderButton = uibutton(app.TabExplorer, 'push');
            app.DeleteFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFolderButtonPushed, true);
            app.DeleteFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.DeleteFolderButton.Tooltip = {'Delete folder'};
            app.DeleteFolderButton.Text = '';
            app.OpenFolderButton = uibutton(app.TabExplorer, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'open_folder.png');
            app.OpenFolderButton.Tooltip = {'Open Folder'};
            app.OpenFolderButton.Text = '';
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.GridLayout4 = uigridlayout(app.TabDatasets);
            app.TreeDatasets = uitree(app.GridLayout4);
            app.TreeDatasets.SelectionChangedFcn = createCallbackFcn(app, @TreeDatasetsSelectionChanged, true);
            app.InformationPanel = uipanel(app.GridLayout4);
            app.InformationPanel.Title = 'Information';
            app.PanelViewer = uipanel(app.GridLayout);
            app.PanelViewer.Title = 'Viewer';
            app.LabelBreadcrumbs = uilabel(app.GridLayout);
            app.LabelBreadcrumbs.Text = '';
            app.PanelControls = uipanel(app.GridLayout);
            app.PanelActions = uipanel(app.GridLayout);
            app.PanelActions.Title = 'Actions';
            app.GridLayout3 = uigridlayout(app.PanelActions);
            app.TreeActions = uitree(app.GridLayout3);
            app.TreeActions.SelectionChangedFcn = createCallbackFcn(app, @TreeActionsSelectionChanged, true);
            app.NodeAction1 = uitreenode(app.TreeActions);
            app.NodeAction1.Text = 'Node';
            app.Node2 = uitreenode(app.NodeAction1);
            app.Node2.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.NodeAction1);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.NodeAction1);
            app.Node4.Text = 'Node4';
            app.NodeAction2 = uitreenode(app.TreeActions);
            app.NodeAction2.Text = 'Node';
            app.Node2_2 = uitreenode(app.NodeAction2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.NodeAction2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.NodeAction2);
            app.Node4_2.Text = 'Node4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XiAlphaNetUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end