classdef DatasetsUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        HelpMenu         matlab.ui.container.Menu
        Toolbar          matlab.ui.container.Toolbar
        LoadPushTool     matlab.ui.container.toolbar.PushTool
        ExplorePushTool  matlab.ui.container.toolbar.PushTool
        DeletePushTool   matlab.ui.container.toolbar.PushTool
        PushTool         matlab.ui.container.toolbar.PushTool
        GridLayout       matlab.ui.container.GridLayout
        Panel            matlab.ui.container.Panel
        CloseButton      matlab.ui.control.Button
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = public)
        XANdir;
        Datasets_file;
        Datasets;
        last_dir;
        currentDS;
        Finished;
    end
    
    properties (Access = private)
       
    end
    
    methods (Access = private)
        
        function UpdateDatasetsTable(app)
            set(app.UITable, 'Data', {});
            tableData.Name                  = {app.Datasets.Name}';                
                for i=1:length(app.Datasets)                   
                    descrip_array{i}        = string(app.Datasets(i).Description);
                end
                tableData.Description       = descrip_array';                
                tableData.Location          = {app.Datasets.Location}';
                app.UITable.Data            = struct2table(tableData);
        end    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.SelectionType = 'row';
            movegui(app.UIFigure,[800,200]); 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Finished = true;
            app.UIFigure.Visible = false;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            uDataset = app.Datasets(indices(1));
            switch indices(2)
                case 1
                    uDataset.Name = newData;
                case 2
                    uDataset.Description = {newData};
            end
            app.Datasets(indices(1)) = uDataset;
            xan_set('datasets',app.Datasets);
            xan_set('dataset_info',uDataset);
            [icondata,iconcmap] = imread("guide/images/success.png");
            msg = msgbox({'The Dataset was updated successfully!!!'},'Success',"custom",icondata,iconcmap);
            waitfor(msg);
            figure(app.UIFigure);
        end

        % Callback function: LoadPushTool
        function LoadPushToolClicked(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Loading Dataset');
            folder = uigetdir(app.last_dir,"Select the Dataset folder");
            folder = strrep(folder,'\','/');
            if(isequal(folder,0))
                figure(app.UIFigure);
                return;
            end
            app.last_dir = folder;
            dataset_file = fullfile(folder,'XIALPHANET.json');
            if(isfile(dataset_file))
                XiAlphaNet_info = jsondecode(fileread(dataset_file));
                XiAlphaNet_info.Location = folder;
                if(~isempty(app.Datasets) && ~isempty(find(ismember({app.Datasets.UUID},XiAlphaNet_info.UUID),1)))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Add';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("The dataset: ", XiAlphaNet_info.Name, " is already loaded"), ...
                        'Would you like import this the dataset anyway?'};
                    answer = questdlg(quest,'Apply changes',...
                        'Add','Replace','Cancel',opts);
                    % Handle response
                    switch answer
                        case 'Add'
                            app.Datasets(end + 1) = XiAlphaNet_info;
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                            disp("-->> Dataset loaded");
                        case 'Replace'
                            index = find(ismember({app.Datasets.UUID},XiAlphaNet_info.UUID),1);
                            app.Datasets(index) = XiAlphaNet_info;
                            disp("-->> Dataset replaced");
                            [icondata,iconcmap] = imread("guide/images/success.png");
                            msg = msgbox({'The Dataset was replaced successfully!!!'},'Success',"custom",icondata,iconcmap);
                            waitfor(msg);
                        otherwise
                            return;
                    end
                else
                    if(isempty(app.Datasets))
                        app.Datasets = XiAlphaNet_info;
                    else
                        app.Datasets(end + 1) = XiAlphaNet_info;
                    end
                    [icondata,iconcmap] = imread("guide/images/success.png");
                    msg = msgbox({'The Dataset was loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                    waitfor(msg);
                    disp("-->> Dataset loaded");                    
                end
            else
                msg = msgbox({'The selected folder do not contains the dataset file.',...
                    ' Please select a correct folder.'},'Error',"error","modal");
                waitfor(msg);
            end
            xan_set('datasets',app.Datasets);
            xan_set('dataset_info',XiAlphaNet_info);
            app.Datasets = xan_get('datasets');
            app.currentDS = XiAlphaNet_info;
            app.UpdateDatasetsTable();
            close(d);
            % Updating Dataset Tree
            figure(app.UIFigure);
        end

        % Callback function: DeletePushTool
        function DeletePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
              msg =  msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
              waitfor(msg);
            else
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Cancel';
                % Use the TeX interpreter to format the question
                quest = {strcat("Would you like to delete the Dataset: ", app.Datasets(row).Name)};
                answer = questdlg(quest,'Delete',...
                    'Delete','Delete with data','Cancel',opts);
                % Handle response
                switch answer
                    case 'Delete'
                        app.Datasets(row) = [];                        
                        xan_set('datasets',app.Datasets);
                        app.UpdateDatasetsTable();
                        disp("-->> Dataset deleted");
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Dataset was deleted successfully!!!'},'Success',"custom",icondata,iconcmap);
                        waitfor(msg);
                    case 'Delete with data'
                        uDataset = app.Datasets(row);
                        rmdir(uDataset.Location,"s");
                        app.Datasets(row) = [];
                        app.UpdateDatasetsTable();                        
                        xan_set('datasets',app.Datasets);
                        disp("-->> Dataset and data deleted");                        
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Dataset and the data were deleted successfully!!!'},'Success',"custom",icondata,iconcmap);
                        waitfor(msg);
                    case 'Cancel'
                end
            end
        end

        % Callback function: ExplorePushTool
        function ExplorePushToolClicked(app, event)
            row = app.UITable.Selection;
            if(isempty(row))
                msg = msgbox({'Please select a Dataset to delete.'},'Error',"error","modal");
                movegui(msg,'north');
            else
                DSExplore = app.Datasets(row);                
                appExplore = ExploreDS(app,DSExplore);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Datasets';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.LoadPushTool = uipushtool(app.Toolbar);
            app.LoadPushTool.Tooltip = {'Load Dataset'};
            app.LoadPushTool.ClickedCallback = createCallbackFcn(app, @LoadPushToolClicked, true);
            app.LoadPushTool.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.ExplorePushTool = uipushtool(app.Toolbar);
            app.ExplorePushTool.Tooltip = {'Explore Dataset'};
            app.ExplorePushTool.ClickedCallback = createCallbackFcn(app, @ExplorePushToolClicked, true);
            app.ExplorePushTool.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.DeletePushTool = uipushtool(app.Toolbar);
            app.DeletePushTool.Tooltip = {'Delete Dataset'};
            app.DeletePushTool.ClickedCallback = createCallbackFcn(app, @DeletePushToolClicked, true);
            app.DeletePushTool.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Help'};
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images', 'help.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Name'; 'Description'; 'Location'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true true false];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Panel = uipanel(app.GridLayout);
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetsUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end