classdef ParamsConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SetparametersPanel          matlab.ui.container.Panel
        Showrejectedsubjects        matlab.ui.control.Hyperlink
        ButtonNext                  matlab.ui.control.Button
        ButtonBack                  matlab.ui.control.Button
        ApplyButton                 matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        GeneralsTab                 matlab.ui.container.Tab
        GeneralsparamsPanel         matlab.ui.container.Panel
        LabelReqRefFile             matlab.ui.control.Label
        ParallelprocessingLabel     matlab.ui.control.Label
        ParallelProcCheckBox        matlab.ui.control.CheckBox
        RefFileEditField            matlab.ui.control.EditField
        filenameLabel_2             matlab.ui.control.Label
        RefFileButton               matlab.ui.control.Button
        LabelReqOutput              matlab.ui.control.Label
        LabelReqInputPath           matlab.ui.control.Label
        LabelReqDatasetName         matlab.ui.control.Label
        DatasetDescriptionTextArea  matlab.ui.control.TextArea
        DatasetdescriptionLabel     matlab.ui.control.Label
        DatasetnameEditField        matlab.ui.control.EditField
        DatasetnameEditFieldLabel   matlab.ui.control.Label
        TmpPathEditField            matlab.ui.control.EditField
        TmppathLabel                matlab.ui.control.Label
        InputPathEditField          matlab.ui.control.EditField
        InputpathLabel              matlab.ui.control.Label
        TmpPathButton               matlab.ui.control.Button
        InputPathButton             matlab.ui.control.Button
        OutputpathEditField         matlab.ui.control.EditField
        OutputpathEditFieldLabel    matlab.ui.control.Label
        OutputPathButton            matlab.ui.control.Button
        ParticipantsTab             matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        AnatomyTab                  matlab.ui.container.Tab
        UsedefaultsLabel            matlab.ui.control.Label
        UseAnatDefaultCheckBox      matlab.ui.control.CheckBox
        AnatPanel                   matlab.ui.container.Panel
        LabelReqCondDelay           matlab.ui.control.Label
        CondDelayFileButton         matlab.ui.control.Button
        CondDelayFileEditField      matlab.ui.control.EditField
        ConddelayLabel              matlab.ui.control.Label
        LabelReqNeuroTracs          matlab.ui.control.Label
        NeuroTracsFileButton        matlab.ui.control.Button
        NeuroTracsFileEditField     matlab.ui.control.EditField
        NeuroTracsLabel             matlab.ui.control.Label
        LabelReqAnatConn            matlab.ui.control.Label
        AnatConnFileButton          matlab.ui.control.Button
        AnatConnFileField           matlab.ui.control.EditField
        AnatconnLabel               matlab.ui.control.Label
        LabelReqChannels            matlab.ui.control.Label
        ChannelsFileButton          matlab.ui.control.Button
        ChannelsFileEditField       matlab.ui.control.EditField
        ChannelsfileLabel           matlab.ui.control.Label
        LabelReqLeadfild            matlab.ui.control.Label
        LeadfieldFileButton         matlab.ui.control.Button
        LeadfieldFileEditField      matlab.ui.control.EditField
        LeadfieldfileLabel          matlab.ui.control.Label
        LabelReqCortex              matlab.ui.control.Label
        CortexFileButton            matlab.ui.control.Button
        CortexFileEditField         matlab.ui.control.EditField
        CortexfileLabel             matlab.ui.control.Label
        ModelTab                    matlab.ui.container.Tab
        ModelparamsButtonGroup      matlab.ui.container.ButtonGroup
    end

    
    properties (Access = private)
        cfs_properties % Description
        cfs_dir % Description
    end
    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            %% General tab
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                 msgbox({'The Modality can not be empty',...
                    ' Please select a correct Modality.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.DatasetnameEditField.Value))
                 msgbox({'The Dataset name can not be empty',...
                    ' Please type a Dataset name.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                 msgbox({'The Protocol name can not be empty',...
                    ' Please type a Protocol name.'},'Info');
                checked = false;
                return;
            end
            
            iProtocol = bst_get('Protocol', app.ProtocolnameEditField.Value);
            if(~isempty(iProtocol))
                msgbox({'The Protocol name already exist',...
                    ' Please type a new Protocol name.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.InputPathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.TmpPathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.TmpPathEditField.Value = 'local';
                checked = false;
                return;
            end           
            if(isempty(app.OutputpathEditField.Value))
                msgbox({'The Output path can not be empty.',...
                    ' Please select a correct Output path.'},'Info');
                checked = false;
                return;
            end
            
            %% Import anatomy tab
            if(app.Button_BST_Default.Value)
                if(isequal(app.AnatomyTemplateDropDown.Value,'--Select a template--'))
                    msgbox({'The Template can not be empty',...
                        ' Please select a correct Template.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.AnatomyTemplateAtlasDropDown.Value,'--Select an Atlas--'))
                    msgbox({'The Atlas can not be empty',...
                        ' Please select a correct Atlas.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_HCP_Individual.Value)
               if(isempty(app.HCPIndividPathEditField.Value))
                   msgbox({'The HCP anatomy path can not be empty',...
                       ' Please select a correct HCP anatomy path.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivT1wDropDown.Value, '--Select the T1w file--'))
                   msgbox({'The T1w file can not be empty',...
                       ' Please select a correct T1w file.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivAtlasDropDown.Value, '--Select the Atlas file--'))
                   msgbox({'The Atlas file can not be empty',...
                       ' Please select a correct Atlas file.'},'Info');
                   checked = false;
                   return;
               end
            end
            if(isequal(app.LayerDescripDropDown.Value,'--Select--'))
                msgbox({'The Layer descriptor can not be empty',...
                    ' Please select a correct Layer descriptor.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.NonBrainPathEditField.Value))
                msgbox({'The Non-Brain path can not be empty.',...
                    ' Please select a correct Non-Brain path.'},'Info');
                checked = false;
                return;
            end

            %% Import channel tab
             if(app.Button_Channel_template.Value)
                 if(isequal(app.ChannTemplateGroupDropDown.Value,'--Select--'))
                     msgbox({'The Template group can not be empty',...
                         ' Please select a correct Template group.'},'Info');
                     checked = false;
                     return;
                 end
                  if(isequal(app.ChannTemplateNameDropDown.Value,'--Select--'))
                     msgbox({'The Template name can not be empty',...
                         ' Please select a correct Template name.'},'Info');
                     checked = false;
                     return;
                 end
             end
             if(app.Button_Channel_raw_data.Value)
                 if(isempty(app.RawDataBPathEditField.Value))
                     msgbox({'The Raw data path can not be empty.',...
                         ' Please select a correct Raw data path.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isempty(app.CortexFileEditField.Value))
                     msgbox({'The Raw data file can not be empty.',...
                         ' Please select a correct Raw data file.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isequal(app.RawDataFormatDropDown.Value,'--Select--'))
                     msgbox({'The File format can not be empty',...
                         ' Please select a correct File format.'},'Info');
                     checked = false;
                     return;
                 end
             end
            %% Head model tab
            if(app.Button_Spheres.Value)
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonSinglesphere.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonSinglesphere.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonOverlappingspheres.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonOverlappingspheres.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'MEG') && app.Buttons3Shellsphere.Value )
                    msgbox({['The selected Headmodel method (',app.Buttons3Shellsphere.Text,') does not apply to MEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
            end
            if(app.Button_OpenMEEGBEM.Value)
                if(~app.CheckBoxScalpBEM.Value && ~app.CheckBoxSkullBEM.Value && ~app.CheckBoxBrainBEM.Value)
                    msgbox({'The BEM surfaces can not be empty',...
                        ' Please select at least two BEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The BEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_DUNEuroFEM.Value)
                if(~app.CheckBoxScalpFEM.Value && ~app.CheckBoxSkullFEM.Value && ~app.CheckBoxBrainFEM.Value)
                    msgbox({'The FEM surfaces can not be empty',...
                        ' Please select at least two FEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The FEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMMethod.Value,'--Select--'))
                     msgbox({'The FEM Method can not be empty',...
                        ' Please select a correct FEM Method.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMSourcemodel.Value,'--Select--'))
                     msgbox({'The FEM Source model can not be empty',...
                        ' Please select a correct FEM Source model.'},'Info');
                    checked = false;
                    return;
                end
            end

            %% Integration params
            if(isempty(app.PrepdatapathEditField.Value))
                msgbox({'The preprocessed data path can not be empty',...
                    ' Please select a correct preprocessed data path.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataEventDropDown.Value, '--Select event--'))
                msgbox({'The data file can not be empty',...
                    ' Please select a correct data file.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataFormatDropDown.Value, '--Select--'))
                msgbox({'The data format can not be empty',...
                    ' Please select a correct data format.'},'Info');
                checked = false;
                return;
            end

        end
        
        function load_predef_params(app)
            app.BSTDefaultAnatomyPanel.Enable       = 'on';
            app.HCPIndividualAnatomyPanel.Enable    = 'off';
            
            app.UseChanneltemplatePanel.Enable      = 'on';
            app.AnatPanel.Enable                 = 'off';              

            %% Filling components from predefined params
            % General params
            general_params                          = app.cfs_properties.general_params;
            app.ModalityDropDown.Value              = general_params.modality;
            app.DatasetnameEditField.Value          = general_params.dataset.Name;
            app.DatasetDescriptionTextArea.Value    = general_params.dataset.Description;
            app.ProtocolnameEditField.Value         = general_params.bst_config.protocol_name;
            iProtocol = bst_get('Protocol', app.ProtocolnameEditField.Value);
            if(isempty(iProtocol))
                app.ProtocolnameEditField.FontColor = 'k';
                app.ProtocolnameEditFieldLabel.FontColor = 'k';
                app.ProtocolnameEditField.Tooltip = '';
            else
                app.ProtocolnameEditField.FontColor = 'r';
                app.ProtocolnameEditFieldLabel.FontColor = 'r';
                app.ProtocolnameEditField.Tooltip = 'The protocol name already exist';
            end

            if (general_params.bst_config.reset_protocol)
                app.ResetprotocolSwitch.Value       = 'Yes';
                app.ResetprotocolSwitch.FontColor   = 'b';
                app.ResetprotocolSwitch.FontWeight  = 'bold';
            else
                app.ResetprotocolSwitch.Value       = 'No';
                app.ResetprotocolSwitch.FontColor   = 'k';
                app.ResetprotocolSwitch.FontWeight  = 'normal';
            end
            if(cfs_check_properties('BST_path',general_params.bst_config.bst_path))
                app.InputPathEditField.Value          = general_params.bst_config.bst_path;
            end
            if(cfs_check_properties('BST_db',general_params.bst_config.db_path))
                app.TmpPathEditField.Value        = general_params.bst_config.db_path;
            end
            if(cfs_check_properties('output_path',general_params.output_path))
                app.OutputpathEditField.Value       = general_params.output_path;
            end

            % Anatomy params
            anatomy_params = app.cfs_properties.anatomy_params;
            if(isequal(anatomy_params.anatomy_type.type,'default'))
                app.BSTDefaultAnatomyPanel.Enable       = 'on';                
                app.HCPIndividualAnatomyPanel.Enable    = 'off';
                app.Button_BST_Default.Value            = true;
                app.AnatomyTemplateDropDown.Items       = app.get_bst_default_anatomy();          
                app.AnatomyTemplateDropDown.Value       = anatomy_params.anatomy_type.type_list{1}.template_name;
                app.AnatomyTemplateAtlasDropDown.Items  = app.getAtlasByTemplateName(app.AnatomyTemplateDropDown.Value);
                app.AnatomyTemplateAtlasDropDown.Value  = anatomy_params.anatomy_type.type_list{1}.default_atlas;
            elseif(isequal(anatomy_params.anatomy_type.type,'template'))
            else
                app.BSTDefaultAnatomyPanel.Enable       = 'off';
                app.HCPIndividualAnatomyPanel.Enable    = 'on';
                app.Button_HCP_Individual.Value         = true;
                if(cfs_check_properties('indiv_anat_path',anatomy_params.anatomy_type.type_list{2}.base_path))
                    app.HCPIndividPathEditField.Value       = anatomy_params.anatomy_type.type_list{2}.base_path;
                    app.AnatomyIndivT1wDropDown.Items       = {anatomy_params.anatomy_type.type_list{2}.T1w_file_name};
                    app.AnatomyIndivT1wDropDown.Value       = anatomy_params.anatomy_type.type_list{2}.T1w_file_name;
                    app.AnatomyIndivAtlasDropDown.Items     = {anatomy_params.anatomy_type.type_list{2}.Atlas_file_name};
                    app.AnatomyIndivAtlasDropDown.Value     = anatomy_params.anatomy_type.type_list{2}.Atlas_file_name;
                end
            end
            if(cfs_check_properties('mri_transf_path',anatomy_params.common_params.mri_transformation.base_path,...
                    anatomy_params.common_params.mri_transformation.file_name))
                app.MRITransPathEditField.Value             = anatomy_params.common_params.mri_transformation.base_path;
                app.MRITransFileEditField.Value             = anatomy_params.common_params.mri_transformation.file_name;
            end
            if(cfs_check_properties('non_brain_path',anatomy_params.common_params.non_brain_surfaces.base_path))
                app.NonBrainPathEditField.Value             = anatomy_params.common_params.non_brain_surfaces.base_path;
            end
            for i=1:length(app.LayerDescripDropDown.Items)
                if(isequal(lower(anatomy_params.common_params.layer_desc.desc), lower(app.LayerDescripDropDown.Items{i})))
                    app.LayerDescripDropDown.Value      = app.LayerDescripDropDown.Items{i};
                end
            end
            if(anatomy_params.common_params.surfaces_resolution.nvertices > 2000 && ...
                    anatomy_params.common_params.surfaces_resolution.nvertices < 15000)
                app.SurfacesresolutionEditField.Value       = 8000;
            end

            % Channel params
            channel_params = app.cfs_properties.channel_params;
            if(isequal(channel_params.channel_type.type,1))
                app.UseChanneltemplatePanel.Enable      = 'off';                
                app.AnatPanel.Enable                 = 'on';
                app.Button_Channel_raw_data.Value       = true;
                if(cfs_check_properties('raw_data_path',channel_params.channel_type.type_list{1}.base_path,...
                        channel_params.channel_type.type_list{1}.file_location))
                    app.RawDataBPathEditField.Value     = channel_params.channel_type.type_list{1}.base_path;
                    app.CortexFileEditField.Value      = channel_params.channel_type.type_list{1}.file_location;
                    app.RawDataFormatDropDown.Items     = {channel_params.channel_type.type_list{1}.file_location};
                    app.RawDataFormatDropDown.Value     = channel_params.channel_type.type_list{1}.file_location;
                end
            else                
                app.UseChanneltemplatePanel.Enable      = 'on';                
                app.AnatPanel.Enable                 = 'off';
                app.Button_Channel_template.Value       = true;
                app.ChannTemplateGroupDropDown.Items    = app.get_bst_default_LayoutG(); 
                app.ChannTemplateGroupDropDown.Value    = channel_params.channel_type.type_list{2}.group_layout_name;
                app.ChannTemplateNameDropDown.Items     = app.get_bst_default_LayoutN(app.ChannTemplateGroupDropDown.Value);
                app.ChannTemplateNameDropDown.Value     = channel_params.channel_type.type_list{2}.channel_layout_name;
                order_layout                            = app.get_bst_default_LayoutStruct(app.ChannTemplateGroupDropDown.Value,app.ChannTemplateNameDropDown.Value);
                T                                       = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            end
            % Headmodel params
            headmodel_params = app.cfs_properties.headmodel_params;           
            
            switch lower(headmodel_params.Method.value)
                case 'os_meg'
                    app.Button_Spheres.Value = true;
                    app.SpheresoptionsPanel.Enable = 'on';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                    app.DUNEuroFEMoptionsPanel.Enable = 'off';
                case 'openmeeg'
                    app.Button_OpenMEEGBEM.Value = true;
                    app.SpheresoptionsPanel.Enable = 'off';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                    app.DUNEuroFEMoptionsPanel.Enable = 'off';
                    method = headmodel_params.Method.methods{4};
                    if(method.isAdjoint)
                        app.isAdjointSwitch.Value = 'Yes';
                        app.isAdjointSwitch.FontColor = 'b';
                    else
                        app.isAdjointSwitch.Value = 'No';
                        app.isAdjointSwitch.FontColor = 'k';
                    end
                    if(method.isAdaptative)
                        app.isAdaptativeSwitch.Value = 'Yes';
                        app.isAdaptativeSwitch.FontColor = 'b';
                    else
                        app.isAdaptativeSwitch.Value = 'No';
                        app.isAdaptativeSwitch.FontColor = 'k';
                    end
                    if(method.isSplit)
                        app.isSplitSwitch.Value = 'Yes';
                        app.isSplitSwitch.FontColor = 'b';
                    else
                        app.isSplitSwitch.Value = 'No';
                        app.isSplitSwitch.FontColor = 'k';
                    end
                    app.SplitLengthSpinner.Value = method.SplitLength;
                case 'duneuro'
                    app.Button_DUNEuroFEM.Value = true;
                    app.SpheresoptionsPanel.Enable = 'off';
                    app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                    app.DUNEuroFEMoptionsPanel.Enable = 'on';
                    method = headmodel_params.Method.methods{5};
                    app.FemSelectEditField.Value = method.FemSelect;
                    app.FemCondEditField.Value = method.FemCond;
                    if(method.Isotropic)
                        app.IsotropicSwitch.Value = 'Yes';
                        app.IsotropicSwitch.FontColor = 'b';
                    else
                        app.IsotropicSwitch.Value = 'No';
                        app.IsotropicSwitch.FontColor = 'k';
                    end
                    app.DropDownFEMMethod.Value = method.FemMesh.Method.value;
                    app.MeshTypeDropDown_1.Value = method.FemMesh.MeshType.value;
                    app.MergeMethodDropDown.Value = method.FemMesh.MergeMethod.value;
                    app.VertexDensitySpinner.Value = method.FemMesh.VertexDensity.value;
                    app.DownsampleSpinner.Value = method.FemMesh.Downsample.value;
                    % if(method.FemMesh.  .value)
                    % app.UseTensorSwitch.Value = method.FemMesh.Downsample.value;
                    % else
                    % end
                    app.MaxVolSpinner_1.Value = method.FemMesh.MaxVol.value;
                    app.KeepRatioSpinner_1.Value = method.FemMesh.KeepRatio.value;
                    app.NbVerticesSpinner.Value = method.FemMesh.NbVertices.value;
                    app.NodeShiftSpinner.Value = method.FemMesh.NodeShift.value;
            end            
        end
        
        function defaultANames = get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
            defaultANames = {defaults.Name};
            defaultANames{end+1} = '--Select a template--';
        end
        
        function defaultNames = get_bst_default_LayoutG(app)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = {defaults.name};
            app.ChannTemplateGroupDropDown.Items{end+1} = '--Select--';
            app.ChannTemplateGroupDropDown.Value = '--Select--';
            app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
            app.ChannTemplateNameDropDown.Value = '--Select a template--';
        end
        
        function AtlasName = getAtlasByTemplateName(app,TemplateName)
            AtlasName = [];
            default_anat = cfs_get('defaults_dir');
            template_file = fullfile(default_anat,[TemplateName,'.zip']); 

            % On CFS dir
            if(isfile(template_file))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from template.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end

            % On BST db dir
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from BST db.');
                userDir = getUserDir();
                template_anat = fullfile(userDir,".brainstorm","defaults","anatomy",[TemplateName,'.zip']);
                if(isfile(template_anat))
                    copyfile(template_anat,default_anat)
                    extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                    cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    AtlasName = {cortex.Atlas.Name};
                end
                delete(d);
            end

            % On BST online address
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Downloading template from BST repository.');
                defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
                ind = find(ismember({defaults.Name},TemplateName),1);
                url = defaults(ind).FilePath;
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(template_file,url,options);
                delete(d);
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting template from file.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');                
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end  
            AtlasName{end+1} = '--Select--';
        end
        
        function defaultNames = get_bst_default_LayoutN(app,groupName)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = defaults(find(ismember({defaults.name},groupName),1)).contents;
            defaultNames = {defaultNames.name};
        end
        
        function order_layout = get_bst_default_LayoutStruct(app,group,name)
            name = strrep(name,' ','_');
            layout = [];
            [~, default_eeg] = cfs_get('defaults_dir');
            file_name = ['channel_',name,'.mat'];
            default_group = fullfile(default_eeg,group);
            layout_file = fullfile(default_group,file_name);
            if(isfile(layout_file))
                layout = load(layout_file);
            end
            if(~isempty(app.InputPathEditField.Value) && isempty(layout))
                BST_path = app.InputPathEditField.Value;
                copyfile(fullfile(BST_path,'defaults','eeg',group,file_name),default_group)
                layout = load(layout_file);
            end
            if(isempty(layout))
                bst_online = cfs_get('bst_default_eeg');
                online_file = strcat(bst_online,'/',group,'/',file_name,'?download=1');
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(layout_file,online_file,options);
                layout = load(layout_file);
            end
            for i=1:length(layout.Channel)
                loc = layout.Channel(i).Loc;
                Weight = layout.Channel(i).Weight;
                fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
            end
            order_layout.No         = cellstr(string(1:length(layout.Channel)))';
            order_layout.Name       = {layout.Channel.Name}';
            order_layout.Type       = {layout.Channel.Type}';
            order_layout.Position   = {fields.Locs}';
            order_layout.Comment    = {layout.Channel.Comment}';
        end
        
        function update_property_files(app)
            %% General tab
            general_params                      = app.cfs_properties.general_params;
            general_params.modality             = app.ModalityDropDown.Value;
            general_params.dataset.Name         = app.DatasetnameEditField.Value;
            general_params.dataset.Description  = app.ProtocolnameEditField.Value;
            general_params.bst_config.bst_path  = app.InputPathEditField.Value;
            general_params.bst_config.db_path   = app.TmpPathEditField.Value;
            general_params.output_path          = app.OutputpathEditField.Value;
            app.cfs_properties.general_params   = general_params;            

            %% Import anatomy tab
            anatomy_params = app.cfs_properties.anatomy_params;
            if(app.Button_BST_Default.Value)
                anatomy_params.anatomy_type.type = "default";
                anatomy_params.anatomy_type.type_list{1}.template_name = app.AnatomyTemplateDropDown.Value;
                anatomy_params.anatomy_type.type_list{1}.default_atlas = app.AnatomyTemplateAtlasDropDown.Value;
            end
            if(app.Button_HCP_Individual.Value)
                anatomy_params.anatomy_type.type = "individual";
                anatomy_params.anatomy_type.type_list{2}.base_path = app.HCPIndividPathEditField.Value;
                anatomy_params.anatomy_type.type_list{2}.T1w_file_name = app.AnatomyIndivT1wDropDown.Value;
                anatomy_params.anatomy_type.type_list{2}.Atlas_file_name = app.AnatomyIndivAtlasDropDown.Value;
            end
            anatomy_params.common_params.layer_desc.desc = app.LayerDescripDropDown.Value;
            anatomy_params.common_params.non_brain_surfaces.base_path = app.NonBrainPathEditField.Value;
            anatomy_params.common_params.surfaces_resolution.nvertices = app.SurfacesresolutionEditField.Value;
            anatomy_params.common_params.mri_transformation.use_transformation = app.UseMRITransCheckBox.Value;
            if(app.UseMRITransCheckBox.Value)
                anatomy_params.common_params.mri_transformation.base_path = app.MRITransPathEditField.Value;
                anatomy_params.common_params.mri_transformation.file_name = app.MRITransFileEditField.Value;
            end
            app.cfs_properties.anatomy_params = anatomy_params;

            %% Import channel tab
            channel_params = app.cfs_properties.channel_params;
            if(app.Button_Channel_template.Value)
                channel_params.channel_type.type = "default";
                channel_params.channel_type.type_list{2}.group_layout_name = app.ChannTemplateGroupDropDown.Value;
                channel_params.channel_type.type_list{2}.channel_layout_name = app.ChannTemplateNameDropDown.Value;
            end
            if(app.Button_Channel_raw_data.Value)
                channel_params.channel_type.type = "raw";
                channel_params.channel_type.type_list{1}.base_path = app.RawDataBPathEditField.Value;
                channel_params.channel_type.type_list{1}.file_location = app.CortexFileEditField.Value;
                channel_params.channel_type.type_list{1}.data_format = app.RawDataFormatDropDown.Value;
            end
            app.cfs_properties.channel_params = channel_params;

            %% Head model tab
            headmodel_params                                    = app.cfs_properties.headmodel_params;
            if(app.Button_Spheres.Value)
                if(app.ButtonSinglesphere.Value)
                    headmodel_params.Method.value               = 'meg_sphere';
                end
                if(app.ButtonOverlappingspheres.Value)
                    headmodel_params.Method.value               = 'os_meg';
                end
                if(app.Buttons3Shellsphere.Value)
                    headmodel_params.Method.value               = 'eeg_3sphereberg';
                end                
            end
            if(app.Button_OpenMEEGBEM.Value)
                headmodel_params.Method.value = 'openmeeg';
                headmodel_params.method_type{4}.BemSelect       = [app.CheckBoxScalpBEM.Value,app.CheckBoxSkullBEM.Value,app.CheckBoxBrainBEM.Value];
                headmodel_params.method_type{4}.BemNames        = {"Scalp","Skull","Brain"};
                headmodel_params.method_type{4}.BemCond         = [app.EditFieldScalpCond.Value,app.EditFieldSkullCond.Value,app.EditFieldBrainCond.Value];
                headmodel_params.method_type{4}.isAdjoint       = isequal(app.isAdjointSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isAdaptative    = isequal(app.isAdaptativeSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isSplit         = isequal(app.isSplitSwitch.Value,'Yes');
                headmodel_params.method_type{4}.SplitLength     = app.SplitLengthSpinner.Value;
            end
            if(app.Button_DUNEuroFEM.Value)
                headmodel_params.Method.value = 'duneuro';
                headmodel_params.method_type{5}.FemSelect = [app.CheckBoxScalpFEM.Value,app.CheckBoxSkullFEM.Value,app.CheckBoxBrainFEM.Value];

                headmodel_params.method_type{5}.BemCond   = [app.EditFieldScalpFEM.Value,app.EditFieldSkullFEM.Value,app.EditFieldBrainFEM.Value];

            end
            app.cfs_properties.headmodel_params = headmodel_params;

            %% Process integration
            integration_params                              = app.cfs_properties.integration_params;
            integration_params.preproc_data.base_path       = app.PrepdatapathEditField.Value;
            integration_params.preproc_data.event_file      = app.DataEventDropDown.Value;
            integration_params.preproc_data.format          = app.DataFormatDropDown.Value;

            app.cfs_properties.integration_params = integration_params;

            % saving property files
            saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.anatomy_params,'cfs_properties/process_import_anat.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.channel_params,'cfs_properties/process_import_channel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_channel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.headmodel_params,'cfs_properties/process_comp_headmodel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_comp_headmodel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.cfs_properties.process_integration,'cfs_properties/process_integration.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_integration.json'));
            h.smartIndentContents
            h.save
            h.close
        end        
        
        
        function response = ShowParameters(app)
            response = true;
            general_params = app.cfs_properties.general_params;
            
            anatomy_params = app.cfs_properties.anatomy_params;

            channel_params = app.cfs_properties.channel_params;

            headmodel_params = app.cfs_properties.headmodel_params;

            integration_params = app.cfs_properties.integration_params;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dataset Configuration';
            movegui(app.UIFigure,"center");
            addpath('app');
            addpath('bst_defaults');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            app.cfs_dir = cfs_get('cfs_dir');
            app.cfs_properties = get_properties();
            % load prodefinition parameters
            load_predef_params(app);
        end

        % Button pushed function: InputPathButton
        function InputPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.InputPathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        folder,...
                        ' Please select a correct Brainstorm path.'},'Info');
                    app.InputPathEditField.Value = '';
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: TmpPathButton
        function TmpPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.TmpPathEditField.Value = folder;
                else
                    app.TmpPathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
                app.TmpPathEditField.Value = 'local';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: OutputPathButton
        function OutputPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.OutputpathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function: not associated with a component
        function SelectAnatomyoptionButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'on';
                app.HCPIndividualAnatomyPanel.Enable = 'off';
                app.AnatomyTemplateDropDown.Items       = app.get_bst_default_anatomy();          
                app.AnatomyTemplateDropDown.Value       = '--Select a template--';
                app.AnatomyTemplateAtlasDropDown.Value  = '--Select--';
            end            
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'off';
                app.HCPIndividualAnatomyPanel.Enable = 'on';
            end
        end

        % Callback function: not associated with a component
        function ChannTemplateGroupDropDownValueChanged(app, event)
            group_name = app.ChannTemplateGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.InputPathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))                
                app.ChannTemplateNameDropDown.Items = app.get_bst_default_LayoutN(group_name);
                app.ChannTemplateNameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
            else
                app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end
        end

        % Callback function: not associated with a component
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.AnatPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.AnatPanel.Enable = 'on';
            end
        end

        % Callback function: not associated with a component
        function ChannTemplateNameDropDownValueChanged(app, event)
            template_name = event.Value;            
            if(~isequal(template_name,'--Select a template--'))                
                group           = app.ChannTemplateGroupDropDown.Value;
                order_layout    = app.get_bst_default_LayoutStruct(group,template_name);
                T               = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Callback function: not associated with a component
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.AnatPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.AnatPanel.Enable = 'on';
            end
        end

        % Callback function: not associated with a component
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.RawDataBPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function: not associated with a component
        function SelectHCPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Anatomy basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Ciftify structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a HCP structure.',...
                        'Please check the Anatomy folder.'},'Info');                    
                end
                app.HCPIndividPathEditField.Value = folder;

                filesList = dir(fullfile(cStructure.folder,cStructure.name,"T1w"));
                opt_T1w_files = regexpi({filesList.name}, '(T1w|T2w)[a-z-_]*\.nii\.gz', 'match');
                opt_T1w_files(cellfun(@isempty,opt_T1w_files))=[];
                app.AnatomyIndivT1wDropDown.Items = [opt_T1w_files{:}];
                app.AnatomyIndivT1wDropDown.Items{end+1} = '--Select the T1w file--';
                app.AnatomyIndivT1wDropDown.Value = '--Select the T1w file--';

                opt_Atlas_files = regexpi({filesList.name}, '(wmparc.nii.gz)|((aparc)[\w\+\.]*\+aseg\.nii\.gz)', 'match');
                opt_Atlas_files(cellfun(@isempty,opt_Atlas_files))=[];
                app.AnatomyIndivAtlasDropDown.Items = [opt_Atlas_files{:}];
                app.AnatomyIndivAtlasDropDown.Items{end+1} = '--Select the Atlas file--';
                app.AnatomyIndivAtlasDropDown.Value = '--Select the Atlas file--';
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: ModelparamsButtonGroup
        function ModelparamsButtonGroupSelectionChanged(app, event)
            if(app.Button_Spheres.Value)
                app.SpheresoptionsPanel.Enable = 'on';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_OpenMEEGBEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_DUNEuroFEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'on';
            end
        end

        % Callback function: not associated with a component
        function SelectRawDataFPathButtonPushed(app, event)
            try
                if(isempty(app.RawDataBPathEditField.Value))
                    msgbox('Select first the base path of the raw data.','Info');
                    return;
                end
                root_path = app.RawDataBPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.RawDataBPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.CortexFileEditField.Value = ref_path;
                 [~,~,ext] = fileparts(app.CortexFileEditField.Value);
                 if(isempty(ext) && isequal(file_name,'c,rfDC'))
                    ext = '4D';
                 end
                 app.RawDataFormatDropDown.Value = strcat('.',ext);
            catch
                
            end
            figure(app.UIFigure);
        end

        % Callback function: not associated with a component
        function SelectNonBrainPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                        count = count + 1;
                    end
                end
                 if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any FSL Bet structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a FSL Bet structure.',...
                        'Please check the FSL non-brain surfaces folder.'},'Info');                    
                end
                app.NonBrainPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Anatomy'))
                app.TabGroup.SelectedTab = app.GeneralsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Channel'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Compute Headmodel'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Fuctional Integration'))
                app.TabGroup.SelectedTab = app.ModelTab;
                return;
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Generals'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Anatomy'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Import Channel'))
                app.TabGroup.SelectedTab = app.ModelTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Compute Headmodel'))
                app.TabGroup.SelectedTab = app.FunctionalIntegrationTab;
                return;
            end
        end

        % Callback function: not associated with a component
        function AnatomyTemplateDropDownValueChanged(app, event)
            value = app.AnatomyTemplateDropDown.Value;     
            app.AnatomyTemplateAtlasDropDown.Items = {'Please wait...','--Select--'};
            app.AnatomyTemplateAtlasDropDown.Value = 'Please wait...';
            app.AnatomyTemplateAtlasDropDown.Enable = false;
            if(isequal(value,"--Select a template--"))
                msgbox('Select a correct Anatomy template.','Info');
                return;
            end            
            AtlasName = app.getAtlasByTemplateName(value);
            app.AnatomyTemplateAtlasDropDown.Items = AtlasName;
            app.AnatomyTemplateAtlasDropDown.Value = '--Select--';
            app.AnatomyTemplateAtlasDropDown.Enable = true;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checked = app.check_visual_params();
            if(checked)
                response = app.ShowParameters();
                if(response)
                    app.update_property_files();
                    delete(app);
                end
            end              
        end

        % Callback function: not associated with a component
        function SelectMRITransFileButtonPushed(app, event)
            try
                if(isempty(app.MRITransPathEditField.Value))
                    msgbox('Select first the MRI transformation base path.','Info');
                    return;
                end
                root_path = app.MRITransPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.txt';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.MRITransPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.MRITransFileEditField.Value = ref_path;
            catch
                
            end
            figure(app.UIFigure);
        end

        % Callback function: not associated with a component
        function SelectMRITransPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.MRITransPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function: not associated with a component
        function ResetprotocolSwitchValueChanged(app, event)
            value = app.ResetprotocolSwitch.Value;
            if(isequal(value,'Yes'))
                app.ResetprotocolSwitch.FontColor = 'b';
                app.ResetprotocolSwitch.FontWeight = 'bold';
            else
                app.ResetprotocolSwitch.FontColor = 'k';
                app.ResetprotocolSwitch.FontWeight = 'normal';
            end
        end

        % Callback function: not associated with a component
        function isAdjointSwitchValueChanged(app, event)
            value = app.isAdjointSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdjointSwitch.FontColor = 'b';
                app.isAdjointSwitch.FontWeight = 'bold';
            else
                app.isAdjointSwitch.FontColor = 'k';
                app.isAdjointSwitch.FontWeight = 'normal';
            end
        end

        % Callback function: not associated with a component
        function isAdaptativeSwitchValueChanged(app, event)
            value = app.isAdaptativeSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdaptativeSwitch.FontColor = 'b';
                app.isAdaptativeSwitch.FontWeight = 'bold';
            else
                app.isAdaptativeSwitch.FontColor = 'k';
                app.isAdaptativeSwitch.FontWeight = 'normal';
            end
        end

        % Callback function: not associated with a component
        function isSplitSwitchValueChanged(app, event)
            value = app.isSplitSwitch.Value;
            if(isequal(value,'Yes'))
                app.isSplitSwitch.FontColor = 'b';
                app.isSplitSwitch.FontWeight = 'bold';
            else
                app.isSplitSwitch.FontColor = 'k';
                app.isSplitSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function IsotropicSwitchValueChanged(app, event)
            value = app.IsotropicSwitch.Value;
            if(isequal(value,'Yes'))
                app.IsotropicSwitch.FontColor = 'b';
                app.IsotropicSwitch.FontWeight = 'bold';
            else
                app.IsotropicSwitch.FontColor = 'k';
                app.IsotropicSwitch.FontWeight = 'normal';
            end
        end

        % Callback function: not associated with a component
        function UseMRITransCheckBoxValueChanged(app, event)
            value = app.UseMRITransCheckBox.Value;
            if(value)
                app.MRITransPathEditField.Enable = true;
                app.SelectMRITransPathButton.Enable = true;
                app.MRITransFileEditField.Enable = true;
                app.SelectMRITransFileButton.Enable = true;
            else
                app.MRITransPathEditField.Enable = false;
                app.SelectMRITransPathButton.Enable = false;
                app.MRITransFileEditField.Enable = false;
                app.SelectMRITransFileButton.Enable = false;
            end
        end

        % Callback function: not associated with a component
        function DropDownFEMMethodValueChanged(app, event)
            value = app.DropDownFEMMethod.Value;
            if(isequal(value,'iso2mesh-2021') || isequal(value,'iso2mesh'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'on';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'brain2mesh'))
                app.FEMoptsPanel.Visible = "off";   
            end
            if(isequal(value,'simnibs3'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'on';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'simnibs4'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'on';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'fieldtrip'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'on';             
            end
            
        end

        % Callback function: not associated with a component
        function DropDownFEMSourcemodelValueChanged(app, event)
            value = app.DropDownFEMSourcemodel.Value;
            if(isequal(value,'Venant'))
                app.PanelVenant.Visible = 'on';
                app.PanelSubtraction.Visible = 'off';
            end
            if(isequal(value,'Subtraction'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'on';
            end
            if(isequal(value,'Partial integration') || isequal(value,'--Select--'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);            
        end

        % Callback function: not associated with a component
        function ProtocolnameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            iProtocol = bst_get('Protocol', changingValue);
            if(isempty(iProtocol))
                app.ProtocolnameEditField.FontColor = 'k';
                app.ProtocolnameEditFieldLabel.FontColor = 'k';
                app.ProtocolnameEditField.Tooltip = '';
            else
                app.ProtocolnameEditField.FontColor = 'r';
                app.ProtocolnameEditFieldLabel.FontColor = 'r';
                app.ProtocolnameEditField.Tooltip = 'The protocol name already exist';
            end
        end

        % Callback function: not associated with a component
        function SelectPrepdatapathButtonPushed(app, event)
            % try
                folder = uigetdir("Select the Preprocessed data path");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    files = dir(fullfile(structure.folder,structure.name,"*preproc_eeg.set"));
                    if(isempty(files))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Preprocessed data',...
                        'Please select a correct preprocessed data folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders do not contain a processed data file.',...
                        'Please check the Preprocessed data folder.'},'Info');
                end
                app.PrepdatapathEditField.Value = folder;

                filesList = dir(fullfile(folder,"*","*preproc_eeg.set"));
                opt_files = regexpi({filesList.name}, '[\w\W\-_]*(preproc\_eeg\.set)', 'match');
                opt_files(cellfun(@isempty,opt_files))=[];
                
                for j=1:length(opt_files)
                   file = opt_files{j};
                   descriptors = split(file{1},'_');
                   task = split(descriptors{find(contains(descriptors,'task'),1)},'-');
                   events(j) = task(2); 
                end
                events = unique(events);
                app.DataEventDropDown.Items = [events];
                app.DataEventDropDown.Items{end+1} = 'All';
                app.DataEventDropDown.Items{end+1} = '--Select event--';
                app.DataEventDropDown.Value = '--Select event--';
            % catch
            % end
            figure(app.UIFigure);
        end

        % Button pushed function: RefFileButton
        function RefFileButtonPushed(app, event)
            try
                if(isempty(app.DatasetPathEditField.Value))
                    msgbox('Select first the Dataset base path.','Info');
                    return;
                end
                root_path = app.DatasetPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.edf';'*.set';'*.mat';'*.plg';'*.eeg'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.DatasetPathEditField.Value;
                 
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end

                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name); 

                app.ref_sub.SubID = SubID;
                app.ref_sub.Path = root_path;
                app.ref_sub.File = ref_path;

                ref_path = strrep(ref_path,SubID,'SubID');
                app.RefFileEditField.Value = ref_path;     

                
                % Filling table
                subjects = dir(app.DatasetPathEditField.Value);
                subjects(ismember({subjects.name},{'.','..','derivatives','NIM_EEGXplorer','.git'})) = [];
                subjects(~[subjects.isdir]) = [];
                subs = {subjects.name}';
                reject = [];
                for i=1:length(subs)
                    sub = subs{i};
                    file = fullfile(app.DatasetPathEditField.Value,sub,strrep(ref_path,'SubID',sub));
                    if(~isfile(file))
                        reject = [reject i];
                    end
                end
                app.rejected_subs = subjects(reject);
                app.Showrejectedsubjects.Enable = false;
                if(~isempty(reject))
                    app.Showrejectedsubjects.Enable = true;
                    subs(reject) = [];
                    cbxs = true(size(subs));
                    [~,name,ext] = fileparts(app.RefFileEditField.Value);
                    files = {};
                    for i=1:length(subs)
                       files{i}= strcat(strrep(name,'SubID',subs{i}),ext);
                    end
                     T = table(cbxs,subs,files');
                    app.UITable.Data = T;
                    if(isequal(app.GeneralInformationTab.Children(1).Tag,'CheckAll'))
                        delete(app.GeneralInformationTab.Children(1))
                    end
                    uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[51,287,30,30],Parent=app.GeneralInformationTab,...
                        ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                    total = size(subs,1);
                    selected = length(find(table2array(app.UITable.Data(:,1))));
                    app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
                    ms = msgbox({'Some subjects where rejected from the analysis.', ...
                        'The EEGs file were not found.',...
                        'Please check the Dataset information.'},'Info');
                    waitfor(ms);
                end          

            catch
                app.RefFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetparametersPanel = uipanel(app.UIFigure);
            app.SetparametersPanel.Title = 'Set parameters';
            app.TabGroup = uitabgroup(app.SetparametersPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.OutputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.OutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPathButtonPushed, true);
            app.OutputPathButton.Text = 'Select';
            app.OutputpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.OutputpathEditFieldLabel.Text = 'Output path:';
            app.OutputpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.OutputpathEditField.Editable = 'off';
            app.InputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.InputPathButton.ButtonPushedFcn = createCallbackFcn(app, @InputPathButtonPushed, true);
            app.InputPathButton.Text = 'Select';
            app.TmpPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.TmpPathButton.ButtonPushedFcn = createCallbackFcn(app, @TmpPathButtonPushed, true);
            app.TmpPathButton.Text = 'Select';
            app.InputpathLabel = uilabel(app.GeneralsparamsPanel);
            app.InputpathLabel.Text = 'Input path:';
            app.InputPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.InputPathEditField.Editable = 'off';
            app.TmppathLabel = uilabel(app.GeneralsparamsPanel);
            app.TmppathLabel.Text = 'Tmp path:';
            app.TmpPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.TmpPathEditField.Editable = 'off';
            app.TmpPathEditField.Value = 'local';
            app.DatasetnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name:';
            app.DatasetnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.DatasetdescriptionLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetdescriptionLabel.Text = 'DS. description:';
            app.DatasetDescriptionTextArea = uitextarea(app.GeneralsparamsPanel);
            app.LabelReqDatasetName = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDatasetName.Text = '*';
            app.LabelReqInputPath = uilabel(app.GeneralsparamsPanel);
            app.LabelReqInputPath.Text = '*';
            app.LabelReqOutput = uilabel(app.GeneralsparamsPanel);
            app.LabelReqOutput.Text = '*';
            app.RefFileButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.RefFileButton.ButtonPushedFcn = createCallbackFcn(app, @RefFileButtonPushed, true);
            app.RefFileButton.Tooltip = {'Reference file for one subject'};
            app.RefFileButton.Text = 'Select';
            app.filenameLabel_2 = uilabel(app.GeneralsparamsPanel);
            app.filenameLabel_2.Text = 'Reference file:';
            app.RefFileEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.RefFileEditField.Editable = 'off';
            app.ParallelProcCheckBox = uicheckbox(app.GeneralsparamsPanel);
            app.ParallelProcCheckBox.Text = '';
            app.ParallelprocessingLabel = uilabel(app.GeneralsparamsPanel);
            app.ParallelprocessingLabel.Text = 'Parallel processing:';
            app.LabelReqRefFile = uilabel(app.GeneralsparamsPanel);
            app.LabelReqRefFile.Text = '*';
            app.ParticipantsTab = uitab(app.TabGroup);
            app.ParticipantsTab.Title = 'Participants';
            app.UITable = uitable(app.ParticipantsTab);
            app.UITable.ColumnName = {'Select'; 'Subjects'; 'File'};
            app.UITable.RowName = {};
            app.AnatomyTab = uitab(app.TabGroup);
            app.AnatomyTab.Title = 'Anatomy';
            app.AnatPanel = uipanel(app.AnatomyTab);
            app.AnatPanel.Title = 'Anatomy params';
            app.CortexfileLabel = uilabel(app.AnatPanel);
            app.CortexfileLabel.Text = 'Cortex file:';
            app.CortexFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CortexFileEditField.Editable = 'off';
            app.CortexFileButton = uibutton(app.AnatPanel, 'push');
            app.CortexFileButton.Text = 'Select';
            app.LabelReqCortex = uilabel(app.AnatPanel);
            app.LabelReqCortex.Text = '*';
            app.LeadfieldfileLabel = uilabel(app.AnatPanel);
            app.LeadfieldfileLabel.Text = 'Leadfield file:';
            app.LeadfieldFileEditField = uieditfield(app.AnatPanel, 'text');
            app.LeadfieldFileEditField.Editable = 'off';
            app.LeadfieldFileButton = uibutton(app.AnatPanel, 'push');
            app.LeadfieldFileButton.Text = 'Select';
            app.LabelReqLeadfild = uilabel(app.AnatPanel);
            app.LabelReqLeadfild.Text = '*';
            app.ChannelsfileLabel = uilabel(app.AnatPanel);
            app.ChannelsfileLabel.Text = 'Channels file:';
            app.ChannelsFileEditField = uieditfield(app.AnatPanel, 'text');
            app.ChannelsFileEditField.Editable = 'off';
            app.ChannelsFileButton = uibutton(app.AnatPanel, 'push');
            app.ChannelsFileButton.Text = 'Select';
            app.LabelReqChannels = uilabel(app.AnatPanel);
            app.LabelReqChannels.Text = '*';
            app.AnatconnLabel = uilabel(app.AnatPanel);
            app.AnatconnLabel.Text = 'Anat. conn:';
            app.AnatConnFileField = uieditfield(app.AnatPanel, 'text');
            app.AnatConnFileField.Editable = 'off';
            app.AnatConnFileButton = uibutton(app.AnatPanel, 'push');
            app.AnatConnFileButton.Text = 'Select';
            app.LabelReqAnatConn = uilabel(app.AnatPanel);
            app.LabelReqAnatConn.Text = '*';
            app.NeuroTracsLabel = uilabel(app.AnatPanel);
            app.NeuroTracsLabel.Text = 'Neuro Tracs:';
            app.NeuroTracsFileEditField = uieditfield(app.AnatPanel, 'text');
            app.NeuroTracsFileEditField.Editable = 'off';
            app.NeuroTracsFileButton = uibutton(app.AnatPanel, 'push');
            app.NeuroTracsFileButton.Text = 'Select';
            app.LabelReqNeuroTracs = uilabel(app.AnatPanel);
            app.LabelReqNeuroTracs.Text = '*';
            app.ConddelayLabel = uilabel(app.AnatPanel);
            app.ConddelayLabel.Text = 'Cond. delay:';
            app.CondDelayFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CondDelayFileEditField.Editable = 'off';
            app.CondDelayFileButton = uibutton(app.AnatPanel, 'push');
            app.CondDelayFileButton.Text = 'Select';
            app.LabelReqCondDelay = uilabel(app.AnatPanel);
            app.LabelReqCondDelay.Text = '*';
            app.UseAnatDefaultCheckBox = uicheckbox(app.AnatomyTab);
            app.UseAnatDefaultCheckBox.Text = '';
            app.UsedefaultsLabel = uilabel(app.AnatomyTab);
            app.UsedefaultsLabel.Text = 'Use defaults:';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelparamsButtonGroup = uibuttongroup(app.ModelTab);
            app.ModelparamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelparamsButtonGroupSelectionChanged, true);
            app.ModelparamsButtonGroup.Title = 'Model params';
            app.CancelButton = uibutton(app.SetparametersPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SetparametersPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'images', 'apply.png');
            app.ApplyButton.Text = 'Apply';
            app.ButtonBack = uibutton(app.SetparametersPanel, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = fullfile(pathToMLAPP, 'images', 'back.png');
            app.ButtonBack.Tooltip = {'Back'};
            app.ButtonBack.Text = '';
            app.ButtonNext = uibutton(app.SetparametersPanel, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Icon = fullfile(pathToMLAPP, 'images', 'next.png');
            app.ButtonNext.Tooltip = {'Next'};
            app.ButtonNext.Text = '';
            app.Showrejectedsubjects = uihyperlink(app.SetparametersPanel);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamsConfig

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end