classdef ParamsUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SetparametersPanel           matlab.ui.container.Panel
        Showrejectedsubjects         matlab.ui.control.Hyperlink
        ButtonNext                   matlab.ui.control.Button
        ButtonBack                   matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        GeneralsTab                  matlab.ui.container.Tab
        GeneralsparamsPanel          matlab.ui.container.Panel
        LabelReqDataType             matlab.ui.control.Label
        DatatypeLabel                matlab.ui.control.Label
        gener_AnalysislevelDropDown  matlab.ui.control.DropDown
        ParallelProcessSwitch        matlab.ui.control.Switch
        ParallelprocessLabel         matlab.ui.control.Label
        LabelReqRefFile              matlab.ui.control.Label
        RefFileEditField             matlab.ui.control.EditField
        filenameLabel_2              matlab.ui.control.Label
        RefFileButton                matlab.ui.control.Button
        LabelReqOutput               matlab.ui.control.Label
        LabelReqInputPath            matlab.ui.control.Label
        LabelReqDatasetName          matlab.ui.control.Label
        DatasetDescriptionTextArea   matlab.ui.control.TextArea
        DatasetdescriptionLabel      matlab.ui.control.Label
        DatasetnameEditField         matlab.ui.control.EditField
        DatasetnameEditFieldLabel    matlab.ui.control.Label
        TmpPathEditField             matlab.ui.control.EditField
        TmppathLabel                 matlab.ui.control.Label
        InputPathEditField           matlab.ui.control.EditField
        InputpathLabel               matlab.ui.control.Label
        TmpPathButton                matlab.ui.control.Button
        InputPathButton              matlab.ui.control.Button
        OutputPathEditField          matlab.ui.control.EditField
        OutputpathEditFieldLabel     matlab.ui.control.Label
        OutputPathButton             matlab.ui.control.Button
        ParticipantsTab              matlab.ui.container.Tab
        LabelInfo                    matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        AnatomyTab                   matlab.ui.container.Tab
        UseDefaultAnatSwitch         matlab.ui.control.Switch
        ParallelprocessLabel_2       matlab.ui.control.Label
        AnatPanel                    matlab.ui.container.Panel
        LabelReqCondDelay            matlab.ui.control.Label
        CondDelayFileButton          matlab.ui.control.Button
        CondDelayFileEditField       matlab.ui.control.EditField
        ConddelayLabel               matlab.ui.control.Label
        LabelReqNeuroTracs           matlab.ui.control.Label
        NeuroTractFileButton         matlab.ui.control.Button
        NeuroTractFileEditField      matlab.ui.control.EditField
        NeuroTractLabel              matlab.ui.control.Label
        LabelReqAnatConn             matlab.ui.control.Label
        AnatConnFileButton           matlab.ui.control.Button
        AnatConnFileField            matlab.ui.control.EditField
        AnatconnLabel                matlab.ui.control.Label
        LabelReqChannels             matlab.ui.control.Label
        ChannelsFileButton           matlab.ui.control.Button
        ChannelsFileEditField        matlab.ui.control.EditField
        ChannelsfileLabel            matlab.ui.control.Label
        LabelReqLeadfild             matlab.ui.control.Label
        LeadfieldFileButton          matlab.ui.control.Button
        LeadfieldFileEditField       matlab.ui.control.EditField
        LeadfieldfileLabel           matlab.ui.control.Label
        LabelReqCortex               matlab.ui.control.Label
        CortexFileButton             matlab.ui.control.Button
        CortexFileEditField          matlab.ui.control.EditField
        CortexfileLabel              matlab.ui.control.Label
        ModelTab                     matlab.ui.container.Tab
        ModelparamsButtonGroup       matlab.ui.container.ButtonGroup
        Stoch2Switch                 matlab.ui.control.Switch
        Stoch2SwitchLabel            matlab.ui.control.Label
        Stoch1Switch                 matlab.ui.control.Switch
        ParallelprocessLabel_4       matlab.ui.control.Label
        DefaultTensorFieldSwitch     matlab.ui.control.Switch
        ParallelprocessLabel_3       matlab.ui.control.Label
        BayesIter_Reg2Spinner        matlab.ui.control.Spinner
        BayesIter_Reg2Label          matlab.ui.control.Label
        BayesIter_Reg1Spinner        matlab.ui.control.Spinner
        BayesIter_Reg1Label          matlab.ui.control.Label
        Nrand2Spinner                matlab.ui.control.Spinner
        Nrand2Label                  matlab.ui.control.Label
        Nrand1Spinner                matlab.ui.control.Spinner
        Nrand1Label                  matlab.ui.control.Label
        BayesIterationDelaySpinner   matlab.ui.control.Spinner
        BayesIterationDelayLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_properties % Description
        xan_dir % Description
        rejected_subs
        last_dir % Description
    end
    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            %% General tab
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                 msgbox({'The Modality can not be empty',...
                    ' Please select a correct Modality.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.DatasetnameEditField.Value))
                 msgbox({'The Dataset name can not be empty',...
                    ' Please type a Dataset name.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                 msgbox({'The Protocol name can not be empty',...
                    ' Please type a Protocol name.'},'Info');
                checked = false;
                return;
            end
            
            iProtocol = bst_get('Protocol', app.ProtocolnameEditField.Value);
            if(~isempty(iProtocol))
                msgbox({'The Protocol name already exist',...
                    ' Please type a new Protocol name.'},'Info');
                checked = false;
                return;
            end

            if(isempty(app.InputPathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.TmpPathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.TmpPathEditField.Value = 'local';
                checked = false;
                return;
            end           
            if(isempty(app.OutputPathEditField.Value))
                msgbox({'The Output path can not be empty.',...
                    ' Please select a correct Output path.'},'Info');
                checked = false;
                return;
            end
            
            %% Import anatomy tab
            if(app.Button_BST_Default.Value)
                if(isequal(app.AnatomyTemplateDropDown.Value,'--Select a template--'))
                    msgbox({'The Template can not be empty',...
                        ' Please select a correct Template.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.AnatomyTemplateAtlasDropDown.Value,'--Select an Atlas--'))
                    msgbox({'The Atlas can not be empty',...
                        ' Please select a correct Atlas.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_HCP_Individual.Value)
               if(isempty(app.HCPIndividPathEditField.Value))
                   msgbox({'The HCP anatomy path can not be empty',...
                       ' Please select a correct HCP anatomy path.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivT1wDropDown.Value, '--Select the T1w file--'))
                   msgbox({'The T1w file can not be empty',...
                       ' Please select a correct T1w file.'},'Info');
                   checked = false;
                   return;
               end
               if(isequal(app.AnatomyIndivAtlasDropDown.Value, '--Select the Atlas file--'))
                   msgbox({'The Atlas file can not be empty',...
                       ' Please select a correct Atlas file.'},'Info');
                   checked = false;
                   return;
               end
            end
            if(isequal(app.LayerDescripDropDown.Value,'--Select--'))
                msgbox({'The Layer descriptor can not be empty',...
                    ' Please select a correct Layer descriptor.'},'Info');
                checked = false;
                return;
            end
            if(isempty(app.NonBrainPathEditField.Value))
                msgbox({'The Non-Brain path can not be empty.',...
                    ' Please select a correct Non-Brain path.'},'Info');
                checked = false;
                return;
            end

            %% Import channel tab
             if(app.Button_Channel_template.Value)
                 if(isequal(app.ChannTemplateGroupDropDown.Value,'--Select--'))
                     msgbox({'The Template group can not be empty',...
                         ' Please select a correct Template group.'},'Info');
                     checked = false;
                     return;
                 end
                  if(isequal(app.ChannTemplateNameDropDown.Value,'--Select--'))
                     msgbox({'The Template name can not be empty',...
                         ' Please select a correct Template name.'},'Info');
                     checked = false;
                     return;
                 end
             end
             if(app.Button_Channel_raw_data.Value)
                 if(isempty(app.RawDataBPathEditField.Value))
                     msgbox({'The Raw data path can not be empty.',...
                         ' Please select a correct Raw data path.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isempty(app.CortexFileEditField.Value))
                     msgbox({'The Raw data file can not be empty.',...
                         ' Please select a correct Raw data file.'},'Info');
                     checked = false;
                     return;
                 end
                 if(isequal(app.RawDataFormatDropDown.Value,'--Select--'))
                     msgbox({'The File format can not be empty',...
                         ' Please select a correct File format.'},'Info');
                     checked = false;
                     return;
                 end
             end
            %% Head model tab
            if(app.Button_Spheres.Value)
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonSinglesphere.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonSinglesphere.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG') && app.ButtonOverlappingspheres.Value)
                    msgbox({['The selected Headmodel method (',app.ButtonOverlappingspheres.Text,') does not apply to EEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
                if(isequal(app.ModalityDropDown.Value,'MEG') && app.Buttons3Shellsphere.Value )
                    msgbox({['The selected Headmodel method (',app.Buttons3Shellsphere.Text,') does not apply to MEG'],...
                         ' Please select a correct Headmodel method.'},'Info');
                     checked = false;
                     return;
                end
            end
            if(app.Button_OpenMEEGBEM.Value)
                if(~app.CheckBoxScalpBEM.Value && ~app.CheckBoxSkullBEM.Value && ~app.CheckBoxBrainBEM.Value)
                    msgbox({'The BEM surfaces can not be empty',...
                        ' Please select at least two BEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The BEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
            end
            if(app.Button_DUNEuroFEM.Value)
                if(~app.CheckBoxScalpFEM.Value && ~app.CheckBoxSkullFEM.Value && ~app.CheckBoxBrainFEM.Value)
                    msgbox({'The FEM surfaces can not be empty',...
                        ' Please select at least two FEM Surfaces.'},'Info');
                    checked = false;
                    return;
                end
                if(isempty(app.EditFieldScalpCond.Value) || isempty(app.EditFieldSkullCond.Value) || isempty(app.EditFieldBrainCond.Value))
                     msgbox({'The FEM File conductivity can not be empty',...
                        ' Please fill a correct contuctifvity.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMMethod.Value,'--Select--'))
                     msgbox({'The FEM Method can not be empty',...
                        ' Please select a correct FEM Method.'},'Info');
                    checked = false;
                    return;
                end
                if(isequal(app.DropDownFEMSourcemodel.Value,'--Select--'))
                     msgbox({'The FEM Source model can not be empty',...
                        ' Please select a correct FEM Source model.'},'Info');
                    checked = false;
                    return;
                end
            end

            %% Integration params
            if(isempty(app.PrepdatapathEditField.Value))
                msgbox({'The preprocessed data path can not be empty',...
                    ' Please select a correct preprocessed data path.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataEventDropDown.Value, '--Select event--'))
                msgbox({'The data file can not be empty',...
                    ' Please select a correct data file.'},'Info');
                checked = false;
                return;
            end
            if(isequal(app.DataFormatDropDown.Value, '--Select--'))
                msgbox({'The data format can not be empty',...
                    ' Please select a correct data format.'},'Info');
                checked = false;
                return;
            end

        end
        
        function load_predef_params(app)
            
            %% Filling components from predefined params
            % General params
            general_params                          = app.app_properties.general_params;
            app.DatasetnameEditField.Value          = general_params.dataset.Name;
            app.DatasetDescriptionTextArea.Value    = general_params.dataset.Description;
            
            if(xan_check_properties('Input_path',general_params.input_path))
                app.InputPathEditField.Value        = general_params.input_path;
            end
            if(xan_check_properties('Ref_file',general_params.input_path,general_params.data.ref_file))
                app.RefFileEditField.Value          = general_params.data.ref_file;
            end
            if(xan_check_properties('Output_path',general_params.output_path))
                app.OutputPathEditField.Value        = general_params.output_path;
            end
            if(xan_check_properties('Tmp_path',general_params.tmp.path))
                app.TmpPathEditField.Value       = general_params.tmp.path;
            end

            if(general_params.parallel.conn_delay)
                app.ParallelProcessSwitch.Value = 'Yes';
                app.ParallelProcessSwitch.FontColor = 'b';
            else
                app.ParallelProcessSwitch.Value = 'No';
                app.ParallelProcessSwitch.FontColor = 'k';
            end

            % Participants
            app.FillParticipantsTable();

            % Anatomy params
            anatomy_params = app.app_properties.anatomy_params;
            if(anatomy_params.use_default)
                app.UseDefaultAnatSwitch.Value = 'Yes';
                app.UseDefaultAnatSwitch.FontColor = 'b';
                app.AnatPanel.Enable       = 'off';
                if(xan_check_properties('Cortex',fullfile(pwd,anatomy_params.cortex.file_name)))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfield',fullfile(pwd,anatomy_params.leadfield.file_name)))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',fullfile(pwd,anatomy_params.channel.file_name)))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',fullfile(pwd,anatomy_params.conn_anat.file_name)))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',fullfile(pwd,anatomy_params.neuroTracLengths.file_name)))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',fullfile(pwd,anatomy_params.cond_delay.file_name)))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            else
                app.UseDefaultAnatSwitch.Value = 'No';
                app.UseDefaultAnatSwitch.FontColor = 'k';
                app.AnatPanel.Enable       = 'on';

                if(xan_check_properties('Cortex',anatomy_params.cortex.file_name))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfild',anatomy_params.leadfield.file_name))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',anatomy_params.channel.file_name))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',anatomy_params.conn_anat.file_name))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',anatomy_params.neuroTracLengths.file_name))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',anatomy_params.cond_delay.file_name))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            end

            % Model params
            model_params  = app.app_properties.model_params;
            if(model_params.tensor_field.default)
                app.DefaultTensorFieldSwitch.Value = 'Yes';
                app.DefaultTensorFieldSwitch.FontColor = 'b';
            else
                app.DefaultTensorFieldSwitch.Value = 'No';
                app.DefaultTensorFieldSwitch.FontColor = 'k';
            end

            app.BayesIterationDelaySpinner.Value = model_params.BayesIter_Delay;
            app.Nrand1Spinner.Value = model_params.Nrand1;
            app.Nrand2Spinner.Value = model_params.Nrand2;
            app.BayesIter_Reg1Spinner.Value = model_params.BayesIter_Reg1;
            app.BayesIter_Reg2Spinner.Value = model_params.BayesIter_Reg2;
            
            if(model_params.stoch1)
                app.Stoch1Switch.Value = 'True';
                app.Stoch1Switch.FontColor = 'b';
            else
                app.Stoch1Switch.Value = 'False';
                app.Stoch1Switch.FontColor = 'k';
            end

            if(model_params.stoch1)
                app.Stoch2Switch.Value = 'True';
                app.Stoch2Switch.FontColor = 'b';
            else
                app.Stoch2Switch.Value = 'False';
                app.Stoch2Switch.FontColor = 'k';
            end                      
        end
        
        function defaultANames = get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
            defaultANames = {defaults.Name};
            defaultANames{end+1} = '--Select a template--';
        end
        
        function defaultNames = get_bst_default_LayoutG(app)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = {defaults.name};
            app.ChannTemplateGroupDropDown.Items{end+1} = '--Select--';
            app.ChannTemplateGroupDropDown.Value = '--Select--';
            app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
            app.ChannTemplateNameDropDown.Value = '--Select a template--';
        end
        
        function AtlasName = getAtlasByTemplateName(app,TemplateName)
            AtlasName = [];
            default_anat = xan_get('defaults_dir');
            template_file = fullfile(default_anat,[TemplateName,'.zip']); 

            % On CFS dir
            if(isfile(template_file))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from template.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end

            % On BST db dir
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting Atlas from BST db.');
                userDir = getUserDir();
                template_anat = fullfile(userDir,".brainstorm","defaults","anatomy",[TemplateName,'.zip']);
                if(isfile(template_anat))
                    copyfile(template_anat,default_anat)
                    extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');
                    cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                    AtlasName = {cortex.Atlas.Name};
                end
                delete(d);
            end

            % On BST online address
            if(isempty(AtlasName))
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Downloading template from BST repository.');
                defaults = jsondecode(fileread(fullfile('bst_defaults','bst_default_anatomy.json')));
                ind = find(ismember({defaults.Name},TemplateName),1);
                url = defaults(ind).FilePath;
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(template_file,url,options);
                delete(d);
                d = uiprogressdlg(app.CiftiStormUIFigure,'Title','Extracting template from file.');
                extractFile(template_file, default_anat, 'tess_cortex_pial_low.mat');                
                cortex = load(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                delete(fullfile(default_anat,'tess_cortex_pial_low.mat'));
                AtlasName = {cortex.Atlas.Name};
                delete(d);
            end  
            AtlasName{end+1} = '--Select--';
        end
        
        function defaultNames = get_bst_default_LayoutN(app,groupName)
            defaults = jsondecode(fileread(fullfile('bst_defaults','bst_eeg_layouts.json')));
            defaultNames = defaults(find(ismember({defaults.name},groupName),1)).contents;
            defaultNames = {defaultNames.name};
        end
        
        function order_layout = get_bst_default_LayoutStruct(app,group,name)
            name = strrep(name,' ','_');
            layout = [];
            [~, default_eeg] = xan_get('defaults_dir');
            file_name = ['channel_',name,'.mat'];
            default_group = fullfile(default_eeg,group);
            layout_file = fullfile(default_group,file_name);
            if(isfile(layout_file))
                layout = load(layout_file);
            end
            if(~isempty(app.InputPathEditField.Value) && isempty(layout))
                BST_path = app.InputPathEditField.Value;
                copyfile(fullfile(BST_path,'defaults','eeg',group,file_name),default_group)
                layout = load(layout_file);
            end
            if(isempty(layout))
                bst_online = xan_get('bst_default_eeg');
                online_file = strcat(bst_online,'/',group,'/',file_name,'?download=1');
                matlab.net.http.HTTPOptions.VerifyServerName = false;
                options = weboptions('Timeout',Inf,'RequestMethod','auto');
                websave(layout_file,online_file,options);
                layout = load(layout_file);
            end
            for i=1:length(layout.Channel)
                loc = layout.Channel(i).Loc;
                Weight = layout.Channel(i).Weight;
                fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
            end
            order_layout.No         = cellstr(string(1:length(layout.Channel)))';
            order_layout.Name       = {layout.Channel.Name}';
            order_layout.Type       = {layout.Channel.Type}';
            order_layout.Position   = {fields.Locs}';
            order_layout.Comment    = {layout.Channel.Comment}';
        end
        
        function update_property_files(app)
            %% General tab
            general_params                      = app.app_properties.general_params;
            general_params.modality             = app.ModalityDropDown.Value;
            general_params.dataset.Name         = app.DatasetnameEditField.Value;
            general_params.dataset.Description  = app.ProtocolnameEditField.Value;
            general_params.bst_config.bst_path  = app.InputPathEditField.Value;
            general_params.bst_config.db_path   = app.TmpPathEditField.Value;
            general_params.output_path          = app.OutputPathEditField.Value;
            app.app_properties.general_params   = general_params;            

            %% Import anatomy tab
            anatomy_params = app.app_properties.anatomy_params;
            if(app.Button_BST_Default.Value)
                anatomy_params.anatomy_type.type = "default";
                anatomy_params.anatomy_type.type_list{1}.template_name = app.AnatomyTemplateDropDown.Value;
                anatomy_params.anatomy_type.type_list{1}.default_atlas = app.AnatomyTemplateAtlasDropDown.Value;
            end
            if(app.Button_HCP_Individual.Value)
                anatomy_params.anatomy_type.type = "individual";
                anatomy_params.anatomy_type.type_list{2}.base_path = app.HCPIndividPathEditField.Value;
                anatomy_params.anatomy_type.type_list{2}.T1w_file_name = app.AnatomyIndivT1wDropDown.Value;
                anatomy_params.anatomy_type.type_list{2}.Atlas_file_name = app.AnatomyIndivAtlasDropDown.Value;
            end
            anatomy_params.common_params.layer_desc.desc = app.LayerDescripDropDown.Value;
            anatomy_params.common_params.non_brain_surfaces.base_path = app.NonBrainPathEditField.Value;
            anatomy_params.common_params.surfaces_resolution.nvertices = app.SurfacesresolutionEditField.Value;
            anatomy_params.common_params.mri_transformation.use_transformation = app.UseMRITransCheckBox.Value;
            if(app.UseMRITransCheckBox.Value)
                anatomy_params.common_params.mri_transformation.base_path = app.MRITransPathEditField.Value;
                anatomy_params.common_params.mri_transformation.file_name = app.MRITransFileEditField.Value;
            end
            app.app_properties.anatomy_params = anatomy_params;

            %% Import channel tab
            channel_params = app.app_properties.channel_params;
            if(app.Button_Channel_template.Value)
                channel_params.channel_type.type = "default";
                channel_params.channel_type.type_list{2}.group_layout_name = app.ChannTemplateGroupDropDown.Value;
                channel_params.channel_type.type_list{2}.channel_layout_name = app.ChannTemplateNameDropDown.Value;
            end
            if(app.Button_Channel_raw_data.Value)
                channel_params.channel_type.type = "raw";
                channel_params.channel_type.type_list{1}.base_path = app.RawDataBPathEditField.Value;
                channel_params.channel_type.type_list{1}.file_location = app.CortexFileEditField.Value;
                channel_params.channel_type.type_list{1}.data_format = app.RawDataFormatDropDown.Value;
            end
            app.app_properties.channel_params = channel_params;

            %% Head model tab
            headmodel_params                                    = app.app_properties.headmodel_params;
            if(app.Button_Spheres.Value)
                if(app.ButtonSinglesphere.Value)
                    headmodel_params.Method.value               = 'meg_sphere';
                end
                if(app.ButtonOverlappingspheres.Value)
                    headmodel_params.Method.value               = 'os_meg';
                end
                if(app.Buttons3Shellsphere.Value)
                    headmodel_params.Method.value               = 'eeg_3sphereberg';
                end                
            end
            if(app.Button_OpenMEEGBEM.Value)
                headmodel_params.Method.value = 'openmeeg';
                headmodel_params.method_type{4}.BemSelect       = [app.CheckBoxScalpBEM.Value,app.CheckBoxSkullBEM.Value,app.CheckBoxBrainBEM.Value];
                headmodel_params.method_type{4}.BemNames        = {"Scalp","Skull","Brain"};
                headmodel_params.method_type{4}.BemCond         = [app.EditFieldScalpCond.Value,app.EditFieldSkullCond.Value,app.EditFieldBrainCond.Value];
                headmodel_params.method_type{4}.isAdjoint       = isequal(app.isAdjointSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isAdaptative    = isequal(app.isAdaptativeSwitch.Value,'Yes');
                headmodel_params.method_type{4}.isSplit         = isequal(app.isSplitSwitch.Value,'Yes');
                headmodel_params.method_type{4}.SplitLength     = app.SplitLengthSpinner.Value;
            end
            if(app.Button_DUNEuroFEM.Value)
                headmodel_params.Method.value = 'duneuro';
                headmodel_params.method_type{5}.FemSelect = [app.CheckBoxScalpFEM.Value,app.CheckBoxSkullFEM.Value,app.CheckBoxBrainFEM.Value];

                headmodel_params.method_type{5}.BemCond   = [app.EditFieldScalpFEM.Value,app.EditFieldSkullFEM.Value,app.EditFieldBrainFEM.Value];

            end
            app.app_properties.headmodel_params = headmodel_params;

            %% Process integration
            integration_params                              = app.app_properties.integration_params;
            integration_params.preproc_data.base_path       = app.PrepdatapathEditField.Value;
            integration_params.preproc_data.event_file      = app.DataEventDropDown.Value;
            integration_params.preproc_data.format          = app.DataFormatDropDown.Value;

            app.app_properties.integration_params = integration_params;

            % saving property files
            saveJSON(app.app_properties.general_params,'cfs_properties/general_params.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.anatomy_params,'cfs_properties/process_import_anat.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.channel_params,'cfs_properties/process_import_channel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_channel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.headmodel_params,'cfs_properties/process_comp_headmodel.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_comp_headmodel.json'));
            h.smartIndentContents
            h.save
            h.close
            saveJSON(app.app_properties.process_integration,'cfs_properties/process_integration.json');
            h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_integration.json'));
            h.smartIndentContents
            h.save
            h.close
        end        
        
        
        function response = ShowParameters(app)
            response = true;
            general_params = app.app_properties.general_params;
            
            anatomy_params = app.app_properties.anatomy_params;

            channel_params = app.app_properties.channel_params;

            headmodel_params = app.app_properties.headmodel_params;

            integration_params = app.app_properties.integration_params;
        end
        
        function SelectAll(app, cbx)
            if(cbx.Value)
                app.UITable.Data{:,1} = true(size(app.UITable.Data(:,1)));
            else
                app.UITable.Data{:,1} = false(size(app.UITable.Data(:,1)));
            end
            total = size(app.UITable.Data(:,1),1);
            selected = length(find(table2array(app.UITable.Data(:,1))));
            app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
        end
        
        function FillParticipantsTable(app)
            % Filling table
            subjects = dir(app.InputPathEditField.Value);
            subjects(ismember({subjects.name},{'.','..','derivatives','.git'})) = [];
            subjects(~[subjects.isdir]) = [];
            subs = {subjects.name}';
            reject = [];
            for i=1:length(subs)
                sub = subs{i};
                file = fullfile(app.InputPathEditField.Value,sub,strrep(app.RefFileEditField.Value,'SubID',sub));
                if(~isfile(file))
                    reject = [reject i];
                end
            end
            app.rejected_subs = subjects(reject);
            app.Showrejectedsubjects.Enable = false;
            if(~isempty(reject))
                app.Showrejectedsubjects.Enable = true;
                subs(reject) = [];
                cbxs = true(size(subs));
                [~,name,ext] = fileparts(app.RefFileEditField.Value);
                files = {};
                for i=1:length(subs)
                    files{i}= strcat(strrep(name,'SubID',subs{i}),ext);
                end
                T = table(cbxs,subs,files');
                app.UITable.Data = T;
                if(isequal(app.ParticipantsTab.Children(1).Tag,'CheckAll'))
                    delete(app.ParticipantsTab.Children(1))
                end
                uicheckbox("Value",true,Tag='CheckAll',Text='',Position=[107,385,30,30],Parent=app.ParticipantsTab,...
                    ValueChangedFcn=@(cbt,event) app.SelectAll(cbt));
                total = size(subs,1);
                selected = length(find(table2array(app.UITable.Data(:,1))));
                app.LabelInfo.Text = strcat(num2str(selected)," selected from ",num2str(total), " subjects." );
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Params Configuration';
            movegui(app.UIFigure,"center");
            addpath(genpath('app'));
            addpath(genpath('functions'));
            addpath('tools');
            addpath('templates');
            app.xan_dir = xan_get('xan_dir');
            app.last_dir = getUserDir();
            app.app_properties = get_properties();
            % load prodefinition parameters
            load_predef_params(app);
        end

        % Button pushed function: InputPathButton
        function InputPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Input path");
                app.InputPathEditField.Value = folder;
                app.last_dir = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: TmpPathButton
        function TmpPathButtonPushed(app, event)
            try
                folder = uigetdir(app.last_dir,"Select the Temporal folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.TmpPathEditField.Value = folder;
                    app.last_dir = folder;
                else
                    app.TmpPathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected folder.',...
                        ' Please check the folder permission or select another tmp folder.'},'Info');
                end
            catch
                app.TmpPathEditField.Value = 'local';
            end
            figure(app.UIFigure);
        end

        % Button pushed function: OutputPathButton
        function OutputPathButtonPushed(app, event)
            try
                folder = uigetdir(app.last_dir,"Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.OutputPathEditField.Value = folder;
                    app.last_dir = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected folder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function
        function SelectAnatomyoptionButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'on';
                app.HCPIndividualAnatomyPanel.Enable = 'off';
                app.AnatomyTemplateDropDown.Items       = app.get_bst_default_anatomy();          
                app.AnatomyTemplateDropDown.Value       = '--Select a template--';
                app.AnatomyTemplateAtlasDropDown.Value  = '--Select--';
            end            
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'off';
                app.HCPIndividualAnatomyPanel.Enable = 'on';
            end
        end

        % Callback function
        function ChannTemplateGroupDropDownValueChanged(app, event)
            group_name = app.ChannTemplateGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.InputPathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannTemplateGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))                
                app.ChannTemplateNameDropDown.Items = app.get_bst_default_LayoutN(group_name);
                app.ChannTemplateNameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
            else
                app.ChannTemplateNameDropDown.Items = {'--Select a template--'};
                app.ChannTemplateNameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end
        end

        % Callback function
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.AnatPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.AnatPanel.Enable = 'on';
            end
        end

        % Callback function
        function ChannTemplateNameDropDownValueChanged(app, event)
            template_name = event.Value;            
            if(~isequal(template_name,'--Select a template--'))                
                group           = app.ChannTemplateGroupDropDown.Value;
                order_layout    = app.get_bst_default_LayoutStruct(group,template_name);
                T               = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Callback function
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.AnatPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.AnatPanel.Enable = 'on';
            end
        end

        % Callback function
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.RawDataBPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function
        function SelectHCPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Anatomy basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T1w_acpc_dc.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc_restore.nii.gz'))...
                            && ~isfile(fullfile(structure.folder,structure.name,'T1w','T2w_acpc_dc.nii.gz')))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Ciftify structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a HCP structure.',...
                        'Please check the Anatomy folder.'},'Info');                    
                end
                app.HCPIndividPathEditField.Value = folder;

                filesList = dir(fullfile(cStructure.folder,cStructure.name,"T1w"));
                opt_T1w_files = regexpi({filesList.name}, '(T1w|T2w)[a-z-_]*\.nii\.gz', 'match');
                opt_T1w_files(cellfun(@isempty,opt_T1w_files))=[];
                app.AnatomyIndivT1wDropDown.Items = [opt_T1w_files{:}];
                app.AnatomyIndivT1wDropDown.Items{end+1} = '--Select the T1w file--';
                app.AnatomyIndivT1wDropDown.Value = '--Select the T1w file--';

                opt_Atlas_files = regexpi({filesList.name}, '(wmparc.nii.gz)|((aparc)[\w\+\.]*\+aseg\.nii\.gz)', 'match');
                opt_Atlas_files(cellfun(@isempty,opt_Atlas_files))=[];
                app.AnatomyIndivAtlasDropDown.Items = [opt_Atlas_files{:}];
                app.AnatomyIndivAtlasDropDown.Items{end+1} = '--Select the Atlas file--';
                app.AnatomyIndivAtlasDropDown.Value = '--Select the Atlas file--';
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: ModelparamsButtonGroup
        function ModelparamsButtonGroupSelectionChanged(app, event)
            if(app.Button_Spheres.Value)
                app.SpheresoptionsPanel.Enable = 'on';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_OpenMEEGBEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'on';
                app.DUNEuroFEMoptionsPanel.Enable = 'off';
            end
            if(app.Button_DUNEuroFEM.Value)
                app.SpheresoptionsPanel.Enable = 'off';
                app.OpenMEEGBEMoptionsPanel.Enable = 'off';
                app.DUNEuroFEMoptionsPanel.Enable = 'on';
            end
        end

        % Callback function
        function SelectRawDataFPathButtonPushed(app, event)
            try
                if(isempty(app.RawDataBPathEditField.Value))
                    msgbox('Select first the base path of the raw data.','Info');
                    return;
                end
                root_path = app.RawDataBPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.RawDataBPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.CortexFileEditField.Value = ref_path;
                 [~,~,ext] = fileparts(app.CortexFileEditField.Value);
                 if(isempty(ext) && isequal(file_name,'c,rfDC'))
                    ext = '4D';
                 end
                 app.RawDataFormatDropDown.Value = strcat('.',ext);
            catch
                
            end
            figure(app.UIFigure);
        end

        % Callback function
        function SelectNonBrainPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                        count = count + 1;
                    end
                end
                 if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any FSL Bet structure',...
                        'Please select a correct anatomy folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders are not a FSL Bet structure.',...
                        'Please check the FSL non-brain surfaces folder.'},'Info');                    
                end
                app.NonBrainPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ButtonBack
        function ButtonBackPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Participants'))
                app.TabGroup.SelectedTab = app.GeneralsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Anatomy'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Model'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
        end

        % Button pushed function: ButtonNext
        function ButtonNextPushed(app, event)
            if(isequal(app.TabGroup.SelectedTab.Title,'Generals'))
                app.TabGroup.SelectedTab = app.ParticipantsTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Participants'))
                app.TabGroup.SelectedTab = app.AnatomyTab;
                return;
            end
            if(isequal(app.TabGroup.SelectedTab.Title,'Anatomy'))
                app.TabGroup.SelectedTab = app.ModelTab;
                return;
            end
        end

        % Callback function
        function AnatomyTemplateDropDownValueChanged(app, event)
            value = app.AnatomyTemplateDropDown.Value;     
            app.AnatomyTemplateAtlasDropDown.Items = {'Please wait...','--Select--'};
            app.AnatomyTemplateAtlasDropDown.Value = 'Please wait...';
            app.AnatomyTemplateAtlasDropDown.Enable = false;
            if(isequal(value,"--Select a template--"))
                msgbox('Select a correct Anatomy template.','Info');
                return;
            end            
            AtlasName = app.getAtlasByTemplateName(value);
            app.AnatomyTemplateAtlasDropDown.Items = AtlasName;
            app.AnatomyTemplateAtlasDropDown.Value = '--Select--';
            app.AnatomyTemplateAtlasDropDown.Enable = true;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            checked = app.check_visual_params();
            if(checked)
                response = app.ShowParameters();
                if(response)
                    app.update_property_files();
                    delete(app);
                end
            end              
        end

        % Callback function
        function SelectMRITransFileButtonPushed(app, event)
            try
                if(isempty(app.MRITransPathEditField.Value))
                    msgbox('Select first the MRI transformation base path.','Info');
                    return;
                end
                root_path = app.MRITransPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.txt';'*.*'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.MRITransPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name);                
                ref_path = strrep(ref_path,SubID,'SubID');
                app.MRITransFileEditField.Value = ref_path;
            catch
                
            end
            figure(app.UIFigure);
        end

        % Callback function
        function SelectMRITransPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.MRITransPathEditField.Value = folder;
            catch
            end
            figure(app.UIFigure);
        end

        % Callback function
        function ResetprotocolSwitchValueChanged(app, event)
            value = app.ResetprotocolSwitch.Value;
            if(isequal(value,'Yes'))
                app.ResetprotocolSwitch.FontColor = 'b';
                app.ResetprotocolSwitch.FontWeight = 'bold';
            else
                app.ResetprotocolSwitch.FontColor = 'k';
                app.ResetprotocolSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function isAdjointSwitchValueChanged(app, event)
            value = app.isAdjointSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdjointSwitch.FontColor = 'b';
                app.isAdjointSwitch.FontWeight = 'bold';
            else
                app.isAdjointSwitch.FontColor = 'k';
                app.isAdjointSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function isAdaptativeSwitchValueChanged(app, event)
            value = app.isAdaptativeSwitch.Value;
            if(isequal(value,'Yes'))
                app.isAdaptativeSwitch.FontColor = 'b';
                app.isAdaptativeSwitch.FontWeight = 'bold';
            else
                app.isAdaptativeSwitch.FontColor = 'k';
                app.isAdaptativeSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function isSplitSwitchValueChanged(app, event)
            value = app.isSplitSwitch.Value;
            if(isequal(value,'Yes'))
                app.isSplitSwitch.FontColor = 'b';
                app.isSplitSwitch.FontWeight = 'bold';
            else
                app.isSplitSwitch.FontColor = 'k';
                app.isSplitSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function IsotropicSwitchValueChanged(app, event)
            value = app.IsotropicSwitch.Value;
            if(isequal(value,'Yes'))
                app.IsotropicSwitch.FontColor = 'b';
                app.IsotropicSwitch.FontWeight = 'bold';
            else
                app.IsotropicSwitch.FontColor = 'k';
                app.IsotropicSwitch.FontWeight = 'normal';
            end
        end

        % Callback function
        function UseMRITransCheckBoxValueChanged(app, event)
            value = app.UseMRITransCheckBox.Value;
            if(value)
                app.MRITransPathEditField.Enable = true;
                app.SelectMRITransPathButton.Enable = true;
                app.MRITransFileEditField.Enable = true;
                app.SelectMRITransFileButton.Enable = true;
            else
                app.MRITransPathEditField.Enable = false;
                app.SelectMRITransPathButton.Enable = false;
                app.MRITransFileEditField.Enable = false;
                app.SelectMRITransFileButton.Enable = false;
            end
        end

        % Callback function
        function DropDownFEMMethodValueChanged(app, event)
            value = app.DropDownFEMMethod.Value;
            if(isequal(value,'iso2mesh-2021') || isequal(value,'iso2mesh'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'on';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'brain2mesh'))
                app.FEMoptsPanel.Visible = "off";   
            end
            if(isequal(value,'simnibs3'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'on';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'simnibs4'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'on';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'off';
            end
            if(isequal(value,'fieldtrip'))
                app.FEMoptsPanel.Visible = "on";
                % iso2mesh-2021 & iso2mesh
                app.Paneliso2mesh.Visible = 'off';
                % simnibs3
                app.Panelsimnibs3.Visible = 'off';
                % simnibs4
                app.Panelsimnibs4.Visible = 'off';
                % fieldtrip
                app.Panelfieldtrip.Visible = 'on';             
            end
            
        end

        % Callback function
        function DropDownFEMSourcemodelValueChanged(app, event)
            value = app.DropDownFEMSourcemodel.Value;
            if(isequal(value,'Venant'))
                app.PanelVenant.Visible = 'on';
                app.PanelSubtraction.Visible = 'off';
            end
            if(isequal(value,'Subtraction'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'on';
            end
            if(isequal(value,'Partial integration') || isequal(value,'--Select--'))
                app.PanelVenant.Visible = 'off';
                app.PanelSubtraction.Visible = 'off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);            
        end

        % Callback function
        function ProtocolnameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            iProtocol = bst_get('Protocol', changingValue);
            if(isempty(iProtocol))
                app.ProtocolnameEditField.FontColor = 'k';
                app.ProtocolnameEditFieldLabel.FontColor = 'k';
                app.ProtocolnameEditField.Tooltip = '';
            else
                app.ProtocolnameEditField.FontColor = 'r';
                app.ProtocolnameEditFieldLabel.FontColor = 'r';
                app.ProtocolnameEditField.Tooltip = 'The protocol name already exist';
            end
        end

        % Callback function
        function SelectPrepdatapathButtonPushed(app, event)
            % try
                folder = uigetdir("Select the Preprocessed data path");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                count = 0;
                for i=1:length(structures)
                    structure = structures(i);
                    files = dir(fullfile(structure.folder,structure.name,"*preproc_eeg.set"));
                    if(isempty(files))
                        count = count + 1;
                    else
                        cStructure = structure;
                    end
                end
                if(isequal(count,length(structures)))
                    msgbox({'The selected folder do not contain any Preprocessed data',...
                        'Please select a correct preprocessed data folder.'},'Info');
                    return;
                end
                if(count > 0)
                    msgbox({'One or more subject forders do not contain a processed data file.',...
                        'Please check the Preprocessed data folder.'},'Info');
                end
                app.PrepdatapathEditField.Value = folder;

                filesList = dir(fullfile(folder,"*","*preproc_eeg.set"));
                opt_files = regexpi({filesList.name}, '[\w\W\-_]*(preproc\_eeg\.set)', 'match');
                opt_files(cellfun(@isempty,opt_files))=[];
                
                for j=1:length(opt_files)
                   file = opt_files{j};
                   descriptors = split(file{1},'_');
                   task = split(descriptors{find(contains(descriptors,'task'),1)},'-');
                   events(j) = task(2); 
                end
                events = unique(events);
                app.DataEventDropDown.Items = [events];
                app.DataEventDropDown.Items{end+1} = 'All';
                app.DataEventDropDown.Items{end+1} = '--Select event--';
                app.DataEventDropDown.Value = '--Select event--';
            % catch
            % end
            figure(app.UIFigure);
        end

        % Button pushed function: RefFileButton
        function RefFileButtonPushed(app, event)
            try
                if(isempty(app.DatasetPathEditField.Value))
                    msgbox('Select first the Dataset base path.','Info');
                    return;
                end
                root_path = app.DatasetPathEditField.Value;
                [file_name,file_path] = uigetfile({'*.edf';'*.set';'*.mat';'*.plg';'*.eeg'},'Select a reference file to import',root_path);
                file_path = strrep(file_path,'\','/');
                base_path = app.DatasetPathEditField.Value;
                 
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end

                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                SubID_parts(1:2) = [];
                ref_path = fullfile(SubID_parts{:},file_name); 

                app.ref_sub.SubID = SubID;
                app.ref_sub.Path = root_path;
                app.ref_sub.File = ref_path;

                ref_path = strrep(ref_path,SubID,'SubID');
                app.RefFileEditField.Value = ref_path;   


                app.FillParticipantsTable();    

            catch
                app.RefFileEditField.Value = '';
            end
            figure(app.UIFigure);
        end

        % Value changed function: UseDefaultAnatSwitch
        function UseDefaultAnatSwitchValueChanged(app, event)
            value = app.UseDefaultAnatSwitch.Value;
            % Anatomy params
            anatomy_params = app.app_properties.anatomy_params;
            default_anatomy_patams = app.app_properties.defaults.anatomy_params;
            if(isequal(value,'Yes'))
                app.UseDefaultAnatSwitch.FontColor = 'b';
                app.AnatPanel.Enable       = 'off';
                if(xan_check_properties('Cortex',fullfile(pwd,anatomy_params.cortex.file_name)))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfield',fullfile(pwd,anatomy_params.leadfield.file_name)))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',fullfile(pwd,anatomy_params.channel.file_name)))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',fullfile(pwd,anatomy_params.conn_anat.file_name)))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',fullfile(pwd,anatomy_params.neuroTracLengths.file_name)))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',fullfile(pwd,anatomy_params.cond_delay.file_name)))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            else                
                app.UseDefaultAnatSwitch.FontColor = 'k';
                app.AnatPanel.Enable       = 'on';

                if(xan_check_properties('Cortex',default_anatomy_patams.cortex.file_name))
                    app.CortexFileEditField.Value             = anatomy_params.cortex.file_name;
                end
                if(xan_check_properties('Leadfild',default_anatomy_patams.leadfield.file_name))
                    app.LeadfieldFileEditField.Value             = anatomy_params.leadfield.file_name;
                end
                if(xan_check_properties('Channels',default_anatomy_patams.channel.file_name))
                    app.ChannelsFileEditField.Value             = anatomy_params.channel.file_name;
                end
                if(xan_check_properties('Anat_conn',default_anatomy_patams.conn_anat.file_name))
                    app.AnatConnFileField.Value             = anatomy_params.conn_anat.file_name;
                end
                if(xan_check_properties('Neuro_trac',default_anatomy_patams.neuroTracLengths.file_name))
                    app.NeuroTractFileEditField.Value             = anatomy_params.neuroTracLengths.file_name;
                end
                if(xan_check_properties('Cond_delay',default_anatomy_patams.cond_delay.file_name))
                    app.CondDelayFileEditField.Value             = anatomy_params.cond_delay.file_name;
                end
            end
        end

        % Button pushed function: CortexFileButton
        function CortexFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a cortex file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('cortex',fullfile(file_path,file_name));
                if(checked)
                    app.CortexFileEditField.Value = fullfile(file_path,file_name);
                    app.CortexFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Cortex structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.CortexFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.CortexFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: LeadfieldFileButton
        function LeadfieldFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a leadfield file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('leadfield',fullfile(file_path,file_name));
                if(checked)
                    app.LeadfieldFileEditField.Value = fullfile(file_path,file_name);
                    app.LeadfieldFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Leadfield structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.LeadfieldFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.LeadfieldFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: ChannelsFileButton
        function ChannelsFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a channels file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('channels',fullfile(file_path,file_name));
                if(checked)
                    app.ChannelsFileEditField.Value = fullfile(file_path,file_name);
                    app.ChannelsFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Brainstorm Channels structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.ChannelsFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.ChannelsFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: AnatConnFileButton
        function AnatConnFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a Anatomical connectivity file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('conn_anat',fullfile(file_path,file_name));
                if(checked)
                    app.AnatConnFileField.Value = fullfile(file_path,file_name);
                    app.AnatConnFileField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a correct Anatomical conn structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.AnatConnFileField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.AnatConnFileField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: NeuroTractFileButton
        function NeuroTractFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a Neuro tracs file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('trac',fullfile(file_path,file_name));
                if(checked)
                    app.NeuroTractFileEditField.Value = fullfile(file_path,file_name);
                    app.NeuroTractFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Neuro tracs structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.NeuroTractFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.NeuroTractFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end

        % Button pushed function: CondDelayFileButton
        function CondDelayFileButtonPushed(app, event)
            try
                [file_name,file_path] = uigetfile({'*.mat';},'Select a delay file',app.last_dir);
                file_path = strrep(file_path,'\','/');
                checked = check_file_structure('leadfield',fullfile(file_path,file_name));
                if(checked)
                    app.CondDelayFileEditField.Value = fullfile(file_path,file_name);
                    app.CondDelayFileEditField.Tooltip = fullfile(file_path,file_name);
                    app.last_dir = file_path;

                else
                    [icondata,iconcmap] = imread("guide/images/warning.png");
                    msg = msgbox({'The selectect file do not correspond with a Delay structure'},'Warning',"custom",icondata,iconcmap);
                    movegui(msg,"center");
                    waitfor(msg);
                    app.CondDelayFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
                end
            catch
                app.CondDelayFileEditField.Value = app.app_properties.defaults.anatomy_params.cortex.file_name;
            end
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SetparametersPanel = uipanel(app.UIFigure);
            app.SetparametersPanel.Title = 'Set parameters';
            app.TabGroup = uitabgroup(app.SetparametersPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.OutputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.OutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPathButtonPushed, true);
            app.OutputPathButton.Text = 'Select';
            app.OutputpathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.OutputpathEditFieldLabel.Text = 'Output path:';
            app.OutputPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.OutputPathEditField.Editable = 'off';
            app.InputPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.InputPathButton.ButtonPushedFcn = createCallbackFcn(app, @InputPathButtonPushed, true);
            app.InputPathButton.Text = 'Select';
            app.TmpPathButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.TmpPathButton.ButtonPushedFcn = createCallbackFcn(app, @TmpPathButtonPushed, true);
            app.TmpPathButton.Text = 'Select';
            app.InputpathLabel = uilabel(app.GeneralsparamsPanel);
            app.InputpathLabel.Text = 'Input path:';
            app.InputPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.InputPathEditField.Editable = 'off';
            app.TmppathLabel = uilabel(app.GeneralsparamsPanel);
            app.TmppathLabel.Text = 'Tmp path:';
            app.TmpPathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.TmpPathEditField.Editable = 'off';
            app.TmpPathEditField.Value = 'local';
            app.DatasetnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetnameEditFieldLabel.Text = 'Dataset name:';
            app.DatasetnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.DatasetdescriptionLabel = uilabel(app.GeneralsparamsPanel);
            app.DatasetdescriptionLabel.Text = 'DS. description:';
            app.DatasetDescriptionTextArea = uitextarea(app.GeneralsparamsPanel);
            app.LabelReqDatasetName = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDatasetName.Text = '*';
            app.LabelReqInputPath = uilabel(app.GeneralsparamsPanel);
            app.LabelReqInputPath.Text = '*';
            app.LabelReqOutput = uilabel(app.GeneralsparamsPanel);
            app.LabelReqOutput.Text = '*';
            app.RefFileButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.RefFileButton.ButtonPushedFcn = createCallbackFcn(app, @RefFileButtonPushed, true);
            app.RefFileButton.Tooltip = {'Reference file for one subject'};
            app.RefFileButton.Text = 'Select';
            app.filenameLabel_2 = uilabel(app.GeneralsparamsPanel);
            app.filenameLabel_2.Text = 'Reference file:';
            app.RefFileEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.RefFileEditField.Editable = 'off';
            app.LabelReqRefFile = uilabel(app.GeneralsparamsPanel);
            app.LabelReqRefFile.Text = '*';
            app.ParallelprocessLabel = uilabel(app.GeneralsparamsPanel);
            app.ParallelprocessLabel.Text = 'Parallel process:';
            app.ParallelProcessSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.ParallelProcessSwitch.Items = {'No', 'Yes'};
            app.ParallelProcessSwitch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.ParallelProcessSwitch.Value = 'No';
            app.gener_AnalysislevelDropDown = uidropdown(app.GeneralsparamsPanel);
            app.gener_AnalysislevelDropDown.Items = {'Cross', 'EEG signal'};
            app.gener_AnalysislevelDropDown.ItemsData = {'cross', 'eeg_signal'};
            app.gener_AnalysislevelDropDown.Tooltip = {'1- All analysis (sensor, activation and connectivity)'; '2- Compute only sensor.'; '3- Compute only activation.'; '4- Compute only connectivity.'; '5- Compute sensor and activation.'; '6- Compute activation and connectivity.'};
            app.gener_AnalysislevelDropDown.Value = 'cross';
            app.DatatypeLabel = uilabel(app.GeneralsparamsPanel);
            app.DatatypeLabel.Text = 'Data type:';
            app.LabelReqDataType = uilabel(app.GeneralsparamsPanel);
            app.LabelReqDataType.Text = '*';
            app.ParticipantsTab = uitab(app.TabGroup);
            app.ParticipantsTab.Title = 'Participants';
            app.UITable = uitable(app.ParticipantsTab);
            app.UITable.ColumnName = {'Select'; 'Subjects'; 'File'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
            app.LabelInfo = uilabel(app.ParticipantsTab);
            app.LabelInfo.Text = '';
            app.AnatomyTab = uitab(app.TabGroup);
            app.AnatomyTab.Title = 'Anatomy';
            app.AnatPanel = uipanel(app.AnatomyTab);
            app.AnatPanel.Title = 'Anatomy params';
            app.CortexfileLabel = uilabel(app.AnatPanel);
            app.CortexfileLabel.Text = 'Cortex file:';
            app.CortexFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CortexFileEditField.Editable = 'off';
            app.CortexFileButton = uibutton(app.AnatPanel, 'push');
            app.CortexFileButton.ButtonPushedFcn = createCallbackFcn(app, @CortexFileButtonPushed, true);
            app.CortexFileButton.Text = 'Select';
            app.LabelReqCortex = uilabel(app.AnatPanel);
            app.LabelReqCortex.Text = '*';
            app.LeadfieldfileLabel = uilabel(app.AnatPanel);
            app.LeadfieldfileLabel.Text = 'Leadfield file:';
            app.LeadfieldFileEditField = uieditfield(app.AnatPanel, 'text');
            app.LeadfieldFileEditField.Editable = 'off';
            app.LeadfieldFileButton = uibutton(app.AnatPanel, 'push');
            app.LeadfieldFileButton.ButtonPushedFcn = createCallbackFcn(app, @LeadfieldFileButtonPushed, true);
            app.LeadfieldFileButton.Text = 'Select';
            app.LabelReqLeadfild = uilabel(app.AnatPanel);
            app.LabelReqLeadfild.Text = '*';
            app.ChannelsfileLabel = uilabel(app.AnatPanel);
            app.ChannelsfileLabel.Text = 'Channels file:';
            app.ChannelsFileEditField = uieditfield(app.AnatPanel, 'text');
            app.ChannelsFileEditField.Editable = 'off';
            app.ChannelsFileButton = uibutton(app.AnatPanel, 'push');
            app.ChannelsFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelsFileButtonPushed, true);
            app.ChannelsFileButton.Text = 'Select';
            app.LabelReqChannels = uilabel(app.AnatPanel);
            app.LabelReqChannels.Text = '*';
            app.AnatconnLabel = uilabel(app.AnatPanel);
            app.AnatconnLabel.Text = 'Anat. conn:';
            app.AnatConnFileField = uieditfield(app.AnatPanel, 'text');
            app.AnatConnFileField.Editable = 'off';
            app.AnatConnFileButton = uibutton(app.AnatPanel, 'push');
            app.AnatConnFileButton.ButtonPushedFcn = createCallbackFcn(app, @AnatConnFileButtonPushed, true);
            app.AnatConnFileButton.Text = 'Select';
            app.LabelReqAnatConn = uilabel(app.AnatPanel);
            app.LabelReqAnatConn.Text = '*';
            app.NeuroTractLabel = uilabel(app.AnatPanel);
            app.NeuroTractLabel.Text = 'Neuro Tract:';
            app.NeuroTractFileEditField = uieditfield(app.AnatPanel, 'text');
            app.NeuroTractFileEditField.Editable = 'off';
            app.NeuroTractFileButton = uibutton(app.AnatPanel, 'push');
            app.NeuroTractFileButton.ButtonPushedFcn = createCallbackFcn(app, @NeuroTractFileButtonPushed, true);
            app.NeuroTractFileButton.Text = 'Select';
            app.LabelReqNeuroTracs = uilabel(app.AnatPanel);
            app.LabelReqNeuroTracs.Text = '*';
            app.ConddelayLabel = uilabel(app.AnatPanel);
            app.ConddelayLabel.Text = 'Cond. delay:';
            app.CondDelayFileEditField = uieditfield(app.AnatPanel, 'text');
            app.CondDelayFileEditField.Editable = 'off';
            app.CondDelayFileButton = uibutton(app.AnatPanel, 'push');
            app.CondDelayFileButton.ButtonPushedFcn = createCallbackFcn(app, @CondDelayFileButtonPushed, true);
            app.CondDelayFileButton.Text = 'Select';
            app.LabelReqCondDelay = uilabel(app.AnatPanel);
            app.LabelReqCondDelay.Text = '*';
            app.ParallelprocessLabel_2 = uilabel(app.AnatomyTab);
            app.ParallelprocessLabel_2.Text = 'Use default anatomy:';
            app.UseDefaultAnatSwitch = uiswitch(app.AnatomyTab, 'slider');
            app.UseDefaultAnatSwitch.Items = {'No', 'Yes'};
            app.UseDefaultAnatSwitch.ValueChangedFcn = createCallbackFcn(app, @UseDefaultAnatSwitchValueChanged, true);
            app.UseDefaultAnatSwitch.Tooltip = {'Yes - Use default anatomy parameters'};
            app.UseDefaultAnatSwitch.Value = 'No';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.ModelparamsButtonGroup = uibuttongroup(app.ModelTab);
            app.ModelparamsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelparamsButtonGroupSelectionChanged, true);
            app.ModelparamsButtonGroup.Title = 'Model params';
            app.BayesIterationDelayLabel = uilabel(app.ModelparamsButtonGroup);
            app.BayesIterationDelayLabel.Text = 'Bayes Iteration Delay:';
            app.BayesIterationDelaySpinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIterationDelaySpinner.Limits = [30 Inf];
            app.BayesIterationDelaySpinner.Value = 30;
            app.Nrand1Label = uilabel(app.ModelparamsButtonGroup);
            app.Nrand1Label.Text = 'Nrand1:';
            app.Nrand1Spinner = uispinner(app.ModelparamsButtonGroup);
            app.Nrand1Spinner.Limits = [1 100];
            app.Nrand1Spinner.Value = 1;
            app.Nrand2Label = uilabel(app.ModelparamsButtonGroup);
            app.Nrand2Label.Text = 'Nrand2:';
            app.Nrand2Spinner = uispinner(app.ModelparamsButtonGroup);
            app.Nrand2Spinner.Limits = [1 100];
            app.Nrand2Spinner.Value = 1;
            app.BayesIter_Reg1Label = uilabel(app.ModelparamsButtonGroup);
            app.BayesIter_Reg1Label.Text = 'BayesIter_Reg1:';
            app.BayesIter_Reg1Spinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIter_Reg1Spinner.Limits = [20 100];
            app.BayesIter_Reg1Spinner.Value = 30;
            app.BayesIter_Reg2Label = uilabel(app.ModelparamsButtonGroup);
            app.BayesIter_Reg2Label.Text = 'BayesIter_Reg2:';
            app.BayesIter_Reg2Spinner = uispinner(app.ModelparamsButtonGroup);
            app.BayesIter_Reg2Spinner.Limits = [20 100];
            app.BayesIter_Reg2Spinner.Value = 30;
            app.ParallelprocessLabel_3 = uilabel(app.ModelparamsButtonGroup);
            app.ParallelprocessLabel_3.Text = 'Use default Tensor Field:';
            app.DefaultTensorFieldSwitch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.DefaultTensorFieldSwitch.Items = {'No', 'Yes'};
            app.DefaultTensorFieldSwitch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.DefaultTensorFieldSwitch.Value = 'No';
            app.ParallelprocessLabel_4 = uilabel(app.ModelparamsButtonGroup);
            app.ParallelprocessLabel_4.Text = 'Stoch1:';
            app.Stoch1Switch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.Stoch1Switch.Items = {'False', 'True'};
            app.Stoch1Switch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.Stoch1Switch.Value = 'False';
            app.Stoch2SwitchLabel = uilabel(app.ModelparamsButtonGroup);
            app.Stoch2SwitchLabel.Text = 'Stoch2:';
            app.Stoch2Switch = uiswitch(app.ModelparamsButtonGroup, 'slider');
            app.Stoch2Switch.Items = {'False', 'True'};
            app.Stoch2Switch.Tooltip = {'Yes - The time series of different trials are analyzed independently'; 'No - The time series are combined in a single time serie.'};
            app.Stoch2Switch.Value = 'False';
            app.CancelButton = uibutton(app.SetparametersPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Icon = fullfile(pathToMLAPP, 'images', 'cancel.jpg');
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.SetparametersPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Icon = fullfile(pathToMLAPP, 'images', 'apply.png');
            app.ApplyButton.Text = 'Apply';
            app.ButtonBack = uibutton(app.SetparametersPanel, 'push');
            app.ButtonBack.ButtonPushedFcn = createCallbackFcn(app, @ButtonBackPushed, true);
            app.ButtonBack.Icon = fullfile(pathToMLAPP, 'images', 'back.png');
            app.ButtonBack.Tooltip = {'Back'};
            app.ButtonBack.Text = '';
            app.ButtonNext = uibutton(app.SetparametersPanel, 'push');
            app.ButtonNext.ButtonPushedFcn = createCallbackFcn(app, @ButtonNextPushed, true);
            app.ButtonNext.Icon = fullfile(pathToMLAPP, 'images', 'next.png');
            app.ButtonNext.Tooltip = {'Next'};
            app.ButtonNext.Text = '';
            app.Showrejectedsubjects = uihyperlink(app.SetparametersPanel);
            app.Showrejectedsubjects.Enable = 'off';
            app.Showrejectedsubjects.Text = {'Show rejected subjects'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ParamsUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end