classdef XiAlphaNetUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        NewMenu             matlab.ui.container.Menu
        LoadMenu            matlab.ui.container.Menu
        ExitMenu            matlab.ui.container.Menu
        HelpMenu            matlab.ui.container.Menu
        CheckforupdateMenu  matlab.ui.container.Menu
        WikiMenu            matlab.ui.container.Menu
        Toolbar             matlab.ui.container.Toolbar
        PushToolRunProcess  matlab.ui.container.toolbar.PushTool
        PushTool            matlab.ui.container.toolbar.PushTool
        PushTool3           matlab.ui.container.toolbar.PushTool
        PushTool2           matlab.ui.container.toolbar.PushTool
        GridLayout          matlab.ui.container.GridLayout
        PanelActions        matlab.ui.container.Panel
        GridLayout3         matlab.ui.container.GridLayout
        TreeActions         matlab.ui.container.Tree
        NodeAction1         matlab.ui.container.TreeNode
        Node2               matlab.ui.container.TreeNode
        Node3               matlab.ui.container.TreeNode
        Node4               matlab.ui.container.TreeNode
        NodeAction2         matlab.ui.container.TreeNode
        Node2_2             matlab.ui.container.TreeNode
        Node3_2             matlab.ui.container.TreeNode
        Node4_2             matlab.ui.container.TreeNode
        PanelControls       matlab.ui.container.Panel
        LabelBreadcrumbs    matlab.ui.control.Label
        PanelViewer         matlab.ui.container.Panel
        TabGroup            matlab.ui.container.TabGroup
        TabExplorer         matlab.ui.container.Tab
        OpenFolderButton    matlab.ui.control.Button
        DeleteFolderButton  matlab.ui.control.Button
        RefreshButton       matlab.ui.control.Button
        NewFolderButton     matlab.ui.control.Button
        UpperButton         matlab.ui.control.Button
        TreeInput           matlab.ui.container.Tree
        TabDatasets         matlab.ui.container.Tab
        TreeDatasets        matlab.ui.container.Tree
    end


    properties (Access = private)
        app_properties % Description
        rejected_subs % Description
        ref_sub % Description
        dt_file
        currentSub % Description
        currentAxes; % Description
        XANdir % Description
        Datasets_file % Description        
        Datasets % Dataset information
        currentDS % Description
        UIAxes % Description
    end

    methods (Access = private)

        %%
        %% Explore the Tree Folder
        %%
        function ExploreFolder(app,nodeData)            
            app.TreeInput.Children.delete;
            rootDir = fullfile(nodeData.folder,nodeData.name);
            dirName = nodeData.name;    
            if(isempty(dirName))
                dirName = rootDir;
            end
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.NodeData = nodeData;
            parent.Icon = 'guide/images/folder.png';

            sub_cm = uicontextmenu(app.UIFigure);
            folder_cm = uimenu(sub_cm,"Text","Folder");
            fo = uimenu(folder_cm,"Text","New","MenuSelectedFcn",{@app.FolderContenMenu,'New',nodeData});
            f1 = uimenu(folder_cm,"Text","Rename","MenuSelectedFcn",{@app.FolderContenMenu,'Rename',nodeData});
            f3 = uimenu(folder_cm,"Text","Delete","MenuSelectedFcn",{@app.FolderContenMenu,'Delete',nodeData});

            mo = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',nodeData},'Separator','on');
            m1 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',nodeData},'Separator','on');
            m3 = uimenu(sub_cm,"Text","Configure ICA manually","MenuSelectedFcn",{@app.DefineProperties,'ICA_manual',nodeData},'Separator','on');
            m4 = uimenu(sub_cm,"Text","Configure ICA automatic","MenuSelectedFcn",{@app.DefineProperties,'ICA_automatic',nodeData});
            m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',nodeData},'Separator','on');
            parent.ContextMenu = sub_cm;

            file_list = dir(rootDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';
                        % Contex menu
                        sub_cm = uicontextmenu(app.UIFigure);
                        folder_cm = uimenu(sub_cm,"Text","Folder");
                        fo = uimenu(folder_cm,"Text","New","MenuSelectedFcn",{@app.FolderContenMenu,'New',node.NodeData});
                        f1 = uimenu(folder_cm,"Text","Rename","MenuSelectedFcn",{@app.FolderContenMenu,'Rename',node.NodeData});
                        f3 = uimenu(folder_cm,"Text","Delete","MenuSelectedFcn",{@app.FolderContenMenu,'Delete',node.NodeData});

                        m1 = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',node.NodeData});
                        m2 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',node.NodeData});
                        m3 = uimenu(sub_cm,"Text","Configure ICA manually","MenuSelectedFcn",{@app.DefineProperties,'ICA_manual',node.NodeData},'Separator','on');
                        m4 = uimenu(sub_cm,"Text","Configure ICA automatic","MenuSelectedFcn",{@app.DefineProperties,'ICA_automatic',node.NodeData});
                        m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',node.NodeData},'Separator','on');

                        node.ContextMenu = sub_cm;
                        node.Icon = icon_file;
                    end                    
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        
        function LoadDatasetsDB(app)
            TempDatasets = xan_get('datasets');
            if(~isempty(TempDatasets))
                d = uiprogressdlg(app.UIFigure,'Title','Loading saved Datasets');
                opts.Interpreter = 'tex';
                % Include the desired Default answer
                opts.Default = 'Load';
                % Use the TeX interpreter to format the question
                quest = {strcat("XiAlphaNet db have saved Datasets."), ...
                    'Would you like import these the datasets?'};
                answer = questdlg(quest,'Load Datasets',...
                    'Load','Reset',opts);
                % Handle response
                switch answer
                    case 'Load'
                        app.Datasets = TempDatasets;
                        [icondata,iconcmap] = imread("guide/images/success.png");
                        msg = msgbox({'The Datasets loaded successfully!!!'},'Success',"custom",icondata,iconcmap);
                        movegui(msg,"center");
                        waitfor(msg);
                        disp("-->> Datasets loaded");
                    case 'Reset'
                        app.Datasets = struct([]);
                        saveJSON(app.Datasets,app.Datasets_file);
                    otherwise

                end
                close(d)
                figure(app.UIFigure);
            end
            reject = [];
            for i=1:length(app.Datasets)
                dt = app.Datasets(i);
                if(~isfile(fullfile(dt.Location,'XIALPHANET.json')))
                    opts.Interpreter = 'tex';
                    % Include the desired Default answer
                    opts.Default = 'Update';
                    % Use the TeX interpreter to format the question
                    quest = {strcat("We can not find the Dataset: ", dt.Name, "in the defined location:"), ...
                        strcat(dt.Location),...
                        'Would you like to define a correct path or delete the Dataset information?'};
                    answer = questdlg(quest,'Dataset path',...
                        'Update','Delete',opts);
                    % Handle response
                    switch answer
                        case 'Update'
                            while true
                                folder = uigetdir(strcat("Select the ",dt.Name," path"));
                                if(~isfile(fullfile(folder,'XIALPHANET.json')))
                                    d = uiprogressdlg(app.UIFigure,'Title','Checking selected folder.');
                                    msg = msgbox({'The selected folder do not contain a XiAlphaNet structure', ...
                                        'Please select a new Dataset path'},'Info');
                                    waitfor(msg);
                                    delete(d);
                                    figure(app.UIFigure);
                                else
                                    tempDT = jsondecode(fileread(fullfile(folder,'XIALPHANET.json')));
                                    if(~isequal(dt.UUID,tempDT.UUID))
                                        d = uiprogressdlg(app.UIFigure,'Title','Checking selected dataset.');
                                        msg = msgbox({'The new configuration do not corresponde with the stored dataset', ...
                                            'Please select a new Dataset path'},'Info');
                                        waitfor(msg);
                                        delete(d);
                                        figure(app.UIFigure);                                    
                                    end
                                end
                            end
                        case 'Delete'
                            reject = [reject, i];
                    end
                end
            end
            if(~isempty(reject))
                app.Datasets(reject) = [];
            end
        end     

        function FillDatasetsTree(app)            
            delete(app.TreeDatasets.Children);            
            for i=1:length(app.Datasets)
                app.currentDS = app.Datasets(i);
                child = uitreenode(app.TreeDatasets,'Text',app.currentDS.Name);
                child.NodeData = app.currentDS;
                child.UserData.NodeType = 'dataset';
                child.Icon = 'guide/images/box-solid.png';
                if(~isfile(fullfile(app.currentDS.Location,'XIALPHANET.json')))
                    style = uistyle("FontColor",[0.8500 0.3250 0.0980]);
                    addStyle(app.TreeDatasets, style, 'node', child);
                    contexMenu = uicontextmenu(app.UIFigure);
                    menu = uimenu(contexMenu,'Text',strcat("Update location"), "MenuSelectedFcn",{@app.DatasetAction,'update_path'});
                    menu = uimenu(contexMenu,'Text',strcat("Delete"), "MenuSelectedFcn",{@app.DatasetAction,'delete'});
                    child.ContextMenu = contexMenu;
                    child.set("Text",strcat(app.currentDS.Name, " (Wrong location)"));
                    continue;
                end
                contexMenu = uicontextmenu(app.UIFigure);                
                menu = uimenu(contexMenu,'Text','Dataset report', "MenuSelectedFcn",{@app.AnalysisReport,'subject'},'Separator','on');
                child.ContextMenu = contexMenu;
                indexDS = find(contains({app.Datasets.UUID},app.currentDS.UUID),1);
                participants = app.Datasets(indexDS).Participants;
                
                for j=1:length(participants)
                    participant = participants(j);
                    subChild = uitreenode(child,'Text',participant.SubID);
                    subChild.Icon = 'guide/images/participant.png';                    
                    try
                        subChild.NodeData = jsondecode(fileread(fullfile(app.currentDS.Location,participant.SubID,participant.FileInfo)));                       
                    catch
                        subChild.NodeData = struct;
                    end
                    subChild.UserData.NodeType = 'subject';
                    subChild.UserData.Condition = app.currentDS.Task;
                    if(isequal(participant.Status,"Completed"))
                        mStyle = uistyle("FontColor","blue");
                    else
                        mStyle = uistyle("FontColor","red");
                    end
                    addStyle(app.TreeDatasets,mStyle,"node",subChild);
                    contexMenu = uicontextmenu(app.UIFigure);
                    if(isequal(participant.Status,"Completed")) 
                        menu = uimenu(contexMenu,'Text','Subject report', "MenuSelectedFcn",{@app.AnalysisReport,'subject'},'Separator','on');
                        subChild.ContextMenu = contexMenu;
                    end
                end
                clear Participants_Info;
            end
            delete(app.TreeActions.Children);
        end

        % Folder Content Menu
        function FolderContenMenu(app, event, action, clasif, nodeData)
            switch clasif
                case 'New'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end
                    app.TreeInput.SelectedNodes = [selectedNode];
                    app.NewFolderButtonPushed(event);
                case 'Rename'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end
                    inputDLG = inputdlg({'New name:'}, 'Rename folder', 1);
                    if(~isempty(inputDLG))
                        newFolderName= char(inputDLG(1));
                        oldFolder = fullfile(selectedNode.NodeData.folder,selectedNode.NodeData.name);
                        NewFolder = fullfile(selectedNode.NodeData.folder,newFolderName);
                        folders = dir(selectedNode.NodeData.folder);
                        if(~isempty(find(ismember({folders.name},{newFolderName}),1)))
                            msg = msgbox({'The folder name alresdy exist'},'Info');
                            waitfor(msg);
                            return;
                        end
                        try
                            movefile(oldFolder,NewFolder);
                        catch Ex
                            msg = msgbox({Ex.message},'Info');
                            waitfor(msg);
                        end
                        if(isempty(index))
                            app.TreeInput.Children(1).NodeData.name = newFolderName;
                        end
                        app.RefreshButtonPushed();
                    end                    
                case 'Delete'
                    index = find(ismember({app.TreeInput.Children(1).Children.Text},nodeData.name),1);
                    if(isempty(index))
                        selectedNode = app.TreeInput.Children(1);
                    else
                        selectedNode = app.TreeInput.Children(1).Children(index);
                    end    
                    app.TreeInput.SelectedNodes = [selectedNode];
                    app.DeleteFolderButtonPushed(event);                   

            end
        end

        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);
            text = strcat(filesep, " ");
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");
                else
                    text = strcat(text, pathParts{i});
                end
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>");
            app.LabelBreadcrumbs.Interpreter = 'html';
        end
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.UIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Input folder","MenuSelectedFcn",{@app.DefineProperties,'Input',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Output folder","MenuSelectedFcn",{@app.DefineProperties,'Output',node.NodeData});                            
                            m3 = uimenu(sub_cm,"Text","Configure ICA manually","MenuSelectedFcn",{@app.DefineProperties,'ICA_manual',node.NodeData},'Separator','on');
                            m4 = uimenu(sub_cm,"Text","Configure ICA automatic","MenuSelectedFcn",{@app.DefineProperties,'ICA_automatic',node.NodeData});
                            m5 = uimenu(sub_cm,"Text","More settings","MenuSelectedFcn",{@app.DefineProperties,'Setting',node.NodeData},'Separator','on');
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end

        function DefineProperties(app,event,action,clasif,nodeData)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for user action');
            switch clasif
                case 'Output'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    % app.cfs_properties.general_params.output_path = base_path;
                    % saveJSON(app.cfs_properties.general_params,'cfs_properties/general_params.json');
                    % h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/general_params.json'));
                    % h.smartIndentContents
                    % h.save
                    % h.close
                    app.app_properties.general.output_path = base_path;
                    disp("-->> Defining output path to:");
                    disp(app.app_properties.general.output_path);

                    msg = msgbox({strcat('The Output folder is:',app.app_properties.general.output_path)},'Info');
                    waitfor(msg);
                    app.dt_file = fullfile(app.app_properties.general.output_path,'Dataset.json');
                    if(isfile(app.dt_file))
                        app.currentDS = jsondecode(fileread(app.dt_file));
                        app.FillDatasetTab();
                    end
                case 'Input'
                    base_path = fullfile(nodeData.folder,nodeData.name);
                    structures = dir(base_path);
                    structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                    count = 0;
                    for i=1:length(structures)
                        structure = structures(i);
                        files = dir(fullfile(structure.folder,structure.name,"**","*.edf"));
                        if(isempty(files))
                            count = count + 1;
                        end
                    end
                    if(isequal(count,length(structures)))
                        msgbox({'The selected folder do not contain any file to process',...
                            'Please select a correct anatomy folder.'},'Info');
                        return;
                    end
                    if(count > 0)
                        msgbox({'One or more subject forders do not contain the EEG file.',...
                            'Please check the Subject folders or you can continue in this way.'},'Info');
                    end
                    app.app_properties.general.input_path = base_path;
                    disp("-->> Defining output path to:");
                    disp(app.app_properties.general.input_path);
                    msg = msgbox({strcat('The Input folder is:',app.app_properties.general.input_path)},'Info');
                    waitfor(msg);
                case 'ICA_manual'
                    app.app_properties.general.ICA_manual = true;
                    msg = msgbox({strcat('You have selected: Run ICA Manually')},'Info');
                    waitfor(msg);
                case 'ICA_automatic'
                    app.app_properties.general.ICA_manual = false;
                    msg = msgbox({strcat('You have selected: Run ICA Automatically')},'Info');
                    waitfor(msg);
                case 'Setting'
                    app.app_properties.general.more_settings = true;
                    app.app_properties.general.output_path = fullfile(nodeData.folder,nodeData.name);
                    d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
                    obj_Config = ConfigurationUI(app.app_properties);
                    waitfor(obj_Config,'Finished',true);
                    app.app_properties = obj_Config.eegp_properties;
                    close(d);
                    figure(app.UIFigure);
            end
            delete(d);
        end

        function DefineAction(app,event,action,clasif,nodeData)
            if(isfield(nodeData,'Name'))
                d = uiprogressdlg(app.UIFigure,'Title',strcat("Creating reports for Dataset: ",nodeData.Name));
            else
                d = uiprogressdlg(app.UIFigure,'Title',strcat("Creating report for subject: ",nodeData.SubID));
            end
            switch clasif
                case 'Basic'
                    subID = nodeData.SubID;
                    dataset_path = app.currentDS.Location;
                    ReportGenerator(dataset_path, subID);
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Basic');
                case 'Medium'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Medium');
                case 'Expert'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Expert');
                case 'BasicAll'
                    dataset_path = app.currentDS.Location;
                    ReportGenerator(dataset_path);
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Basic');
                case 'MediumAll'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Medium');
                case 'ExpertAll'
                    % createAdvancedReport(output_path, Partic_info, EEG, 'Expert');
                case 'Reload'                    
                    app.currentDS = jsondecode(fileread(app.dt_file));
                    app.FillDatasetTab();
                    
            end            
            close(d);
            figure(app.UIFigure);
            if(~isequal(clasif,'Reload'))
                if (ispc)
                    winopen(dataset_path);
                elseif ismac
                    cmdToExecute = ['open ' dataset_path];
                    [status, path] = system(cmdToExecute); %#ok<ASGLU>
                else
                    % using xdg-open to open a file in Linux. Some Linux systems might not have
                    % xdg-open .In that case displaying as error with the file path
                    cmdToExecute = ['xdg-open ' dataset_path];
                    [status, path] = system(cmdToExecute); %#ok<ASGLU>
                end
            end
        end

        function Partic_info = create_participant(app, iPart, participant, subject_path)  
            try               
                if(~isfolder(subject_path))
                    mkdir(subject_path);
                end
                Partic_info.SubID                 = participant.name;
                Partic_info.Process(1).Comment    = "Creating";
                Partic_info.Process(1).Status     = "Complete";
                Partic_info.Process(1).File       = "";
                Partic_info.Process(1).Error      = "";
            catch Ex
                Partic_info.SubID                 = participant.name;
                Partic_info.Process(1).Comment    = "Creating";
                Partic_info.Process(1).Status     = "Error";
                Partic_info.Process(1).File       = "";
                Partic_info.Process(1).Error      = Ex.message;
                saveJSON(Partic_info,fullfile(subject_path ,strcat(EEG.subject,'.json')));
                
                app.Datasets.Participants(iPart).Status    = "Error";
                saveJSON(app.Datasets,app.dt_file);
            end  
            saveJSON(Partic_info,fullfile(subject_path ,strcat(participant.name,'.json')));
        end

         function [EEG, Partic_info] = Import_data(app, participant, Partic_info, subject_path, iPart)            
             try
                 filz = dir(fullfile(participant.folder ,participant.name, '*.edf'));
                 app.app_properties.dataset.ref_file = filz.name;
                 EEG = import_eeg_process(app.app_properties, participant);
                 EEG = pop_reref(EEG,[]);
                 Partic_info.Process(2).Comment    = "Importing data";
                 Partic_info.Process(2).Status     = "Complete";
                 Partic_info.Process(2).File       = "";
                 Partic_info.Process(2).Error      = "";
             catch
                 Partic_info.Process(2).Comment    = "Importing data";
                 Partic_info.Process(2).Status     = "Error";
                 Partic_info.Process(2).File       = "";
                 Partic_info.Process(2).Error      = "";
                saveJSON(Partic_info,fullfile(subject_path ,strcat(participant.name,'.json')));

                 app.Datasets.Participants(iPart).Status    = "Error";
                saveJSON(app.Datasets,app.dt_file);
             end
        end
        
        function [EEG, Partic_info] = Import_channels(app, EEG, Partic_info, subject_path, iPart)
            try 
                EEG = import_channels_process(app.app_properties,EEG);

                Partic_info.Process(3).Comment    = "Importing data";
                Partic_info.Process(3).Status     = "Complete";
                Partic_info.Process(3).File       = "";
                Partic_info.Process(3).Error      = "";
            catch Ex
                Partic_info.Process(3).Comment    = "Importing data";
                Partic_info.Process(3).Status     = "Error";
                Partic_info.Process(3).File       = "";
                Partic_info.Process(3).Error      = Ex.message;
                saveJSON(Partic_info,fullfile(subject_path ,strcat(EEG.subject,'.json')));

                app.Datasets.Participants(iPart).Status    = "Error";
                saveJSON(app.Datasets,app.dt_file);
            end
        end
        
        function [EEG, Partic_info] = biomarkers_preprocessing(app, EEG_raw, Partic_info, subject_path, iPart)                     
            try
                %% removing base line and filtering
                EEG = pop_rmbase(EEG_raw,[],[]);
                EEG = pop_eegfiltnew(EEG, app.app_properties.dataset.clean.filter(1), app.app_properties.dataset.clean.filter(2));
                [EEG, fig] = removeflatline(EEG);

                %% removing cardioballitic artifact
                % EEG = pop_fmrib_qrsdetect(EEG,1,'qrs','no');
                % EEG = pop_fmrib_pas(EEG,'qrs','gmean');   % method: 'obs', 'mean' , 'gmean', or 'median'

                %% interpolating bad channels
                % Detecting the bad channels
                [~,removed_channels,corrs] = clean_channels(EEG);
                if numel(find(removed_channels)) > round(0.2*EEG.nbchan)
                    removed_channels = false(EEG.nbchan,1);
                end
                [B,I] = sort(sum(corrs,2));
                % if (sum(removed_channels) > 0.2*EEG.nbchan)
                removed_channels_temp = false(EEG.nbchan,1);
                removed_channels_temp(I(1:min([numel(find(B<100)),round(0.2*EEG.nbchan)]))) = true;
                removed_channels = removed_channels | removed_channels_temp;

                removed_channels_temp = false(EEG.nbchan,1);
                removed_channels_temp(zscore(sum(abs(EEG.data),2))>4) = true;
                removed_channels = removed_channels | removed_channels_temp;
                if (~isempty(find(removed_channels, 1)) && app.app_properties.dataset.clean.inter_channels)
                    EEG = pop_interp(EEG, find(removed_channels), 'spherical');
                end

                %% clear data using ASR
                EEG = pop_clean_rawdata(EEG,'FlatlineCriterion','off','ChannelCriterion','off',...
                    'LineNoiseCriterion',app.app_properties.dataset.clean.LineNoiseCriterion, ...
                    'Highpass',app.app_properties.dataset.clean.Highpass', ...
                    'BurstCriterion',app.app_properties.dataset.clean.BurstCriterion,...
                    'WindowCriterion',app.app_properties.dataset.clean.WindowCriterion, ...
                    'BurstRejection','on','Distance','Euclidian',...
                    'WindowCriterionTolerances',[-inf 7]);

                %% ic analysis
                EEG = pop_runica(EEG,'icatype','runica');
                if(app.app_properties.general.ICA_manual)
                    d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
                    obj_Comp = ICACompUI(EEG);
                    waitfor(obj_Comp,'Finished',true);
                    EEG = obj_Comp.EEG;
                    comp = obj_Comp.Comp;
                    delete(obj_Comp);
                    close(d);
                    figure(app.UIFigure);
                    if(isequal(comp,'auto') || isequal(comp,'fullauto'))
                        EEG = pop_iclabel(EEG,'Default');
                        [~,idxmax]        = max(EEG.etc.ic_classification.ICLabel.classifications');
                        brainsourcescount = numel(find(idxmax<2))/size(EEG.etc.ic_classification.ICLabel.classifications,1);
                        %% ic rejection
                        temp = EEG.etc.ic_classification.ICLabel.classifications;

                        figure
                        imagesc(temp)
                        set(gca,"XTick",[1:size(temp,2)],'XTickLabel',EEG.etc.ic_classification.ICLabel.classes)
                        xlabel('Source')

                        temp = sort(unique([find(temp(:,2)>0.70) ;find(temp(:,3)>0.70);find(temp(:,4)>0.70);...
                            find(temp(:,5)>0.70) ;find(temp(:,6)>0.70);find(temp(:,7)>0.70)])');
                        EEG = pop_subcomp(EEG, temp(1:min([4,numel(temp)])));
                        if(isequal(comp,'fullauto'))
                            app.app_properties.general.ICA_manual = false;
                        end
                    else
                        EEG = pop_subcomp(EEG, comp);
                        brainsourcescount = EEG.nbchan - numel(comp);
                    end
                else
                    EEG = pop_iclabel(EEG,'Default');
                    [~,idxmax]        = max(EEG.etc.ic_classification.ICLabel.classifications');
                    brainsourcescount = numel(find(idxmax<2))/size(EEG.etc.ic_classification.ICLabel.classifications,1);
                    %% ic rejection
                    temp = EEG.etc.ic_classification.ICLabel.classifications;

                    figure
                    imagesc(temp)
                    set(gca,"XTick",[1:size(temp,2)],'XTickLabel',EEG.etc.ic_classification.ICLabel.classes)
                    xlabel('Source')

                    temp = sort(unique([find(temp(:,2)>0.70) ;find(temp(:,3)>0.70);find(temp(:,4)>0.70);...
                        find(temp(:,5)>0.70) ;find(temp(:,6)>0.70);find(temp(:,7)>0.70)])');
                    EEG = pop_subcomp(EEG, temp(1:min([4,numel(temp)])));
                end

                %% average reference
                % EEG = pop_reref(EEG,[]);

                %% saving data quality assesment
                EEG.qc.ch = 100*(size(EEG.data,1)/size(EEG_raw.data,1));
                EEG.qc.tr = 100*(size(EEG.data,2)/size(EEG_raw.data,2));
                EEG.qc.ic = 100*brainsourcescount;

                %% adding age
                EEG.age = NaN;
                try
                    tempname = subjFolders(subno).name;
                    idx = find(contains(subject_name,tempname));
                    EEG.age = subject_age(idx);
                catch me
                    disp("error with age...")
                end

                %% Assessing reliability
                num_samples = size(EEG.data, 2);
                num_channels = size(EEG.data, 1);
                half_point = floor(num_samples / 2);

                % Preprocess the data: Band-pass filtering
                EEG_filtered = bandpass(EEG.data', app.app_properties.dataset.clean.Band_pass', EEG.srate)';

                % Initialize array to store mean coherence values for each channel
                mean_coherence_values = zeros(num_channels, 1);

                % Compute MSC for each channel by splitting into two halves
                for ch = 1:num_channels
                    % Split the channel data into two halves
                    first_half = EEG_filtered(ch, 1:half_point);
                    second_half = EEG_filtered(ch, end-half_point+1:end);

                    % Compute MSC between the two halves
                    [coherence, freq] = mscohere(first_half, second_half, [], [], [], EEG.srate);

                    % Aggregate MSC values to get an overall similarity measure
                    mean_coherence = max(coherence);
                    mean_coherence_values(ch) = mean_coherence;
                end

                % Define thresholds and interpretations
                thresholds = [0.60, 0.70, 0.80, 0.90];
                interpretations = {'Bad', 'Poor', 'Acceptable', 'Good', 'Excellent'};
                colors = [
                    1.0, 0.0, 0.0;  % Red for Bad
                    1.0, 0.5, 0.0;  % Orange for Poor
                    1.0, 1.0, 0.0;  % Yellow for Questionable
                    0.3, 1.0, 0.3;  % Light Green for Acceptable
                    0.0, 0.80, 0.0;  % Green for Good
                    ];

                % Assign colors based on interpretations
                bar_colors = zeros(num_channels, 3);
                for ch = 1:num_channels
                    mean_coherence = mean_coherence_values(ch);
                    % Find the interpretation index
                    interpretation_idx = find(mean_coherence < thresholds, 1);
                    if isempty(interpretation_idx)
                        interpretation_idx = length(interpretations);
                    end
                    bar_colors(ch, :) = colors(interpretation_idx, :);
                end

                % Plot the mean coherence values for each channel with colors
                fig = figure;
                b = bar(mean_coherence_values, 'FaceColor', 'flat');
                for ch = 1:num_channels
                    b.CData(ch, :) = bar_colors(ch, :);
                end
                title({'Magnitude Squared Coherence (MSC) for' ,'Split-Half Reliability Assessment per Channel'});
                set(gca,'xtick',[1:EEG.nbchan],'xticklabels',{EEG.chanlocs.labels})
                xlabel('Channel');
                ylabel('Mean Coherence');
                grid on;

                drawnow
                %% saving results
                fprintf('-->> Saving data to disk...\n')
                subject_path = fullfile(app.app_properties.general.output_path,EEG.subject);
                if(~isfolder(subject_path))
                    mkdir(subject_path);
                end
                save(fullfile(subject_path,strcat(EEG.filename,'_desc-prep.set')), '-struct', 'EEG','-mat','-v7.3');

                disp('-->> Saving processing level file.');
                Partic_info.Process(4).Comment    = "Cleanning data";
                Partic_info.Process(4).Status     = "Complete";
                Partic_info.Process(4).File       = fullfile(strcat(EEG.filename,'_desc-prep.set'));
                Partic_info.Process(4).Error      = "";

            catch Ex
                disp('-->> Saving processing level file.');
                Partic_info.Process(4).Comment    = "Cleanning data";
                Partic_info.Process(4).Status     = "Error";
                Partic_info.Process(4).File       = fullfile(strcat(EEG.filename,'_desc-prep.set'));
                Partic_info.Process(4).Error      = Ex.message;
                saveJSON(Partic_info,fullfile(subject_path ,strcat(EEG.subject,'.json')));

                app.Datasets.Participants(iPart).Status    = "Error";
                saveJSON(app.Datasets,app.dt_file);

                 close all,
                restoredefaultpath;
                %%
                %% Restoring paths after error
                %%
                addpath(genpath(fullfile(pwd,'app')));
                addpath(fullfile(pwd,'dependencies','eeglab'));
                addpath(genpath(fullfile(pwd,'external')));
                addpath(genpath(fullfile(pwd,'functions')));
                addpath(genpath(fullfile(pwd,'guide')));
                addpath(genpath(fullfile(pwd,'report')));
                eeglab rebuild;
            end

        end
        
        function [EEG, Partic_info] = biomarkers_estimation(app, Partic_info, subject_path , iPart)  

            % loading data
            try
                EEG = load(fullfile(app.app_properties.general.output_path,Partic_info.SubID,Partic_info.Process(end).File),'-mat');

                %% SOURCE LEVEL
                % putting paths...
                EEG = pop_dipfit_settings( EEG, 'hdmfile',fullfile(char(app.app_properties.general.eeglab_path),'plugins','dipfit','standard_BEM','standard_vol.mat'),...
                    'coordformat','MNI','mrifile',fullfile(char(app.app_properties.general.eeglab_path),'plugins','dipfit','standard_BEM','standard_mri.mat'),...
                    'chanfile',fullfile(char(app.app_properties.general.eeglab_path),'plugins','dipfit','standard_BEM','elec','standard_1005.elc'),...
                    'coord_transform',[0.83215 -15.6287 2.4114 0.081214 0.00093739 -1.5732 1.1742 1.0601 1.1485] ,'chansel',[1:EEG.nbchan] );
                % computing head model...
                EEG = pop_leadfield(EEG, 'sourcemodel',fullfile(char(app.app_properties.general.eeglab_path),'functions','supportfiles','head_modelColin27_5003_Standard-10-5-Cap339.mat'),...
                    'sourcemodel2mni',[0 -24 -45 0 0 -1.5708 1000 1000 1000],'downsample',1);
                % computing inverse solution...
                addpath("dependencies/eeglab/plugins/roiconnect/libs/mvgc_v1.0/utils");
                EEG = pop_roi_activity(EEG, 'leadfield',EEG.dipfit.sourcemodel,'model','eLoreta','modelparams',{0.05},'atlas','LORETA-Talairach-BAs','nPCA',3,'freqresolution',150);
                % computing phase signal information and connectivity analysis...
                EEG = pop_roi_connect(EEG, 'methods', app.app_properties.dataset.methods, 'snippet', 'on', 'snip_length', 60, 'fcsave_format', 'mean_snips','freqresolution',150);
                rmpath("dependencies/eeglab/plugins/roiconnect/libs/mvgc_v1.0/utils");
                
                figures = plotting_roi_solution_v3(EEG);
                subject_path = fullfile(app.app_properties.general.output_path,EEG.subject);
                if(~isfolder(subject_path))
                    mkdir(subject_path);
                end
                save(fullfile(subject_path,strcat(EEG.filename,'desc-figures_1.set')), '-struct', 'EEG','-mat','-v7.3');

                %% ************************************************************
                %
                %      BIOMARKERS EXTRACTION FROM TIME FREQUENCY ANALYSIS
                %
                % *************************************************************

                % initial values
                frex = EEG.roi.freqs;
                band_lim = [2 4; 4 7.5; 8 13; 14 35];
                band_lbl = {'delta','theta','alpha','beta'};
                bio_marks.val  = zeros(1496,1);
                bio_marks.lbl  = [];
                bio_count      = 0;

                % absolute Power Spectral Density
                for jj = 1:size(band_lim,1)
                    for ii = 1:EEG.roi.nROI
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = sum((EEG.roi.source_roi_power(dsearchn(frex,band_lim(jj,1)):dsearchn(frex,band_lim(jj,2)),ii)).^2);
                        bio_marks.lbl{bio_count} = ['Activity - aPSD - ' band_lbl{jj} ' - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % relative Power Spectral Density (in percent)
                for jj = 1:size(band_lim,1)
                    for ii = 1:EEG.roi.nROI
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = 100*sum((EEG.roi.source_roi_power(dsearchn(frex,band_lim(jj,1)):dsearchn(frex,band_lim(jj,2)),ii)).^2)/...
                            sum((EEG.roi.source_roi_power(:,ii)).^2);
                        bio_marks.lbl{bio_count} = ['Activity - rPSD - ' band_lbl{jj} ' - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % Median Frequency
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    bio_marks.val(bio_count) = median(EEG.roi.source_roi_power(dsearchn(frex,4.1):dsearchn(frex,10.8),ii));
                    bio_marks.lbl{bio_count} = ['Activity - MDF - ' EEG.roi.atlas.Scouts(ii).Label];
                end

                % Alpha Peak Frequency
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    [~,indx1] = min(abs(6-frex));
                    [~,indx2] = min(abs(14-frex));
                    alphavector = EEG.roi.source_roi_power(indx1:indx2,ii).^2;
                    alphavector = smoothdata(alphavector, 'movmean', 3);
                    [PKS,LOCS] = findpeaks(alphavector);
                    if ~isempty(LOCS)
                        [~,tmp]    = max(alphavector(LOCS));
                        peak_pos_relative   = LOCS(tmp);
                        bio_marks.val(bio_count) =  frex(indx1+peak_pos_relative-1);
                        bio_marks.lbl{bio_count} = ['Activity - AlphaPeak - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % Alpha to Theta ration
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    alpha_power = sum((EEG.roi.source_roi_power(dsearchn(frex,band_lim(3,1)):dsearchn(frex,band_lim(3,2)),ii)).^2);
                    theta_power = sum((EEG.roi.source_roi_power(dsearchn(frex,band_lim(2,1)):dsearchn(frex,band_lim(2,2)),ii)).^2);
                    bio_marks.val(bio_count) = alpha_power/theta_power;
                    bio_marks.lbl{bio_count} = ['Activity - ATR - ' EEG.roi.atlas.Scouts(ii).Label];
                end

                % BIOMARKERS EXTRACTION FROM iCOH ANALYSIS
                typeconn = 2;
                connectivity = zeros(size(EEG.roi.iCOH,1),size(EEG.roi.iCOH,3));
                for ii = 1:size(EEG.roi.wPLI,1)
                    switch typeconn
                        case 1
                            connectivity(ii,:) = efficiency_wei(atanh(squeeze(abs(EEG.roi.CS(ii,:,:)))),2);
                            titlefig = 'Efficiency';
                        case 2
                            connectivity(ii,:) = strengths_und(squeeze(abs(EEG.roi.CS(ii,:,:))));
                            titlefig = 'Strengths';
                        case 3
                            connectivity(ii,:) =  clustering_coef_wu(squeeze(abs(EEG.roi.CS(ii,:,:))));
                            titlefig = 'Clustering';
                    end
                end

                % absolute Power Spectral Density
                for jj = 1:length(band_lim)
                    for ii = 1:EEG.roi.nROI
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = sum((connectivity(dsearchn(frex,band_lim(jj,1)):dsearchn(frex,band_lim(jj,2)),ii)).^2);
                        bio_marks.lbl{bio_count} = ['Connectivity - aPSD - ' band_lbl{jj} ' - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % relative Power Spectral Density
                for jj = 1:length(band_lim)
                    for ii = 1:EEG.roi.nROI
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = 100*sum((connectivity(dsearchn(frex,band_lim(jj,1)):dsearchn(frex,band_lim(jj,2)),ii)).^2)/...
                            sum((connectivity(:,ii)).^2);
                        bio_marks.lbl{bio_count} = ['Connectivity - rPSD - ' band_lbl{jj} ' - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % Median Frequency
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    bio_marks.val(bio_count) =  median(connectivity(dsearchn(frex,4.1):dsearchn(frex,10.8),ii));
                    bio_marks.lbl{bio_count} = ['Connectivity - MDF - ' EEG.roi.atlas.Scouts(ii).Label];
                end

                % Alpha Peak Frequency
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    [~,indx1] = min(abs(6-frex));
                    [~,indx2] = min(abs(14-frex));
                    alphavector = sum(connectivity(indx1:indx2,:).^2,2);
                    alphavector = smoothdata(alphavector, 'movmean', 3);
                    [PKS,LOCS] = findpeaks(alphavector);
                    if ~isempty(LOCS)
                        [~,tmp]    = max(alphavector(LOCS));
                        peak_pos_relative   = LOCS(tmp);
                        bio_marks.val(bio_count) =  frex(indx1+peak_pos_relative-1);
                        bio_marks.lbl{bio_count} = ['Connectivity - AlphaPeak - ' EEG.roi.atlas.Scouts(ii).Label];
                    end
                end

                % Alpha to Theta ration
                for ii = 1:EEG.roi.nROI
                    bio_count = bio_count+1;
                    alpha_power = sum((connectivity(dsearchn(frex,band_lim(3,1)):dsearchn(frex,band_lim(3,2)),ii)).^2);
                    theta_power = sum((connectivity(dsearchn(frex,band_lim(2,1)):dsearchn(frex,band_lim(2,2)),ii)).^2);
                    bio_marks.val(bio_count) = alpha_power/theta_power;
                    bio_marks.lbl{bio_count} = ['Connectivity - ATR - ' EEG.roi.atlas.Scouts(ii).Label];
                end

                % saving biomarkers
                EEG.roi.biomarkers = bio_marks;

                %% SENSOR LEVEL

                %  -----------------------------------------  %
                %         Cross-spectrum estimation           %
                %  -----------------------------------------  %
                fmin = 1;
                fmax = 50;
                fnum = 150;
                EEG.sen.frex = linspace(fmin,fmax,fnum);
                EEG = xspectrum(EEG,EEG.sen.frex);
                % visualization
                figures = plotting_sen_solution_v3(EEG);
                save(fullfile(subject_path,strcat(EEG.filename,'desc-figures_2.set')), '-struct', 'EEG','-mat','-v7.3');
                % saveas(gcf,fullfile(subject_path,strcat(EEG.subject,'_desc-BIO_SEN.png'));

                %  -----------------------------------------  %
                %             TFA estimation                  %
                %  -----------------------------------------  %
                EEG = trials_creation(EEG,200);
                [EEG.sen.tf,EEG.sen.tf_ext] = tf_estimation(EEG,EEG.sen.frex);
                fprintf('\n');
                %  -----------------------------------------  %
                %             PLI estimation                  %
                %  -----------------------------------------  %
                EEG = pli_estimation(EEG);

                % ************************************************************
                %
                %      BIOMARKERS EXTRACTION FROM TIME FREQUENCY ANALYSIS
                %
                % *************************************************************
                EEG.sen.labels = {'Fp1', 'Fp2', 'C3', 'C4', 'T3', 'T4', 'P3', 'P4', 'O1', 'O2'};
                EEG.sen.nsen = numel(EEG.sen.labels); % based on the 10-20 system
                EEG.sen.psd = 10*log10(EEG.sen.psd);
                % initial values
                frex = EEG.sen.frex;
                band_lim = [2 4; 4 7.5; 8 13; 14 35];
                band_lbl = {'delta','theta','alpha','beta'};
                bio_marks.val  = zeros(220,1);
                bio_marks.lbl  = [];
                bio_count      = 0;
                % absolute Power Spectral Density
                for jj = 1:size(band_lim,1)
                    for ii = 1:EEG.sen.nsen
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = sum((EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(jj,1)):dsearchn(frex',band_lim(jj,2)))).^2);
                        bio_marks.lbl{bio_count} = ['Activity - aPSD - ' band_lbl{jj} ' - ' EEG.sen.labels{ii}];
                    end
                end
                % relative Power Spectral Density (in percent)
                for jj = 1:size(band_lim,1)
                    for ii = 1:EEG.sen.nsen
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = 100*sum((EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(jj,1)):dsearchn(frex',band_lim(jj,2)))).^2)/...
                            sum((EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),:)).^2);
                        bio_marks.lbl{bio_count} = ['Activity - rPSD - ' band_lbl{jj} ' - ' EEG.sen.labels{ii}];
                    end
                end
                % Median Frequency
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    bio_marks.val(bio_count) = median(EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',4.1):dsearchn(frex',10.8)));
                    bio_marks.lbl{bio_count} = ['Activity - MDF - ' EEG.sen.labels{ii}];
                end
                % Alpha Peak Frequency
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    [~,indx1] = min(abs(6-frex));
                    [~,indx2] = min(abs(14-frex));
                    alphavector = EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),indx1:indx2).^2;
                    alphavector = smoothdata(alphavector, 'movmean', 3);
                    [~,LOCS] = findpeaks(alphavector);
                    if ~isempty(LOCS)
                        [~,tmp]    = max(alphavector(LOCS));
                        peak_pos_relative   = LOCS(tmp);
                        bio_marks.val(bio_count) =  frex(indx1+peak_pos_relative-1);
                        bio_marks.lbl{bio_count} = ['Activity - AlphaPeak - ' EEG.sen.labels{ii}];
                    end
                end
                % Alpha to Theta ration
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    alpha_power = sum((EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(3,1)):dsearchn(frex',band_lim(3,2)))).^2);
                    theta_power = sum((EEG.sen.psd(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(2,1)):dsearchn(frex',band_lim(2,2)))).^2);
                    bio_marks.val(bio_count) = alpha_power/theta_power;
                    bio_marks.lbl{bio_count} = ['Activity - ATR - ' EEG.sen.labels{ii}];
                end

                % BIOMARKERS EXTRACTION FROM CS ANALYSIS
                % initial values
                typeconn = 2;
                connectivity = zeros(size(EEG.sen.xspect,1),size(EEG.sen.xspect,3));
                for ii = 1:size(EEG.sen.xspect,3)
                    switch typeconn
                        case 1
                            tempconn = squeeze(abs(EEG.sen.xspect(:,:,ii)));
                            tempconn = tempconn-diag(diag(tempconn));
                            connectivity(:,ii) = efficiency_wei(tempconn,2);
                        case 2
                            tempconn = squeeze(abs(EEG.sen.xspect(:,:,ii)));
                            tempconn = tempconn-diag(diag(tempconn));
                            connectivity(:,ii) = sum(tempconn);
                        case 3
                            tempconn = squeeze(abs(EEG.sen.xspect(:,:,ii)));
                            tempconn = tempconn-diag(diag(tempconn));
                            connectivity(:,ii) = mean(tempconn);
                    end
                end
                % absolute Power Spectral Density
                for jj = 1:length(band_lim)
                    for ii = 1:EEG.sen.nsen
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = sum((connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(jj,1)):dsearchn(frex',band_lim(jj,2)))).^2);
                        bio_marks.lbl{bio_count} = ['Connectivity - aPSD - ' band_lbl{jj} ' - ' EEG.sen.labels{ii}];
                    end
                end
                % relative Power Spectral Density
                for jj = 1:length(band_lim)
                    for ii = 1:EEG.sen.nsen
                        bio_count = bio_count+1;
                        bio_marks.val(bio_count) = 100*sum((connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(jj,1)):dsearchn(frex',band_lim(jj,2)))).^2)/...
                            sum((connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),:)).^2);
                        bio_marks.lbl{bio_count} = ['Connectivity - rPSD - ' band_lbl{jj} ' - ' EEG.sen.labels{ii}];
                    end
                end
                % Median Frequency
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    bio_marks.val(bio_count) =  median(connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',4.1):dsearchn(frex',10.8)));
                    bio_marks.lbl{bio_count} = ['Connectivity - MDF - ' EEG.sen.labels{ii}];
                end
                % Alpha Peak Frequency
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    [~,indx1] = min(abs(6-frex));
                    [~,indx2] = min(abs(14-frex));
                    alphavector = connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),indx1:indx2).^2;
                    alphavector = smoothdata(alphavector, 'movmean', 3);
                    [PKS,LOCS] = findpeaks(alphavector);
                    if ~isempty(LOCS)
                        [~,tmp]    = max(alphavector(LOCS));
                        peak_pos_relative   = LOCS(tmp);
                        bio_marks.val(bio_count) =  frex(indx1+peak_pos_relative-1);
                        bio_marks.lbl{bio_count} = ['Connectivity - AlphaPeak - ' EEG.sen.labels{ii}];
                    end
                end
                % Alpha to Theta ration
                for ii = 1:EEG.sen.nsen
                    bio_count = bio_count+1;
                    alpha_power = sum((connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(3,1)):dsearchn(frex',band_lim(3,2)))).^2);
                    theta_power = sum((connectivity(find(contains(upper({EEG.chanlocs.labels}),upper(EEG.sen.labels(ii)))),dsearchn(frex',band_lim(2,1)):dsearchn(frex',band_lim(2,2)))).^2);
                    bio_marks.val(bio_count) = alpha_power/theta_power;
                    bio_marks.lbl{bio_count} = ['Connectivity - ATR - ' EEG.sen.labels{ii}];
                end

                % saving biomarkers
                EEG.sen.biomarkers = bio_marks;

                %%
                %% saving results
                %%
                fprintf('-->> Saving data to disk...\n')            
                save(fullfile(subject_path,strcat(EEG.filename,'_desc-bio.set')), '-struct', 'EEG','-mat','-v7.3');

                disp('-->> Saving processing level file.');
                Partic_info.Process(5).Comment    = "Estimating Biomarkers";
                Partic_info.Process(5).Status     = "Complete";
                Partic_info.Process(5).File       = fullfile(strcat(EEG.filename,'_desc-bio.set'));
                Partic_info.Process(5).Error      = "";
                saveJSON(Partic_info,fullfile(subject_path ,strcat(EEG.subject,'.json')));

            catch Ex
                fprintf('**************  error at source  *********************\n');

                disp('-->> Saving processing level file.');
                Partic_info.Process(5).Comment    = "Estimating Biomarkers";
                Partic_info.Process(5).Status     = "Error";
                Partic_info.Process(5).File       = "";
                Partic_info.Process(5).Error      = Ex.message;
                saveJSON(Partic_info,fullfile(subject_path ,strcat(EEG.subject,'.json')));

                app.Datasets.Participants(iPart).Status    = "Error";
                saveJSON(app.Datasets,app.dt_file);

                close all,
                restoredefaultpath;
                %%
                %% Restoring paths after error
                %%
                addpath(genpath(fullfile(pwd,'app')));
                addpath(fullfile(pwd,'dependencies','eeglab'));
                addpath(genpath(fullfile(pwd,'external')));
                addpath(genpath(fullfile(pwd,'functions')));
                addpath(genpath(fullfile(pwd,'guide')));
                addpath(genpath(fullfile(pwd,'report')));
                eeglab rebuild;
            end
                          
        end        
                        
        function ShowMessage(app,text)
            delete(app.PanelControls.Children);
            label = uilabel(Text=text,Position=[5,5,500,30],Parent=app.PanelControls);
            disp(strcat("-->> ", text));
        end     
               
        function FillDatasetTab(app)
            delete(app.TreeDatasets.Children);
            dt_path = fileparts(app.dt_file);
            app.currentDS.Location = dt_path;
            app.PanelViewer.Title = app.currentDS.Name;
            node = uitreenode(app.TreeDatasets, 'Text', app.currentDS.Name, 'Icon','guide/images/dataset.png');
            node.NodeData = app.currentDS;
            node.UserData = 'dataset';
            % Dataset Content menu
            dt_cm = uicontextmenu(app.UIFigure);
            exp_cm = uimenu(dt_cm,"Text","Export All");
            uimenu(exp_cm,"Text","Basic report","MenuSelectedFcn",{@app.DefineAction,'BasicAll',node.NodeData});
            uimenu(exp_cm,"Text","Medium report","MenuSelectedFcn",{@app.DefineAction,'MediumAll',node.NodeData});
            uimenu(exp_cm,"Text","Expert report","MenuSelectedFcn",{@app.DefineAction,'ExpertAll',node.NodeData});
            uimenu(dt_cm,"Text","Reload Dataset","MenuSelectedFcn",{@app.DefineAction,'Reload',node.NodeData},'Separator','on');
            node.ContextMenu = dt_cm;
            for i=1:length(app.currentDS.Participants)
                participant = app.currentDS.Participants(i);
                nodeSub = uitreenode(node, 'Text', participant.SubID);
                % subject = jsondecode(fileread(fullfile(dt_path,participant.SubID,participant.FileInfo)));
                nodeSub.NodeData = participant;
                nodeSub.UserData = 'subject';
                icon_file = 'guide/images/subject.png';
                nodeSub.Icon = icon_file;
                % Subject content menu
                sub_cm = uicontextmenu(app.UIFigure);
                exp_cm = uimenu(sub_cm,"Text","Export subject");
                uimenu(exp_cm,"Text","Basic report","MenuSelectedFcn",{@app.DefineAction,'Basic',nodeSub.NodeData});
                uimenu(exp_cm,"Text","Medium report","MenuSelectedFcn",{@app.DefineAction,'Medium',nodeSub.NodeData});
                uimenu(exp_cm,"Text","Expert report","MenuSelectedFcn",{@app.DefineAction,'Expert',nodeSub.NodeData});
                uimenu(sub_cm,"Text","Mark subject","MenuSelectedFcn",{@app.DefineAction,'Mark',nodeSub.NodeData});
                uimenu(sub_cm,"Text","Reject subject","MenuSelectedFcn",{@app.DefineAction,'Reject',nodeSub.NodeData},'Separator','on');
                nodeSub.ContextMenu = sub_cm;

                % Node Common
                nodeCom = uitreenode(nodeSub, 'Text', 'Common','Icon','guide/images/common.png');
                uitreenode(nodeCom, 'Text', "EEG signal","Icon",'guide/images/eeg_data.png');

                % Node Sensor
                nodeSensor = uitreenode(nodeSub, 'Text', 'Sensor','Icon','guide/images/sensor.png');
                option1 = uitreenode(nodeSensor, 'Text', "PSD","UserData","Sensor-PSD","Icon",'guide/images/psd.png');
                option2 = uitreenode(nodeSensor, 'Text', "Topography","UserData","Sensor-Topo","Icon",'guide/images/topo.png');
                option3 = uitreenode(nodeSensor, 'Text', "Connectivity","UserData","Sensor-Conn","Icon",'guide/images/connectivity.png');

                % Node Sensor
                nodeSource = uitreenode(nodeSub, 'Text', 'Source','Icon','guide/images/source.png');
                option1 = uitreenode(nodeSource, 'Text', "PSD","UserData","Source-PSD","Icon",'guide/images/psd.png');
                option2 = uitreenode(nodeSource, 'Text', "Connectivity","UserData","Source-Conn","Icon",'guide/images/connectivity.png');
            end
            expand(app.TreeDatasets);
        end

        function PlotEEG_signal(app)
            delete(app.PanelViewer.Children);
            ax = axes(app.PanelViewer);
            EEG = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), ...
                'data', 'times', 'srate','chanlocs','nbchan','pnts','xmax',  '-mat');
            EEG.data    = reshape(EEG.data,size(EEG.data,1),size(EEG.data,2)*size(EEG.data,3));
            EEG.pnts    = size(EEG.data,2);
            EEG.xmin    = 0;
            EEG.xmax    = EEG.xmin+(EEG.pnts-1)*(1/EEG.srate);
            EEG.times   = (0:EEG.pnts-1)/EEG.srate.*1000;

            pos = app.PanelControls.Position;
            uibutton(app.PanelControls,'Text','<<','Position',[(pos(3)/2-70) 5 30 30],...
                'ButtonPushedFcn',@(cbt,event) app.ChangeTimeEEGData(EEG,1));             % << option
            uibutton(app.PanelControls,'Text','<','Position',[(pos(3)/2-40+2) 5 30 30],...
                'ButtonPushedFcn',@(cbt,event) app.ChangeTimeEEGData(EEG,2));             % < option
            uieditfield(app.PanelControls,'Value','0','Position',[(pos(3)/2-5) 5 50 30], ...
                'FontSize',16,'FontWeight','bold','HorizontalAlignment','center');
            uibutton(app.PanelControls,'Text','>','Position',[(pos(3)/2+50) 5 30 30],...
                'ButtonPushedFcn',@(cbt,event) app.ChangeTimeEEGData(EEG,3));             % > option
            uibutton(app.PanelControls,'Text','>>','Position',[(pos(3)/2+80+2) 5 30 30],...
                'ButtonPushedFcn',@(cbt,event) app.ChangeTimeEEGData(EEG,4));             % >> option
            app.SEEGData(EEG,0);
        end
        function SEEGData(app, EEG,iniTime)
            app.currentAxes = uiaxes(app.PanelViewer);
            pos = app.PanelViewer.Position;
            app.currentAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = EEG.data;
            time = EEG.times;
            srate = EEG.srate;
            chanlocs = EEG.chanlocs;
            nbchan = EEG.nbchan;
            pnts = EEG.pnts;
            winlength	= 5; 	 % Number of seconds of EEG displayed

            %%
            %% getting spacing
            %%
            maxindex = min(1000, pnts);
            stds = std(data(:,1:maxindex),[],2);
            stds = sort(stds);
            if length(stds) > 2
                stds = mean(stds(2:end-1));
            else
                stds = mean(stds);
            end
            spacing = stds*3;
            if spacing > 10
                spacing = round(spacing);
            end
            if spacing  == 0 || isnan(spacing)
                spacing = 1; % default
            end
            %%
            %% Plotting data
            %%
            lowlim = round((time+iniTime)*srate+1);
            highlim = round(min((time+iniTime+winlength)*srate+2,pnts));
            plot(app.currentAxes,bsxfun(@plus, data(end:-1:1,lowlim:highlim), spacing*(1:nbchan)')', ...
                'color', 'b', 'clipping','on');
            xlim(app.currentAxes,[1 winlength*srate]);
            xticks(app.currentAxes,(1+iniTime:srate:(iniTime+winlength)*srate+1)');            
            xticklabels(app.currentAxes,num2str((iniTime:1:iniTime+winlength-1)'));
            app.currentAxes.XLabel.String = 'Time (seg)';
            app.currentAxes.YLabel.String = 'Labels';
            ylim(app.currentAxes,[0 (nbchan+1)*spacing]);
            yticks(app.currentAxes,0:spacing:nbchan*spacing);            
            set(app.currentAxes,'TickLength',[.005 .005]);
            YLabels = {chanlocs.labels}';
            YLabels = strvcat(YLabels);
            YLabels = flipud(char(YLabels,' '));
            yticklabels(app.currentAxes,YLabels);
        end

        function ChangeTimeEEGData(app,EEG,action)
            iniTime = str2num(app.PanelControls.Children(3).Value);            
            switch action
                case 1
                    iniTime = iniTime-5;     % << subtract one window length
                case 2
                    iniTime = iniTime-1;     % < subtract one second
                case 3
                    iniTime = iniTime+1;     % > add one second
                case 4
                    iniTime = iniTime+5;     % >> add one window length                    
            end   
            if(iniTime < 0)
                iniTime = 0;
            end
            if(iniTime+5 > round(EEG.xmax))
                iniTime = round(EEG.xmax) - 5;
            end
            app.PanelControls.Children(3).Value = string(iniTime);
           app.SEEGData(EEG,iniTime);
        end

        function PlotSensorPSD(app)
            delete(app.PanelViewer.Children);
            ax = axes(app.PanelViewer) ;
            load('mycolormap6.mat','mycmap6');
            band_lim = [1 3; 5 6; 10 12; 18 35];
            load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), 'sen', '-mat');
            plot(ax,sen.frex(1:dsearchn(sen.frex',band_lim(4,2))),10*log10(sen.psd(:,1:dsearchn(sen.frex',band_lim(4,2)))),'LineWidth',2);
            xlabel(ax,'Freq (Hz)','FontSize',14);
            ylabel(ax,'PSD (dB) at sensor level','FontSize',14);
            grid(ax,"on");
        end               
        
        function PlotSensorTopo(app)
            delete(app.PanelControls.Children);
            delete(app.PanelViewer.Children);
            load('mycolormap6.mat','mycmap6');           
            load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), 'sen','chanlocs', '-mat');
            pos = app.PanelControls.Position;
            bg = uibuttongroup(app.PanelControls,"Position",[(pos(3)/2-150) 5 300 30], ...
                "SelectionChangedFcn",@(src,event) app.PlotTopoBand(sen, chanlocs), "BorderType","none", FontSize=14,FontWeight="bold");
            b1 = uiradiobutton(bg,"Text","Delta","Position",[10 5 70 22], FontSize=14,FontWeight="bold");
            b2 = uiradiobutton(bg,"Text","Theta","Position",[90 5 70 22], FontSize=14,FontWeight="bold");
            b3 = uiradiobutton(bg,"Text","Alpha","Position",[170 5 70 22], FontSize=14,FontWeight="bold");
            b4 = uiradiobutton(bg,"Text","Beta","Position",[250 5 70 22], FontSize=14,FontWeight="bold");           
            
            app.PlotTopoBand(sen, chanlocs);
        end

        function PlotTopoBand(app,sen, chanlocs)
            band_lim = [1 3; 5 6; 10 12; 18 35];
            buttons = app.PanelControls.Children(1).Children;
            if(buttons(4).Value)
                topoplotIndie(10*log10(mean(sen.psd(:,dsearchn(sen.frex',band_lim(1,1)):dsearchn(sen.frex',band_lim(1,2))),2))', ...
                    chanlocs,'ELECTRODES','LABELS');
            elseif(buttons(3).Value)
                topoplotIndie(10*log10(mean(sen.psd(:,dsearchn(sen.frex',band_lim(2,1)):dsearchn(sen.frex',band_lim(2,2))),2))', ...
                    chanlocs,'ELECTRODES','LABELS');
            elseif(buttons(2).Value)
                topoplotIndie(10*log10(mean(sen.psd(:,dsearchn(sen.frex',band_lim(3,1)):dsearchn(sen.frex',band_lim(3,2))),2))', ...
                    chanlocs,'ELECTRODES','LABELS');
            else
                topoplotIndie(10*log10(mean(sen.psd(:,dsearchn(sen.frex',band_lim(4,1)):dsearchn(sen.frex',band_lim(4,2))),2))', ...
                    chanlocs,'ELECTRODES','LABELS');
            end
            ax = gca;
            ax.Parent = app.PanelViewer;
            colormap(ax,'turbo');
            ylabel(ax,'ACTIVITY','FontSize',20)   
            axis(ax,"off");
            figHandles = findobj('Type', 'figure');
            delete(figHandles);
        end    

        function PlotSensorConn(app)
            delete(app.PanelControls.Children);
            delete(app.PanelViewer.Children);
            load('mycolormap6.mat','mycmap6');           
            load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), 'sen','chanlocs', '-mat');
            pos = app.PanelControls.Position;
            bg = uibuttongroup(app.PanelControls,"Position",[(pos(3)/2-150) 5 300 30], ...
                "SelectionChangedFcn",@(src,event) app.PlotSensorConnBand(sen, chanlocs), "BorderType","none");
            b1 = uiradiobutton(bg,"Text","Delta","Position",[10 5 70 22], FontSize=14,FontWeight="bold");
            b2 = uiradiobutton(bg,"Text","Theta","Position",[90 5 70 22], FontSize=14,FontWeight="bold");
            b3 = uiradiobutton(bg,"Text","Alpha","Position",[170 5 70 22], FontSize=14,FontWeight="bold");
            b4 = uiradiobutton(bg,"Text","Beta","Position",[250 5 70 22], FontSize=14,FontWeight="bold");  

            app.PlotSensorConnBand(sen, chanlocs);
        end

        function PlotSensorConnBand(app,sen, chanlocs)
            delete(app.PanelViewer.Children);
            ax = axes(app.PanelViewer) ;
            band_lim = [1 3; 5 6; 10 12; 18 35];
            buttons = app.PanelControls.Children(1).Children;
            if(buttons(4).Value)
                band = band_lim(1,:);
            elseif(buttons(3).Value)
                band = band_lim(2,:);
            elseif(buttons(2).Value)
                band = band_lim(3,:);
            else
                band = band_lim(4,:);
            end
            connectivityMatrix = squeeze(mean(abs(sen.xspect(:,:,dsearchn(sen.frex',band(1)):dsearchn(sen.frex',band(2)))),3));
            connectivityMatrix(isnan(connectivityMatrix)) = 0;
            connectivityMatrix = connectivityMatrix - diag(diag(connectivityMatrix));
            connectivityMatrix = connectivityMatrix - min(connectivityMatrix(connectivityMatrix > 0));
            connectivityMatrix = connectivityMatrix - diag(diag(connectivityMatrix));
            connectivityMatrix = connectivityMatrix/max(connectivityMatrix(:));
            % Cell array of labels for the 19 sensors
            sensorLabels = {'Fp1', 'Fp2', 'F7', 'F3', 'Fz', 'F4', 'F8', 'T3', 'C3', 'Cz', 'C4', 'T4', 'T5', 'P3', 'Pz', 'P4', 'T6', 'O1', 'O2'};
            % Mapping of sensors to grid nodes
            sensorToNodeMap = [2, 4, 6, 7, 8, 9, 10, 11, 12,13, 14, 15, 16, 17, 18, 19, 20, 22, 24];
            % Number of nodes in the grid
            % Generate node positions for a 5x5 grid
            [x, y] = meshgrid(1:5, 1:5);
            x = x(:);
            y = y(:);
            % Rotate the positions by 90 degrees (clockwise)
            x_rotated = y;
            y_rotated = 6-x;
            % Plot nodes that have labels
            hold(ax,"on");
            % Plot the head circle
            theta = linspace(0, 2*pi, 100);
            head_x = 3 * cos(theta) + 3;
            head_y = 3 * sin(theta) + 3;
            plot(ax,head_x, head_y, 'k-', 'LineWidth', 2);
            % Plot the nose
            nose_x = [3, 3.3, 2.7, 3];
            nose_y = [6.2, 6, 6, 6.2];
            plot(ax,nose_x, nose_y, 'k-', 'LineWidth', 2);
            % Plot the labeled nodes
            for i = 1:length(sensorToNodeMap)
                nodeIndex = sensorToNodeMap(i);
                plot(ax,x_rotated(nodeIndex), y_rotated(nodeIndex), 'ko', 'MarkerSize', 20, 'LineWidth', 0.5);
                text(ax,x_rotated(nodeIndex), y_rotated(nodeIndex), sensorLabels{i}, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', 'FontSize', 10);
            end
            % Plot connections based on the connectivity matrix
            threshold_conn = prctile(connectivityMatrix(:), 90);
            for i = 1:length(sensorToNodeMap)
                for j = i+1:length(sensorToNodeMap)
                    node1 = sensorToNodeMap(i);
                    node2 = sensorToNodeMap(j);
                    idx1 = find(contains(upper({chanlocs.labels}),upper(sensorLabels{i})));
                    idx2 = find(contains(upper({chanlocs.labels}),upper(sensorLabels{j})));
                    if connectivityMatrix(idx1, idx2) > threshold_conn
                        plot(ax,[x_rotated(node1) x_rotated(node2)], [y_rotated(node1) y_rotated(node2)], '-', 'LineWidth', 2,'Color',[0 0 connectivityMatrix(idx1, idx2)]);
                    end
                end
            end
            axis(ax,"off");
            ylabel(ax,'ACTIVITY','FontSize',20)
        end

        function PlotSourcePSD(app)
            delete(app.PanelViewer.Children);
            ax = axes(app.PanelViewer);
            load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), 'roi', '-mat');
            frex = roi.freqs';
            frex(1:3) = [];
            psdplot = roi.source_roi_power'; 
            psdplot(:,1:3) = [];
            plot(ax,frex(1:dsearchn(frex',25)),10*log10(psdplot(:,1:dsearchn(frex',25))),'LineWidth',1)
            ax.XLabel.String = 'Freq (Hz)';
            ax.YLabel.String = 'PSD (dB) at Source Level';
            grid(ax,'on');
        end
        
        function PlotSourceAtivConnec(app)
            delete(app.PanelViewer.Children);            
            load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Process(5).File), 'roi', '-mat');

            pos = app.PanelControls.Position;
            bg = uibuttongroup(app.PanelControls,"Position",[(pos(3)/2-150) 5 300 30], ...
                "SelectionChangedFcn",@(src,event) app.PlotSourceConnBand(roi), "BorderType","none");
            b1 = uiradiobutton(bg,"Text","Delta","Position",[10 5 70 22], FontSize=14,FontWeight="bold");
            b2 = uiradiobutton(bg,"Text","Theta","Position",[90 5 70 22], FontSize=14,FontWeight="bold");
            b3 = uiradiobutton(bg,"Text","Alpha","Position",[170 5 70 22], FontSize=14,FontWeight="bold");
            b4 = uiradiobutton(bg,"Text","Beta","Position",[250 5 70 22], FontSize=14,FontWeight="bold");
            app.PlotSourceConnBand(roi);            
        end

        function PlotSourceConnBand(app,roi)
            delete(app.PanelViewer.Children);
            ax = axes(app.PanelViewer);
            load('mycolormap6.mat','mycmap6');
            band_lim = [1 3; 5 6; 10 12; 18 35];
             buttons = app.PanelControls.Children(1).Children;
            if(buttons(4).Value)
                band = band_lim(1,:);
            elseif(buttons(3).Value)
                band = band_lim(2,:);
            elseif(buttons(2).Value)
                band = band_lim(3,:);
            else
                band = band_lim(4,:);
            end
            frex = roi.freqs';
            frex(1:3) = [];
            CData = roi.source_roi_power';
            CData = mean(CData(:,dsearchn(frex',band(1)):dsearchn(frex',band(2))),2);
            temp1 = zeros(size(roi.cortex.Vertices,1),1);
            for ii = 1:numel(roi.atlas.Scouts)
                temp1(roi.cortex.Atlas.Scouts(ii).Vertices) = CData(ii);
            end
            % Compute the mean coordinate for each ROI
            roiCoordinates = zeros(numel(roi.cortex.Atlas.Scouts), 3);
            for roii = 1:numel(roi.cortex.Atlas.Scouts)
                roiVertices = roi.cortex.Vertices(roi.cortex.Atlas.Scouts(roii).Vertices,:);
                roiCoordinates(roii, :) = mean(roiVertices, 1);
            end
            % Connectivity matrix from Cross-spectrum
            connectivityMatrix = squeeze(mean(abs(roi.iCOH(dsearchn(frex',band(1)):dsearchn(frex',band(2)),:,:)),1));
            connectivityMatrix(isnan(connectivityMatrix)) = 0;
            connectivityMatrix = connectivityMatrix - diag(diag(connectivityMatrix));
            connectivityMatrix = connectivityMatrix - min(connectivityMatrix(connectivityMatrix > 0));
            connectivityMatrix = connectivityMatrix - diag(diag(connectivityMatrix));
            connectivityMatrix = connectivityMatrix/max(connectivityMatrix(:));

            % Add lines for connections with values higher than the threshold
            temp2 = sort(connectivityMatrix(:),'descend');
            threshold_conn = temp2(numel(roi.cortex.Atlas.Scouts)); 
            
            patch(ax,'Faces',roi.cortex.Faces,'Vertices',roi.cortex.Vertices, 'CData', temp1, 'FaceColor', 'interp','EdgeColor','none','FaceAlpha', 0.5);
            colormap(ax, mycmap6);
            hold(ax,'on')
            
            % Plot the ROIs
            hold(ax,'on')
            scatter3(ax,roiCoordinates(:, 1), roiCoordinates(:, 2), roiCoordinates(:, 3), 10,'black', 'filled');

            % Add lines for connections with values higher than the threshold
            for i = 1:numel(roi.cortex.Atlas.Scouts)
                for j = i+1:numel(roi.cortex.Atlas.Scouts)
                    if connectivityMatrix(i, j) > threshold_conn
                        line(ax,[roiCoordinates(i, 1), roiCoordinates(j, 1)], ...
                            [roiCoordinates(i, 2), roiCoordinates(j, 2)], ...
                            [roiCoordinates(i, 3), roiCoordinates(j, 3)], ...
                            'LineWidth', 2,'Color','black');
                    end
                end
            end  
            axis(ax,"off");
            rotate3d(ax,'on');
        end          
          
        function SensorCrossSpectrum(app)    
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
            freqs = data.freq;
            Svv = data.Cross; 
            PSD = zeros(size(Svv,1),length(freqs));
            for freq = 1:length(freqs)
                PSD(:,freq) = diag(squeeze(abs(Svv(:,:,freq))));
            end
            PSD_log = 10*log10(abs(PSD));
            plot(app.UIAxes,freqs,PSD_log);
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end

        function SourceCrossSpectrum(app)    
            app.UIAxes = uiaxes(app.PanelViewer);             
            pos = app.PanelViewer.Position;
            app.UIAxes.Position = [5 5 pos(3)-10 pos(4)-10];
            data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_PSD));
            freqs = data.freq;
            PSD = data.Source_PSD; 
            PSD_log = 10*log10(abs(PSD));
            plot(app.UIAxes,freqs,PSD_log);
            app.UIAxes.XLabel.String    = 'Freq. (Hz)';
            app.UIAxes.FontSize         = 14;
            app.UIAxes.XColor           = 'K';
            app.UIAxes.YLabel.String    = 'PSD (dB)';
            app.UIAxes.YColor           = 'K';
            app.UIAxes.Title.String     = 'Power Spectral Density';
        end
        
        function PlotMeasure(app,J)
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));             
            colorMap                = load("template/mycolormap_brain_basic_conn.mat");
            template                = load("guide/axes.mat");
            app.currentAxes         = template.axes;
            J_smoothed              = J;
            sources_iv = J_smoothed;%_star;
            sources_iv              = sources_iv/max(sources_iv(:));
            %%
            smoothValue             = 0.4;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',Cortex.SulciMap*0.00+1+sources_iv, ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',1);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            colormap(app.currentAxes,colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            axis(app.currentAxes,'equal');
            rotate3d(app.currentAxes,'on');
        end
        
        function PlotFullActivation(app,full_activation)
            Cortex                  = load(fullfile(app.currentDS.Location, app.currentDS.Structural.Cortex));             
            colorMap                = load("template/mycolormap_brain_basic_conn.mat");
            template                = load("guide/axes.mat");
            app.currentAxes         = template.axes;
            delete(app.PanelViewer.Children);
            J = abs(full_activation(:,app.PanelControls.Children(1).ValueIndex));
            J_smoothed              = J;
            sources_iv = J_smoothed;%_star;
            sources_iv              = sources_iv/max(sources_iv(:));
            %%
            smoothValue             = 0.4;
            SurfSmoothIterations    = 10;
            Vertices                = tess_smooth(Cortex.Vertices, smoothValue, SurfSmoothIterations, Cortex.VertConn, 1);
            patch(app.currentAxes, ...
                'Faces',Cortex.Faces, ...
                'Vertices',Vertices, ...
                'FaceVertexCData',Cortex.SulciMap*0.00+1+sources_iv, ...
                'FaceColor','interp', ...
                'EdgeColor','none', ...
                'AlphaDataMapping', 'none', ...
                'EdgeColor',        'none', ...
                'EdgeAlpha',        1, ...
                'BackfaceLighting', 'lit', ...
                'AmbientStrength',  0.5, ...
                'DiffuseStrength',  0.5, ...
                'SpecularStrength', 0.2, ...
                'SpecularExponent', 1, ...
                'SpecularColorReflectance', 0.5, ...
                'FaceLighting',     'gouraud', ...
                'EdgeLighting',     'gouraud', ...
                'FaceAlpha',1);
            set(app.currentAxes,'xcolor','w','ycolor','w','zcolor','w');
            view(app.currentAxes,0,0);
            colormap(app.currentAxes,colorMap.cmap_a);
            set(app.currentAxes,"Parent",app.PanelViewer);
            axis(app.currentAxes,'equal');
            rotate3d(app.currentAxes,'on');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %movegui(app.UIFigure,"center");
            app.app_properties = get_properties();
            % Load Explorer tree
            userDir = getUserDir();
            app.Breadcrumbs(userDir);
            [nodeData.folder,nodeData.name] = fileparts(userDir);
            nodeData.isdir = 1;
            app.ExploreFolder(nodeData);   

            app.XANdir  = xan_get('xan_dir'); 
            app.Datasets_file = xan_get('datasets_file');
            
            app.LoadDatasetsDB();
            app.FillDatasetsTree();
            figure(app.UIFigure);
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end

        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            nodeData = event.InteractionInformation.Node.NodeData;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);   
                dirName = split(rootDir,filesep);
                dirName =dirName{end};
                rootDir = fileparts(rootDir);
                nodeData.folder = rootDir; 
                nodeData.name = dirName;            
            end
            if(nodeData.isdir)
                app.ExploreFolder(nodeData);
            end

        end

        % Callback function: PushToolRunProcess
        function PushToolRunProcessClicked(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Processing dataset. Please wait');
            if(isempty(app.app_properties.general.input_path))
                msg =  msgbox({'You should define a correct Input folder.'},'Info');
                waitfor(msg);
                figure(app.UIFigure);
                return;
            end
            if(isempty(app.app_properties.general.output_path))
                msgbox({'You should define a correct Output folder.'},'Info');
                figure(app.UIFigure);
                return;
            end          
            subjects = dir(app.app_properties.general.input_path);
            subjects(ismember({subjects.name},{'.','..'})) = [];

            for sub=1:length(subjects)
                if(isfile(app.dt_file))
                    app.Datasets = jsondecode(fileread(app.dt_file));
                else
                    [~,name,~] = fileparts(app.app_properties.general.input_path);
                    app.Datasets.Name = name;
                    app.Datasets.Participants = [];
                end
                participant = subjects(sub);
                text = strcat("Processing subject: ", participant.name, ".");                
                app.ShowMessage(text)
                if(isempty(app.Datasets.Participants))
                     iPart = 1;
                else
                    iPart = find(ismember({app.Datasets.Participants.SubID},participant.name),1);
                    if(~isempty(iPart) && isequal(app.Datasets.Participants(iPart).Status,"Complete"))
                        continue;
                    end
                    if(isempty(iPart))
                        iPart = length(app.Datasets.Participants)+1;
                    end
                end  

                %%
                %% Starting Dataset configuretion
                %%
                disp("-->> Saving Dataset file information");
                app.Datasets.Participants(iPart).SubID                   = participant.name;
                app.Datasets.Participants(iPart).Status                  = "Processing";
                app.Datasets.Participants(iPart).FileInfo                = strcat(participant.name,".json");
                app.Datasets.Participants(iPart).Sessions                = "";
                app.Datasets.Participants(iPart).Name                    = "";
                app.Datasets.Participants(iPart).Age                     = "";
                app.Datasets.Participants(iPart).Gender                  = "";
                app.Datasets.Participants(iPart).Handedness              = "";
                app.Datasets.Participants(iPart).EEG.EEG_ID              = "";
                app.Datasets.Participants(iPart).EEG.EEG_recorded_on     = "";
                app.Datasets.Participants(iPart).EEG.EEG_processed_on    = "";
                app.Datasets.Participants(iPart).EEG.Test_Date           = "";
                app.Datasets.Participants(iPart).EEG.Condition           = "Resting";            
                app.Datasets.Participants(iPart).Error                   = [];


                subject_path                      = fullfile(app.app_properties.general.output_path,participant.name);
                %% Create Participant                
                app.ShowMessage(strcat(text, " - Creating participant"));
                Partic_info = app.create_participant(iPart,participant,subject_path);
                if(~isequal(Partic_info.Process(end).Status,"Complete"))
                    app.Datasets.Participants(iPart).Status                  = "Error";
                    continue;
                end

                %%
                %% step 1: loading data
                %%
                app.ShowMessage(strcat(text, " - Importing EEG file"));
                [EEG, Partic_info] = app.Import_data(participant, Partic_info, subject_path, iPart);
                if(~isequal(Partic_info.Process(end).Status,"Complete"))
                    continue;
                end

                %%
                %% step 2: Import and edit channels
                %%
                app.ShowMessage(strcat(text, " - Importing Channels"));
                [EEG, Partic_info] = app.Import_channels(EEG, Partic_info, subject_path, iPart);
                if(~isequal(Partic_info.Process(end).Status,"Complete"))
                    continue;
                end

                %%
                %%
                %%
                app.ShowMessage(strcat(text, " - Cleanning data"));
                [EEG, Partic_info] = app.biomarkers_preprocessing(EEG, Partic_info, subject_path, iPart);
                if(~isequal(Partic_info.Process(end).Status,"Complete"))
                    continue;
                end

                [EEG, Partic_info] = app.biomarkers_estimation(Partic_info, subject_path, iPart);
                if(~isequal(Partic_info.Process(end).Status,"Complete"))
                    continue;
                end

                %% 
                %% Export report
                %%
                % createAdvancedReport(app.eegp_properties.general.output_path, Partic_info);  

                %%
                %% Save Subject Process on dataset
                %% 
                app.Datasets.Participants(iPart).Status = "Complete";
                saveJSON(app.Datasets,app.dt_file);
                
                close all;
            end
            close(d);
            figure(app.UIFigure);
            app.currentDS = jsondecode(fileread(app.dt_file));
            app.FillDatasetTab();

            if (ispc)
                winopen(app.currentDS.Location);
            elseif ismac
                cmdToExecute = ['open ' app.currentDS.Location];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            else
                % using xdg-open to open a file in Linux. Some Linux systems might not have
                % xdg-open .In that case displaying as error with the file path
                cmdToExecute = ['xdg-open ' app.currentDS.Location];
                [status, path] = system(cmdToExecute); %#ok<ASGLU>
            end
            disp("=============================================================");
            disp("-->> Procesing finished");
            disp("=============================================================");
        end

        % Selection changed function: TreeDatasets
        function TreeDatasetsSelectionChanged(app, event)
            selectedNode = app.TreeDatasets.SelectedNodes;           
            delete(app.PanelControls.Children);
            nodeData = selectedNode.NodeData;

            %%
            %%  Filling actions
            %%
            delete(app.TreeActions.Children);
            if(isequal(selectedNode.UserData.NodeType,'dataset'))
                app.currentDS = jsondecode(fileread(fullfile(selectedNode.NodeData.Location,'XIALPHANET.json')));                
            end


            if(isequal(selectedNode.UserData.NodeType,'subject'))
                app.currentDS = selectedNode.Parent.NodeData;
                app.currentSub = nodeData;
            end
            
            if(~isempty(fields(nodeData)))
                child = uitreenode(app.TreeActions,'Text','PSD');
                child.Tag = 'psd';
                child.Icon = 'guide/images/frequency.png';
                child.NodeData = nodeData;

                %% Alpha
                child = uitreenode(app.TreeActions,'Text','Alpha');
                child.Icon = 'guide/images/frequency.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Power');
                subChild.Tag = 'power_alpha';
                subChild.Icon = 'guide/images/power.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Width');
                subChild.Tag = 'width_alpha';
                subChild.Icon = 'guide/images/width.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Exponent');
                subChild.Tag = 'exponent_alpha';
                subChild.Icon = 'guide/images/exponent.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','PAF');
                subChild.Tag = 'paf_alpha';
                subChild.Icon = 'guide/images/paf.png';
                subChild.NodeData = nodeData;

                %% Xi (AC)
                child = uitreenode(app.TreeActions,'Text','Xi (AC)');
                child.Icon = 'guide/images/frequency.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Power');
                subChild.Tag = 'power_xi';
                subChild.Icon = 'guide/images/power.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Width');
                subChild.Tag = 'width_xi';
                subChild.Icon = 'guide/images/width.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Exponent');
                subChild.Tag = 'exponent_xi';
                subChild.Icon = 'guide/images/exponent.png';
                subChild.NodeData = nodeData;

                %% Activation
                child = uitreenode(app.TreeActions,'Text','Activation');
                child.Tag = 'activation';
                child.Icon = 'guide/images/activation.png';
                child.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Full Activation');
                subChild.Tag = 'full_activation';
                subChild.Icon = 'guide/images/activation.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Alpha Activation');
                subChild.Tag = 'activation_alpha';
                subChild.Icon = 'guide/images/activation.png';
                subChild.NodeData = nodeData;

                subChild = uitreenode(child,'Text','Xi Activation');
                subChild.Tag = 'activation_xi';
                subChild.Icon = 'guide/images/activation.png';
                subChild.NodeData = nodeData;               


                %% Anat Connectivity
                child = uitreenode(app.TreeActions,'Text','Anat Connectivity');
                child.Tag = 'anat_conn';
                child.Icon = 'guide/images/anat_conn.png';
                child.NodeData = nodeData;

                %% Condition Delay
                child = uitreenode(app.TreeActions,'Text','Conduction Delay');
                child.Tag = 'cond_delay';
                child.Icon = 'guide/images/delays.png';
                child.NodeData = nodeData;

                %% Source PSD
                child = uitreenode(app.TreeActions,'Text','Source PSD');
                child.Tag = 'source_psd';
                child.Icon = 'guide/images/frequency.png';
                child.NodeData = nodeData;

                %% Shows cross spectrum
                app.SensorCrossSpectrum();
            else
                delete(app.PanelViewer.Children);
                delete(app.PanelControls.Children);
            end
            expand(app.TreeActions,'all');
        end

        % Button pushed function: UpperButton
        function UpperButtonPushed(app, event)
            nodeData = app.TreeInput.Children(1).NodeData;
            rootDir = fileparts(nodeData.folder);
            dirName = split(nodeData.folder,filesep);
            dirName =dirName{end};
            nodeData.folder = rootDir;
            nodeData.name = dirName;
            app.ExploreFolder(nodeData);
        end

        % Button pushed function: NewFolderButton
        function NewFolderButtonPushed(app, event)
            selectedNode = app.TreeInput.SelectedNodes;
            if(isempty(selectedNode))
                selectedNode = app.TreeInput.Children(1);
            end
            inputDLG = inputdlg({'Name:'}, 'New folder', 1);
            if(~isempty(inputDLG))
                folderName= char(inputDLG(1));                
                rootDir = fullfile(selectedNode.NodeData.folder,selectedNode.NodeData.name);
                folders = dir(rootDir);
                if(~isempty(find(ismember({folders.name},{folderName}),1)))
                     msg = msgbox({'The folder name alresdy exist'},'Info');
                     waitfor(msg);
                     return;
                end
                try
                    mkdir(fullfile(rootDir,folderName));
                catch Ex
                    msg = msgbox({Ex.message},'Info');
                    waitfor(msg);
                end
                app.ExploreFolder(selectedNode.NodeData);
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            nodeData = app.TreeInput.Children(1).NodeData;       
            app.ExploreFolder(nodeData);
        end

        % Button pushed function: DeleteFolderButton
        function DeleteFolderButtonPushed(app, event)
            selectedNode = app.TreeInput.SelectedNodes;
            if(isempty(selectedNode))
                msg = msgbox({'Please select a folder to delete'},'Info');
                waitfor(msg);
                return;
            end
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for message box');
            opts.Interpreter = 'tex';
            % Include the desired Default answer
            opts.Default = 'Cancel';
            % Use the TeX interpreter to format the question
            quest = {strcat("Would you like to delete the folder: ",selectedNode.Text)};
            answer = questdlg(quest,'Delete folder',...
                'Yes','Cancel',opts);
            % Handle response
            switch answer
                case 'Yes'
                    nodeData = selectedNode.NodeData;
                    [status, message, ~] = rmdir(fullfile(nodeData.folder,nodeData.name), 's');
                    if(status)
                        msg = msgbox(strcat("The folder ",selectedNode.Text, " was deleted."),'Info');
                        waitfor(msg);
                        app.RefreshButtonPushed(event);
                    else
                        msg = msgbox(message,'info');
                        waitfor(msg);
                    end
                case 'Cancel'
                    close(d);
                    figure(app.UIFigure);
                    return;                
            end
            close(d);
            figure(app.UIFigure);
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Waiting for the parameters configuration');
            obj_Config = ParamsUI();
            waitfor(obj_Config,'Finished',true);
            % app.app_properties = obj_Config.app_properties;
            delate(obj_Config);
            app.app_properties = get_properties();
            close(d);
            figure(app.UIFigure);
        end

        % Button pushed function: OpenFolderButton
        function OpenFolderButtonPushed(app, event)
            try
                folder = uigetdir("Select the root path");
                folder = strrep(folder,'\','/');
                [base_path, name] = fileparts(folder);
                nodeData.isdir = 1;
                nodeData.name = name;
                nodeData.folder = base_path;
                app.ExploreFolder(nodeData);
                figure(app.UIFigure);
            catch
                
            end
        end

        % Selection changed function: TreeActions
        function TreeActionsSelectionChanged(app, event)
            % d = uiprogressdlg(app.UIFigure,'Title','Waiting for visualization');   
            selectedNode = app.TreeActions.SelectedNodes;
            nodeData = selectedNode.NodeData;
            delete(app.PanelViewer.Children);
            delete(app.PanelControls.Children);
            % Checking node level
            if(isempty(nodeData))               
               return;
            end
            
            % PSD
            if(isequal(selectedNode.Tag,'psd'))
                app.SensorCrossSpectrum();
            end

            % Showing Alpha measures
            if(isequal(selectedNode.Tag,'power_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Power);
            end
            if(isequal(selectedNode.Tag,'width_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Width);
            end
            if(isequal(selectedNode.Tag,'exponent_alpha'))
                alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.Exponent);
            end
            if(isequal(selectedNode.Tag,'paf_alpha'))
               alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Alpha_estimate));
                app.PlotMeasure(alpha.PAF);
            end

            % Showing Xi measures
            if(isequal(selectedNode.Tag,'power_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Power);
            end
            if(isequal(selectedNode.Tag,'width_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Width);
            end
            if(isequal(selectedNode.Tag,'exponent_xi'))
                xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Xi_estimate));
                app.PlotMeasure(xi.Exponent);
            end
            
            % Showing Source cross spectrum
            if(isequal(selectedNode.Tag,'source_psd'))
                %% Showing Source cross spectrum
                app.SourceCrossSpectrum(); 
            end

             % Showing Activation
            if(isequal(selectedNode.Tag,'full_activation'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_full = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Full));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)),...
                    'Value', string(freqs(1)),'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.PlotFullActivation(activation_full.Source_Activations_Full));
                app.PlotFullActivation(activation_full.Source_Activations_Full);
            end 
            if(isequal(selectedNode.Tag,'activation_alpha'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_alpha = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Alpha));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)),...
                    'Value', string(freqs(1)),'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.PlotFullActivation(activation_alpha.Source_Activations_Alpha));
                app.PlotFullActivation(activation_alpha.Source_Activations_Alpha);
            end
            if(isequal(selectedNode.Tag,'activation_xi'))
                data = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Data));
                freqs = data.freq;
                activation_xi = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Source_Activations_Xi));
                pos = app.PanelControls.Position;
                uilabel(app.PanelControls,'Text','Frequencies:',...
                    'Position',[(pos(3)/2-80) 4 80 30]);
                uidropdown(app.PanelControls,"Items", string(num2cell(freqs)),...
                    'Value', string(freqs(1)),'Position',[(pos(3)/2) 4 90 27],...
                    "ValueChangedFcn",@(dropd,event) app.PlotFullActivation(activation_xi.Source_Activations_Xi));
                app.PlotFullActivation(activation_xi.Source_Activations_Xi);
            end

            % Showing Anat connectivity
            if(isequal(selectedNode.Tag,'anat_conn'))
                anat_conn = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Conn_Matrix));
            end 

             % Showing Conduction delays
            if(isequal(selectedNode.Tag,'cond_delay')) 
               cond_delay = load(fullfile(app.currentDS.Location, app.currentSub.SubID, app.currentSub.Delay_Matrix));
            end 
            % close(d);
            figure(app.UIFigure);
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Xi Alpha net';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'images', 'favicon.png');
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Text = 'New';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.CheckforupdateMenu = uimenu(app.HelpMenu);
            app.CheckforupdateMenu.Text = 'Check for update';
            app.WikiMenu = uimenu(app.HelpMenu);
            app.WikiMenu.Text = 'Wiki';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolRunProcess = uipushtool(app.Toolbar);
            app.PushToolRunProcess.Tooltip = {'Run processing'};
            app.PushToolRunProcess.ClickedCallback = createCallbackFcn(app, @PushToolRunProcessClicked, true);
            app.PushToolRunProcess.Icon = fullfile(pathToMLAPP, 'images', 'run.jpg');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Params configuration'};
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.Tooltip = {'Upload dataset'};
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'images', 'upload.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.Tooltip = {'Explore dataset'};
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.UpperButton = uibutton(app.TabExplorer, 'push');
            app.UpperButton.ButtonPushedFcn = createCallbackFcn(app, @UpperButtonPushed, true);
            app.UpperButton.Icon = fullfile(pathToMLAPP, 'images', 'folder_parent.png');
            app.UpperButton.Tooltip = {'Go to parent'};
            app.UpperButton.Text = '';
            app.NewFolderButton = uibutton(app.TabExplorer, 'push');
            app.NewFolderButton.ButtonPushedFcn = createCallbackFcn(app, @NewFolderButtonPushed, true);
            app.NewFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'new_folder.png');
            app.NewFolderButton.Tooltip = {'New Folder'};
            app.NewFolderButton.Text = '';
            app.RefreshButton = uibutton(app.TabExplorer, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'images', 'refresh.png');
            app.RefreshButton.Tooltip = {'Refresh explorer'};
            app.RefreshButton.Text = '';
            app.DeleteFolderButton = uibutton(app.TabExplorer, 'push');
            app.DeleteFolderButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFolderButtonPushed, true);
            app.DeleteFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'delete.png');
            app.DeleteFolderButton.Tooltip = {'Delete folder'};
            app.DeleteFolderButton.Text = '';
            app.OpenFolderButton = uibutton(app.TabExplorer, 'push');
            app.OpenFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderButtonPushed, true);
            app.OpenFolderButton.Icon = fullfile(pathToMLAPP, 'images', 'open_folder.png');
            app.OpenFolderButton.Tooltip = {'Open Folder'};
            app.OpenFolderButton.Text = '';
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.TreeDatasets = uitree(app.TabDatasets);
            app.TreeDatasets.SelectionChangedFcn = createCallbackFcn(app, @TreeDatasetsSelectionChanged, true);
            app.PanelViewer = uipanel(app.GridLayout);
            app.PanelViewer.Title = 'Viewer';
            app.LabelBreadcrumbs = uilabel(app.GridLayout);
            app.LabelBreadcrumbs.Text = '';
            app.PanelControls = uipanel(app.GridLayout);
            app.PanelActions = uipanel(app.GridLayout);
            app.PanelActions.Title = 'Actions';
            app.GridLayout3 = uigridlayout(app.PanelActions);
            app.TreeActions = uitree(app.GridLayout3);
            app.TreeActions.SelectionChangedFcn = createCallbackFcn(app, @TreeActionsSelectionChanged, true);
            app.NodeAction1 = uitreenode(app.TreeActions);
            app.NodeAction1.Text = 'Node';
            app.Node2 = uitreenode(app.NodeAction1);
            app.Node2.Icon = fullfile(pathToMLAPP, 'images', 'participant.png');
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.NodeAction1);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.NodeAction1);
            app.Node4.Text = 'Node4';
            app.NodeAction2 = uitreenode(app.TreeActions);
            app.NodeAction2.Text = 'Node';
            app.Node2_2 = uitreenode(app.NodeAction2);
            app.Node2_2.Text = 'Node2';
            app.Node3_2 = uitreenode(app.NodeAction2);
            app.Node3_2.Text = 'Node3';
            app.Node4_2 = uitreenode(app.NodeAction2);
            app.Node4_2.Text = 'Node4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XiAlphaNetUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end